classdef winAppAnalise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        menu_Panel                      matlab.ui.container.Panel
        menu_Grid                       matlab.ui.container.GridLayout
        menu_Settings                   matlab.ui.control.Image
        menu_Position                   matlab.ui.control.Image
        menu_searchPath                 matlab.ui.control.Image
        menu_userPathGrid               matlab.ui.container.GridLayout
        menu_userPath                   matlab.ui.control.DropDown
        menu_Button7                    matlab.ui.control.Button
        menu_Button6                    matlab.ui.control.Button
        menu_Button5                    matlab.ui.control.Button
        menu_Separator2                 matlab.ui.control.Image
        menu_Button4                    matlab.ui.control.StateButton
        menu_Button3                    matlab.ui.control.StateButton
        menu_Button2                    matlab.ui.control.StateButton
        menu_Separator1                 matlab.ui.control.Image
        menu_Button1                    matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_Home                       matlab.ui.container.Tab
        home_Grid                       matlab.ui.container.GridLayout
        home_Button5                    ccTools.Button
        home_Button4                    ccTools.Button
        home_Button3                    ccTools.Button
        home_Button2                    ccTools.Button
        home_Button1                    ccTools.Button
        Tab2_File                       matlab.ui.container.Tab
        file_Grid                       matlab.ui.container.GridLayout
        GridLayout42                    matlab.ui.container.GridLayout
        GridLayout44                    matlab.ui.container.GridLayout
        Tree                            matlab.ui.container.Tree
        FREQUNCIANode                   matlab.ui.container.TreeNode
        DESCRIONode                     matlab.ui.container.TreeNode
        Faixa2de10PMEC2022Node          matlab.ui.container.TreeNode
        Faixa1de10PMEC2022Node          matlab.ui.container.TreeNode
        IDNode                          matlab.ui.container.TreeNode
        Node_2                          matlab.ui.container.TreeNode
        Node                            matlab.ui.container.TreeNode
        file_doc_MetadataLabel_2        matlab.ui.control.Label
        GridLayout43                    matlab.ui.container.GridLayout
        file_doc_Metadata               matlab.ui.control.TextArea
        file_doc_MetadataLabel          matlab.ui.control.Label
        file_left_TabGrid_2             matlab.ui.container.GridLayout
        file_left_TabImage_2            matlab.ui.control.Image
        file_left_TabTitle_2            matlab.ui.control.Label
        file_left_TabGrid_3             matlab.ui.container.GridLayout
        file_left_TabImage_3            matlab.ui.control.Image
        file_left_TabTitle_3            matlab.ui.control.Label
        file_docGrid                    matlab.ui.container.GridLayout
        playReport_left_MetadataGrid_2  matlab.ui.container.GridLayout
        playReport_left_DownArrow_5     matlab.ui.control.Image
        playReport_left_DownArrow_4     matlab.ui.control.Image
        file_left_TabGrid               matlab.ui.container.GridLayout
        file_left_TabImage              matlab.ui.control.Image
        file_left_TabTitle              matlab.ui.control.Label
        Panel_42                        matlab.ui.container.Panel
        GridLayout41                    matlab.ui.container.GridLayout
        repoSFILabel                    matlab.ui.control.Label
        LocalLabel                      matlab.ui.control.Label
        file_tool_RepositoryButton      matlab.ui.control.Button
        file_tool_OpenFileButton        matlab.ui.control.Button
        file_left_TreeLabel_2           matlab.ui.control.Label
        file_left_TreeLabel             matlab.ui.control.Label
        file_left_Tree                  matlab.ui.container.Tree
        appColeta_211202_T080348_ThreadID1_1binNode  matlab.ui.container.TreeNode
        ROHDESCHWARZEB500101512002V04904072871000Node_2  matlab.ui.container.TreeNode
        file_toolGrid                   matlab.ui.container.GridLayout
        file_tool_RepositoryButton_4    matlab.ui.control.Button
        Button                          matlab.ui.control.StateButton
        play_tool_Separator1_2          matlab.ui.control.Image
        file_tool_RepositoryButton_3    matlab.ui.control.Button
        file_tool_SpecReadButton        matlab.ui.control.Button
        Tab3_playReport                 matlab.ui.container.Tab
        playReport_Grid                 matlab.ui.container.GridLayout
        playReport_toolGrid             matlab.ui.container.GridLayout
        report_toolGrid                 matlab.ui.container.GridLayout
        report_tool_Button3             matlab.ui.control.Button
        report_tool_Button2             matlab.ui.control.Button
        play_tool_Separator1_3          matlab.ui.control.Image
        report_tool_Button1             matlab.ui.control.Button
        play_toolGrid                   matlab.ui.container.GridLayout
        play_tool_Separator1_5          matlab.ui.control.Image
        play_tool_Button4_2             matlab.ui.control.Button
        play_tool_Separator1_4          matlab.ui.control.Image
        play_tool_Button1_2             matlab.ui.control.Button
        play_tool_Button2_3             matlab.ui.control.Button
        play_tool_Button2_2             matlab.ui.control.Button
        play_tool_Separator1            matlab.ui.control.Image
        play_tool_Button1               matlab.ui.control.Button
        play_tool_Button2               matlab.ui.control.Button
        play_tool_Button5               matlab.ui.control.Button
        play_tool_Button4               matlab.ui.control.Button
        play_tool_Button3               matlab.ui.control.Button
        playReport_docPanel             matlab.ui.container.Panel
        playReport_rightGrid            matlab.ui.container.GridLayout
        report_right_Tab2Info           matlab.ui.container.GridLayout
        Panel_41                        matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        Report_ClassificationParameters  matlab.ui.control.TextArea
        Report_ClassificationButton     matlab.ui.control.Button
        Report_Classification           matlab.ui.control.EditField
        Report_ClassificationLabel      matlab.ui.control.Label
        Report_Sublabel6                matlab.ui.control.Label
        Panel_40                        matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        Report_DetectionButton          matlab.ui.control.Button
        Report_Detection                matlab.ui.control.EditField
        Report_DetectionLabel           matlab.ui.control.Label
        Report_BandPanelLabel           matlab.ui.control.Label
        Report_PlaybackOnly             matlab.ui.control.CheckBox
        Report_DetectionParameters      matlab.ui.control.TextArea
        Report_BandPanel                matlab.ui.container.Panel
        Report_BandGrid                 matlab.ui.container.GridLayout
        Report_BandLim2                 matlab.ui.control.Spinner
        Report_BandSliderLabel          matlab.ui.control.Label
        Report_BandLim1                 matlab.ui.control.Spinner
        Report_Sublabel5_2              matlab.ui.control.Label
        Panel_39                        matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        Report_occParameters            matlab.ui.control.TextArea
        Report_occButton                matlab.ui.control.Button
        Report_occMethod                matlab.ui.control.EditField
        Report_occMethodLabel           matlab.ui.control.Label
        Report_Sublabel5                matlab.ui.control.Label
        report_right_Tab2Grid           matlab.ui.container.GridLayout
        report_right_Tab2Image          matlab.ui.control.Image
        report_right_Tab2Label          matlab.ui.control.Label
        report_right_Tab1Info           matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        Report_addButton                matlab.ui.control.Image
        Report_Sublabel3                matlab.ui.control.Label
        Panel_37                        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Report_NewProject               matlab.ui.control.Button
        ProjectFilename                 matlab.ui.control.TextArea
        RelatrioLabel                   matlab.ui.control.Label
        Panel_38                        matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        Report_VersionLabel             matlab.ui.control.Label
        Report_Version                  matlab.ui.control.DropDown
        Report_NewProject_2             matlab.ui.control.Button
        Report_TypeLabel                matlab.ui.control.Label
        Report_Type                     matlab.ui.control.DropDown
        Report_Issue                    matlab.ui.control.NumericEditField
        Report_IssueLabel               matlab.ui.control.Label
        Report_SaveProject              matlab.ui.control.Button
        ProjectName                     matlab.ui.control.EditField
        Panel_36                        matlab.ui.container.Panel
        Report_Grid4                    matlab.ui.container.GridLayout
        Report_Spinner4                 matlab.ui.control.Spinner
        Report_Spinner3                 matlab.ui.control.Spinner
        Report_DatePicker2              matlab.ui.control.DatePicker
        Report_Spinner2                 matlab.ui.control.Spinner
        Report_Spinner1                 matlab.ui.control.Spinner
        Report_DatePicker1              matlab.ui.control.DatePicker
        Report_Sublabel4                matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        Report_Sublabel1                matlab.ui.control.Label
        Report_ImageWarn                matlab.ui.control.Image
        Report_Tree                     matlab.ui.container.CheckBoxTree
        report_right_Tab1Grid           matlab.ui.container.GridLayout
        report_right_Tab1Image          matlab.ui.control.Image
        report_right_Tab1Label          matlab.ui.control.Label
        play_right_Tab4Info             matlab.ui.container.GridLayout
        play_FindPeaks_Label_6          matlab.ui.control.Label
        play_FindPeaks_Tree_2           matlab.ui.container.Tree
        play_FindPeaks_add_2            matlab.ui.control.Image
        play_FindPeaks_Panel_2          matlab.ui.container.Panel
        play_FindPeaks_Grid_2           matlab.ui.container.GridLayout
        LarguracanalkHzEditField        matlab.ui.control.NumericEditField
        LarguracanalkHzLabel            matlab.ui.control.Label
        FrequnciacentralnsimocanalMHzEditField  matlab.ui.control.NumericEditField
        FrequnciacentralnsimocanalMHzEditFieldLabel  matlab.ui.control.Label
        EspaamentocanaiskHzEditField    matlab.ui.control.NumericEditField
        EspaamentocanaiskHzLabel        matlab.ui.control.Label
        Frequnciacentral1canalMHzEditField  matlab.ui.control.NumericEditField
        Frequnciacentral1canalMHzEditFieldLabel  matlab.ui.control.Label
        play_FindPeaks_RadioGroup_2     matlab.ui.container.ButtonGroup
        play_FindPeaks_Radio3_2         matlab.ui.control.RadioButton
        play_FindPeaks_Radio2_2         matlab.ui.control.RadioButton
        play_FindPeaks_Radio1_2         matlab.ui.control.RadioButton
        play_right_Tab4Grid             matlab.ui.container.GridLayout
        play_right_Tab4Image            matlab.ui.control.Image
        play_right_Tab4Label            matlab.ui.control.Label
        play_right_Tab3Info             matlab.ui.container.GridLayout
        play_FindPeaks_Label            matlab.ui.control.Label
        play_FindPeaks_PeakBW           matlab.ui.control.NumericEditField
        play_FindPeaks_PeakBWLabel      matlab.ui.control.Label
        play_FindPeaks_PeakCF           matlab.ui.control.NumericEditField
        play_FindPeaks_PeakCFLabel      matlab.ui.control.Label
        play_FindPeaks_Tree             matlab.ui.container.Tree
        play_FindPeaks_add              matlab.ui.control.Image
        play_FindPeaks_Panel            matlab.ui.container.Panel
        play_FindPeaks_Grid             matlab.ui.container.GridLayout
        play_FindPeaks_BW               matlab.ui.control.Spinner
        play_FindPeaks_BWLabel          matlab.ui.control.Label
        play_FindPeaks_distance         matlab.ui.control.Spinner
        play_FindPeaks_distanceLabel    matlab.ui.control.Label
        play_FindPeaks_proeminence      matlab.ui.control.Spinner
        play_FindPeaks_proeminenceLabel  matlab.ui.control.Label
        play_FindPeaks_THR              matlab.ui.control.Spinner
        play_FindPeaks_THRLabel         matlab.ui.control.Label
        play_FindPeaks_Numbers          matlab.ui.control.Spinner
        play_FindPeaks_NumbersLabel     matlab.ui.control.Label
        play_FindPeaks_Trace            matlab.ui.control.DropDown
        play_FindPeaks_TraceLabel       matlab.ui.control.Label
        play_FindPeaks_RadioGroup       matlab.ui.container.ButtonGroup
        play_FindPeaks_Radio3           matlab.ui.control.RadioButton
        play_FindPeaks_Radio2           matlab.ui.control.RadioButton
        play_FindPeaks_Radio1           matlab.ui.control.RadioButton
        play_right_Tab3Grid             matlab.ui.container.GridLayout
        play_right_Tab3Image            matlab.ui.control.Image
        play_right_Tab3Label            matlab.ui.control.Label
        play_right_Tab2Info             matlab.ui.container.GridLayout
        play_OCC_Panel                  matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        Playback_occValue               matlab.ui.control.Spinner
        Playback_occValueLabel          matlab.ui.control.Label
        Playback_occType                matlab.ui.control.DropDown
        Playback_occTypeLabel           matlab.ui.control.Label
        play_OCC_Label                  matlab.ui.control.Label
        play_Waterfall_Panel            matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        play_Waterfall_Colormap         matlab.ui.control.DropDown
        play_Waterfall_ColormapLabel    matlab.ui.control.Label
        play_Waterfall_cLim_Grid1       matlab.ui.container.GridLayout
        play_Waterfall_cLim_Mode        matlab.ui.control.Image
        play_Waterfall_cLim_Grid2       matlab.ui.container.GridLayout
        play_Waterfall_cLim_Separation  matlab.ui.control.Label
        play_Waterfall_cLim1            matlab.ui.control.Spinner
        play_Waterfall_cLim2            matlab.ui.control.Spinner
        play_Waterfall_cLim_Label       matlab.ui.control.Label
        play_Waterfall_Interpolation    matlab.ui.control.DropDown
        play_Waterfall_Interpolation_Label  matlab.ui.control.Label
        play_Waterfall_Timestamp        matlab.ui.control.DropDown
        play_Waterfall_Timestamp_Label  matlab.ui.control.Label
        play_Waterfall_Decimation       matlab.ui.control.DropDown
        play_Waterfall_Decimation_Label  matlab.ui.control.Label
        play_Waterfall_Label            matlab.ui.control.Label
        play_Persistance_Panel          matlab.ui.container.Panel
        play_Persistance_Grid           matlab.ui.container.GridLayout
        play_Persistance_Colormap       matlab.ui.control.DropDown
        play_Persistance_ColormapLabel  matlab.ui.control.Label
        play_Persistance_cLim_Grid2     matlab.ui.container.GridLayout
        play_Persistance_cLim_Separation  matlab.ui.control.Label
        play_Persistance_cLim2          matlab.ui.control.Spinner
        play_Persistance_cLim1          matlab.ui.control.Spinner
        play_Persistance_cLim_Grid1     matlab.ui.container.GridLayout
        play_Persistance_cLim_Mode      matlab.ui.control.Image
        play_Persistance_cLim_Label     matlab.ui.control.Label
        play_Persistance_Transparency   matlab.ui.control.Spinner
        play_Persistance_TransparencyLabel  matlab.ui.control.Label
        play_Persistance_Samples        matlab.ui.control.DropDown
        play_Persistance_SamplesLabel   matlab.ui.control.Label
        play_Persistance_Interpolation  matlab.ui.control.DropDown
        play_Persistance_InterpolationLabel  matlab.ui.control.Label
        play_Persistance_Label          matlab.ui.control.Label
        play_right_Tab2Grid             matlab.ui.container.GridLayout
        play_right_Tab2Image            matlab.ui.control.Image
        play_right_Tab2Label            matlab.ui.control.Label
        play_right_Tab1Info             matlab.ui.container.GridLayout
        Panel_34                        matlab.ui.container.Panel
        GridLayout40                    matlab.ui.container.GridLayout
        play_MinPlotTime                matlab.ui.control.Spinner
        play_MinPlotTimeLabel           matlab.ui.control.Label
        play_ClearWriteVisibility       matlab.ui.control.DropDown
        play_ClearWriteVisibilityLabel  matlab.ui.control.Label
        play_LimitsLabel_4              matlab.ui.control.Label
        play_Limits_Panel               matlab.ui.container.Panel
        play_Limits_Grid                matlab.ui.container.GridLayout
        play_Limits_yLim2               matlab.ui.control.Spinner
        play_Limits_yLim1               matlab.ui.control.Spinner
        play_Limits_yLimLabel           matlab.ui.control.Label
        play_Limits_xLim2               matlab.ui.control.Spinner
        play_Limits_xLim1               matlab.ui.control.Spinner
        play_Limits_xLimLabel           matlab.ui.control.Label
        play_LimitsLabel_3              matlab.ui.control.Label
        Panel_33                        matlab.ui.container.Panel
        play_ControlsGrid               matlab.ui.container.GridLayout
        play_Waterfall                  matlab.ui.control.StateButton
        play_Occupancy                  matlab.ui.control.StateButton
        play_Persistance                matlab.ui.control.StateButton
        play_MaxHold                    matlab.ui.control.StateButton
        play_Average                    matlab.ui.control.StateButton
        play_MinHold                    matlab.ui.control.StateButton
        play_WaterfallLabel             matlab.ui.control.Label
        play_OccupancyLabel             matlab.ui.control.Label
        play_PersistanceLabel           matlab.ui.control.Label
        play_MaxHoldLabel               matlab.ui.control.Label
        play_AverageLabel               matlab.ui.control.Label
        play_MinHoldLabel               matlab.ui.control.Label
        play_LimitsLabel_2              matlab.ui.control.Label
        play_right_PanelA               matlab.ui.container.Panel
        play_right_GridA                matlab.ui.container.GridLayout
        play_right_Slider               matlab.ui.control.Slider
        play_right_Grid                 matlab.ui.container.GridLayout
        play_right_Loop                 matlab.ui.control.Button
        play_right_Play                 matlab.ui.control.Button
        play_right_MainControlLabel     matlab.ui.control.Label
        play_right_Tab1Grid             matlab.ui.container.GridLayout
        play_right_Tab1Image            matlab.ui.control.Image
        play_right_Tab1Label            matlab.ui.control.Label
        playReport_leftGrid             matlab.ui.container.GridLayout
        playReport_left_TreeLabel       matlab.ui.control.Label
        playReport_left_MetadataGrid    matlab.ui.container.GridLayout
        playReport_left_DownArrow       matlab.ui.control.Image
        playReport_left_DownArrow_3     matlab.ui.control.Image
        playReport_left_MetadataLabel   matlab.ui.control.Label
        playReport_left_Metadata        matlab.ui.control.TextArea
        playReport_left_Tree            matlab.ui.container.Tree
        appColeta_230818_T151504_ID4_1binNode  matlab.ui.container.TreeNode
        RohdeSchwarzFSVR3013110006K30101119163Node  matlab.ui.container.TreeNode
        TEKTRONIXSA2500PCB0000007050Node  matlab.ui.container.TreeNode
        KeysightTechnologiesN9344CCN06041424A0627Node  matlab.ui.container.TreeNode
        RohdeSchwarzEB500101512002V04904072871000Node  matlab.ui.container.TreeNode
        playReport_left_TabGrid         matlab.ui.container.GridLayout
        playReport_left_TabImage        matlab.ui.control.Image
        playReport_left_TabTitle        matlab.ui.control.Label
        ContextMenu_Report              matlab.ui.container.ContextMenu
        Report_Delete                   matlab.ui.container.Menu
        Report_ExternalFiles            matlab.ui.container.Menu
        ContextMenu_File2               matlab.ui.container.ContextMenu
        File_ContextMenu2               matlab.ui.container.Menu
        play_ContextMenu2               matlab.ui.container.ContextMenu
        play_ContextMenu2_Drive         matlab.ui.container.Menu
        play_ContextMenu2_search        matlab.ui.container.Menu
        play_ContextMenu2_edit          matlab.ui.container.Menu
        play_ContextMenu2_analog        matlab.ui.container.Menu
        play_ContextMenu2_digital       matlab.ui.container.Menu
        play_ContextMenu2_del           matlab.ui.container.Menu
        ContextMenu_File1               matlab.ui.container.ContextMenu
        File_ContextMenu1               matlab.ui.container.Menu
    end

    
    properties (Access = public)

        metaData = struct('File', {}, 'Type', {}, 'Data', {}, 'Samples', {}, 'Memory', {});
        Samples  = [];
        specData = [];
        projData = struct('image', {''}, 'table', {[]});

        axes1 = [];
        axes2 = [];
        axes3 = [];
        
        line_ClearWrite = [];                                               % ClearWrite (main trace)
        line_OCC        = [];                                               % OCC roi/line
        line_OCCLabel   = [];                                               % OCC roi/line label
        obj_Persistance = [];                                               % Persistance Object
        mkr_ROI         = [];                                               % Selected marker roi
        mkr_Label       = [];                                               % Markers labels
        line_wfTime     = [];                                               % Waterfall timestamp
        
        RestoreView = [];
        timeIndex   =  1;
        
        occFlag  = 0;
        plotFlag = 0;
        axesFlag = 0;
        
        x;
        Span;
        aCoef;
        bCoef;
        Unit;
        
        occTHR;                                                             % "OffSet Statistical OCC"

        RootFolder
        General        
        traceInfo     = struct('SelectedNode', 0, ...
                               'Mode', [0, 0, 0], ...
                               'plotLayout', 0, ...
                               'Persistance', 0);
        peaksTable    = table('Size', [0, 23],                                                                                                                                                                                                         ...
                              'VariableTypes', {'cell', 'single', 'single', 'uint16', 'double', 'single', 'double', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'int16', 'int32', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell', 'cell'}, ...
                              'VariableNames', {'Tag', 'Latitude', 'Longitude', 'Index', 'Frequency', 'Truncated', 'BW', 'minLevel', 'meanLevel', 'maxLevel', 'meanOCC', 'maxOCC', 'Type', 'Regulatory', 'Service', 'Station', 'Description', 'Distance', 'Irregular', 'RiskLevel', 'occMethod', 'Detection', 'Classification'});
        exceptionList = table('Size', [0, 10],                                                                                       ...
                              'VariableTypes', {'cell', 'double', 'cell', 'cell', 'int16', 'int32', 'cell', 'cell', 'cell', 'cell'}, ...
                              'VariableNames', {'Tag', 'Frequency', 'Type', 'Regulatory', 'Service', 'Station', 'Description', 'Distance', 'Irregular', 'RiskLevel'});
        
        auxWin1       = [];                                                 % winTreeEdit
        auxWin2       = [];                                                 % winDriveTest
        auxWin3       = [];                                                 % winFiscaliza
        auxWin4       = [];                                                 % winMaskDraw
        auxWin5       = [];                                                 % winSettings
        auxWin6       = [];                                                 % winRepository
        auxWin7       = [];                                                 % winReport
        auxWin8       = [];                                                 % winAnatelDB
    end
    
    
    methods (Access = public)
        %-----------------------------------------------------------------%
        % STARTUP
        %-----------------------------------------------------------------%
        function startup_WindowPosition(app)
            menu_PositionImageClicked(app)
            ccTools.compCustomization(app.UIFigure, 'windowMinSize', class.Constants.windowMinSize);
        end


        %-----------------------------------------------------------------%
        function startup_ConfigFiles(app)

            tempObj = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
            tempObj.layout.lightColor = tempObj.layout.lightColor';
            tempObj.layout.cyanColor  = tempObj.layout.cyanColor';
            if isempty(tempObj.Merge.DistanceValue); tempObj.Merge.DistanceValue = Inf;
            end

            app.General = tempObj;
            
            % Others fields
            app.General.ver    = startup_Versions("Startup", app.RootFolder);
            app.General.Models = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Template', 'html_General.cfg'))));
            app.General.Report = '';

            % openGL
            switch app.General.openGL
                case 'Software';       opengl software
                case 'Hardware Full';  opengl hardware
                case 'Hardware Basic'; opengl hardwarebasic
            end

            % Temp folder
            if ~isfolder(fullfile(app.RootFolder, 'Temp'))
                mkdir(fullfile(app.RootFolder, 'Temp'))
            end

            % UserPath
            userPaths = {fullfile(getenv('USERPROFILE'), 'Documents'); ...
                         fullfile(getenv('USERPROFILE'), 'Downloads')};

            if ~isempty(app.General.userPath)
                userPaths(end+1) = {app.General.userPath};
            end

            for ii = numel(userPaths):-1:1
                if ~isfolder(userPaths{ii})
                    userPaths(ii) = [];
                end
            end

            set(app.menu_userPath, 'Items', userPaths, 'Value', userPaths{end})
            app.General.userPath = app.menu_userPath.Value;

            % PythonPath
            if ~isempty(app.General.pythonPath)
                Python = pyenv;
                if ~strcmp(app.General.pythonPath, Python.Executable)
                    try
                        pyenv('Version', app.General.pythonPath);
                    catch
                    end
                end
            end
            
            % Miscellaneous
            Misc_FindPeaksDefaultValues(app.RootFolder)            
            app.play_Waterfall_Colormap.Value = app.General.Waterfall;
            app.Report_Type.Items             = app.General.Models.Name;
        end


        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            t = tiledlayout(app.playReport_docPanel, 3, 1, "Padding", "tight", "TileSpacing", "tight");

            % app.axes1
            app.axes1 = uiaxes(t);
            app.axes1.Layout.Tile = 1;
            set(app.axes1, Color=[0 0 0], FontName='Helvetica', FontSize=9, FontSmoothing='on',     ...
                                          XGrid='on', XMinorGrid='on', YGrid='on', YMinorGrid='on', ...
                                          GridAlpha=.25, GridColor=[.94,.94,.94], MinorGridAlpha=.2, MinorGridColor=[.94,.94,.94], Interactions=[], XTickLabel={}, ...
                                          TitleHorizontalAlignment='right')
            ylabel(app.axes1, 'Nível (dB)')

            app.axes1.Title.String = 'Título';
            app.axes1.Subtitle.String = {'Sub-título'; 'Segunda linha...'};

            % app.axes2
            app.axes2 = uiaxes(t);
            app.axes2.Layout.Tile = 2;
            set(app.axes2, Color=[0 0 0], FontName='Helvetica', FontSize=9, FontSmoothing='on',     ...
                                          XGrid='on', XMinorGrid='on', YGrid='on', YMinorGrid='on', ...
                                          GridAlpha=.25, GridColor=[.94,.94,.94], MinorGridAlpha=.2, MinorGridColor=[.94,.94,.94], Interactions=[], XTickLabel={})
            ylabel(app.axes2, 'Ocupação (%)')


            % app.axes3
            app.axes3 = uiaxes(t);
            app.axes3.Layout.Tile = 3;
            set(app.axes3, Color=[.98,.98,.98], FontName='Helvetica', FontSize=9, FontSmoothing='on', Interactions = [])
            xlabel(app.axes3, 'Frequência (MHz)')
            ylabel(app.axes3, 'Amostras')

            % Others aspects...
            hold(app.axes1, 'on')
            hold(app.axes2, 'on')
            hold(app.axes3, 'on')
            
            disableDefaultInteractivity(app.axes1)
            disableDefaultInteractivity(app.axes2)
            disableDefaultInteractivity(app.axes3)

            axtoolbar(app.axes1, {'datacursor', 'pan', 'zoomin', 'zoomout', 'restoreview'});
            axtoolbar(app.axes2, {'datacursor', 'pan', 'zoomin', 'zoomout', 'restoreview'});
            axtoolbar(app.axes3, {'datacursor', 'pan', 'zoomin', 'zoomout', 'restoreview'});

            linkaxes([app.axes1, app.axes2, app.axes3], 'x')

            addlistener(app.axes1, 'XLim', 'PostSet', @app.Playback_xLimits);
            addlistener(app.axes1, 'YLim', 'PostSet', @app.Playback_yLimits);  

            % ButtonPushed_plotLayout(app)
        end
        

        function startup_Colormap_axes1(app)

            colormap(app.axes1, app.play_Persistance_Colormap.Value);
            app.axes1.Colormap(1,:) = [0,0,0];

        end


        function startup_Colormap_axes3(app)

            colormap(app.axes3, app.play_Waterfall_Colormap.Value);
            app.axes3.Colormap(1,:) = [0,0,0];

        end


        %-----------------------------------------------------------------%
        function ModeOperation(app, pushedButton)

            switch pushedButton
                case app.menu_Button1
                    idx = 1;

                case app.menu_Button2
                    idx = 2;

                otherwise
                    idx = 3;
                    
                    switch pushedButton
                        case app.menu_Button3
                            app.playReport_rightGrid.RowHeight = {22, '1x', 22, 0, 22, 0, 22, 0, 0, 0, 0, 0};
                            app.playReport_toolGrid.RowHeight  = {22, 0};
                            % ModeOperation_Playback(app)

                        case app.menu_Button4
                            app.playReport_rightGrid.RowHeight = {0, 0, 0, 0, 0, 0, 0, 0, 22, '1x', 22, 0};
                            app.playReport_toolGrid.RowHeight  = {0, 22};
                            % ModeOperation_Report(app)
                    end
            end

            % Fcn_expandTree(app, 'mainInfo')
            app.TabGroup.SelectedTab = app.TabGroup.Children(idx);
            drawnow            
        end

        
        function ModeOperation_Playback (app)
            
            if ~isempty(app.playReport_docPanel.UserData) & isvalid(app.playReport_docPanel.UserData)
                if ~isequal(app.playReport_left_Tree.SelectedNodes, app.playReport_docPanel.UserData)
                    app.playReport_left_Tree.SelectedNodes = app.playReport_docPanel.UserData;
                    playReport_left_TreeSelectionChanged(app)
                end

                app.play_FindPeaks_add.Enable = 1;
                app.play_right_Slider.Enable    = 1;
                app.play_right_Loop.Enable          = 1;
                app.play_right_Play.Enable          = 1;

            else
                app.playReport_left_Tree.SelectedNodes = [];

                app.play_FindPeaks_add.Enable   = 0;
                app.play_right_Slider.Enable      = 0;
                app.play_right_Loop.Enable            = 0;
                app.play_right_Play.Enable            = 0;

                app.occFlag                     = 0;
            end

            Playback_Layout(app)

        end


        function ModeOperation_Report(app)

            idx = find([app.specData.reportFlag], 1);

            if ~isempty(idx)
                app.Report_addButton.Enable     = 0;
                app.report_tool_Button3.Enable    = 1;
                app.report_tool_Button2.Enable = 1;

                if isempty(app.Report_Tree.Children)
                    Report_ProjectIcon(app)
                    Report_TreeBuilding(app)
                end
                app.Report_Tree.SelectedNodes = app.Report_Tree.Children(1).Children(1);
                Report_TreeSelectionChanged(app)

            else
                app.report_tool_Button3.Enable    = 0;
                app.report_tool_Button2.Enable = 0;

                playReport_left_TreeSelectionChanged(app)
            end

        end


        function ModeOperation_Edit(app)

            Info_Layout(app)

        end    


        function ModeOperation_Misc(app)

            if ~isempty(app.playReport_left_Tree.SelectedNodes)
                idx = unique([app.playReport_left_Tree.SelectedNodes.NodeData]);

                app.Export_idx.Value   = strjoin(string(idx), ', ');
                app.Misc_Button_Play.Enable = 1;

            else
                app.Export_idx.Value   = '';
                app.Misc_Button_Play.Enable = 0;
            end
            
            Export_CheckBoxValueChanged(app)

        end
        

        %-----------------------------------------------------------------%
        % Modo "ARQUIVO(S)" 
        %-----------------------------------------------------------------%
        function File_Layout(app, Status)
            
            switch Status
                case 'Off'
                    app.File_DataPoints.FontColor      = [.94,.94,.94];
                    app.File_Resolution.FontColor      = [.94,.94,.94];
                    app.File_Samples.FontColor         = [.94,.94,.94];
                    app.File_EstimatedMemory.FontColor = [.94,.94,.94];
                    
                    app.File_Operation.Value   = '';
                    app.File_LevelUnit.Value   = '';
                    app.File_Description.Value = '';
                    app.File_ObservationTime.Value = '';
                    app.File_City.Value            = '';
                    
                    set(app.File_GridB2.Children, 'Enable', 0)
                    
                case 'On'
                    app.File_DataPoints.FontColor      = [0,0,0];
                    app.File_Resolution.FontColor      = [0,0,0];
                    app.File_Samples.FontColor         = [0,0,0];
                    app.File_EstimatedMemory.FontColor = [0,0,0];
                    
                    set(app.File_GridB2.Children, 'Enable', 1)
            end
            
        end
                
        
        function File_GeneralReader(app, ind)
            
            [~, name, ext] = fileparts(app.metaData(ind).File);
            
            switch lower(ext)
                case '.bin'
                    fileID = fopen(app.metaData(ind).File);
                    Format = fread(fileID, [1 36], '*char');
                    fclose(fileID);
    
                    if contains(Format, 'CRFS', "IgnoreCase", true)
                        app.metaData(ind).Data = fileReader.CRFSBin(app.metaData(ind).File, 'MetaData', []);
                    elseif contains(Format, 'RFlookBin v.1/1', "IgnoreCase", true)
                        app.metaData(ind).Data = fileReader.RFLookBin(app.metaData(ind).File, 'MetaData');
                    elseif contains(Format, 'RFlookBin v.2/1', "IgnoreCase", true)
                        app.metaData(ind).Data = fileReader.RFlookBinV2(app.metaData(ind).File, 'MetaData', []);
                    else
                        error('O arquivo indicado a seguir parece não ser de um dos formatos binários cuja leitura foi implantada no appAnalise.\n• %s', [name ext])
                    end
                
                case '.dbm'
                    app.metaData(ind).Data = fileReader.CellPlanDBM(app.metaData(ind).File, 'MetaData', [], app.RootFolder);

                case '.sm1809'
                    app.metaData(ind).Data = fileReader.SM1809(app.metaData(ind).File, 'MetaData', []);
                    
                case '.csv'
                    app.metaData(ind).Data = fileReader.ArgusCSV(app.metaData(ind).File, 'SingleFile', []);

                case '.mat'
                    load(app.metaData(ind).File, '-mat', 'prj_metaData')
                    app.metaData(ind).Data = prj_metaData;                
            end
            
            if isempty([app.metaData(ind).Data.Samples])
                error('O arquivo indicado a seguir não possui informação espectral.\n• %s', [name ext])
            end
            
            app.metaData(ind).Samples = [app.metaData(ind).Data.Samples]';
            app.metaData(ind).Memory  = File_GeneralReader_EstimatedMemory(app, ind);
            
        end
        
        
        function EstimatedMemory = File_GeneralReader_EstimatedMemory(app, ii)
            
            EstimatedMemory = 0;
            for jj = 1:numel(app.metaData(ii).Data)
                EstimatedMemory = EstimatedMemory + 4 * app.metaData(ii).Data(jj).Samples .* app.metaData(ii).Data(jj).MetaData.DataPoints .* 1e-6;
            end
            
        end
        
        
        function File_Tree1_Building(app)

            delete(app.file_left_Tree.Children)
                        
            app.File_Number.Value = numel(app.metaData);
            if app.File_Number.Value
                app.File_Button_ReadFiles.Enable = 1;
                
                for ii = 1:app.File_Number.Value
                    imageIcon      = Fcn_TreeIcon(app, app.metaData(ii).Data(1).Node);                    
                    [~, name, ext] = fileparts(app.metaData(ii).File);
                    
                    uitreenode(app.file_left_Tree, 'Text', [name ext], ...
                                               'NodeData', ii,     ...
                                               'Icon', imageIcon,  ...
                                               'ContextMenu', app.ContextMenu_File1);
                end
                app.file_left_Tree.SelectedNodes = app.file_left_Tree.Children(1);
                
            else
                app.File_Button_ReadFiles.Enable = 0;
                delete(app.File_Tree2.Children)
            end
            
            file_left_TreeSelectionChanged(app)
            
        end


        function File_Tree1SelectionChanged_Aux(app, Status)
            
            switch Status
                case 'Off'
                    app.File_FileType.Value        = '';
                    app.File_FilesList.Value       = '';
                                                            
                    File_Layout(app, 'Off')
                    
                    set(app.File_GridA1.Children, 'Enable', 0)
                    
                    delete(app.File_Tree2.Children)
                    app.File_Tree2.Enable = 0;                    
                    
                case 'On'
                    set(app.File_GridA1.Children, 'Enable', 1)
                    app.File_Tree2.Enable = 1;
            end
            
        end
        
        
        function File_Tree2_Building(app)
            
            delete(app.File_Tree2.Children);
            
            ind = app.file_left_Tree.SelectedNodes.NodeData;
            
            Category = {};
            for ii = 1:numel(app.metaData(ind).Data)
                Category = [Category, app.metaData(ind).Data(ii).Node];
            end
            Category = unique(Category);
            
            for ii = 1:numel(Category)
                imageIcon = Fcn_TreeIcon(app, Category{ii});
                TempNode  = uitreenode(app.File_Tree2, 'Text', Category{ii},     ...
                                                       'NodeData', Category{ii}, ...
                                                       'Icon', imageIcon);
                
                for jj = 1:numel(app.metaData(ind).Data)
                    if strcmp(Category{ii}, app.metaData(ind).Data(jj).Node)
                        if ismember(app.metaData(ind).Data(jj).MetaData.DataType, class.Constants.specDataTypes)
                            uitreenode(TempNode, 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz',                         ...
                                                                 app.metaData(ind).Data(jj).ThreadID,                    ...
                                                                 app.metaData(ind).Data(jj).MetaData.FreqStart .* 1e-6,  ...
                                                                 app.metaData(ind).Data(jj).MetaData.FreqStop  .* 1e-6), ...
                                                 'NodeData', jj, 'ContextMenu', app.ContextMenu_File2);
                            
                        else
                            uitreenode(TempNode, 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz (Ocupação)',       ...
                                                                 app.metaData(ind).Data(jj).ThreadID,             ...
                                                                 app.metaData(ind).Data(jj).MetaData.FreqStart .* 1e-6, ...
                                                                 app.metaData(ind).Data(jj).MetaData.FreqStop .* 1e-6), ... 
                                                 'NodeData', jj, 'ContextMenu', app.ContextMenu_File2);
                        end
                    end
                end
            end
            Fcn_expandTree(app, 'File')
            
            app.File_Tree2.SelectedNodes = app.File_Tree2.Children(1).Children(1);
            File_Tree2SelectionChanged(app)
            
        end


        function rawData = Misc_occMap(app, rawData)

            for ii = 1:numel(rawData)
                rawData(ii).reportOCC.Related = [];

                if ismember(rawData(ii).MetaData.DataType, class.Constants.specDataTypes)
                    occInd = [];
                    for jj = 1:numel(rawData)
                        if ismember(rawData(jj).MetaData.DataType, class.Constants.occDataTypes)
                            occInd = [occInd, jj];
                        end
                    end

                    for kk = occInd
                        logEvaluation = strcmp(rawData(ii).Node, rawData(kk).Node)                         & ...
                                        rawData(ii).MetaData.FreqStart  == rawData(kk).MetaData.FreqStart  & ...
                                        rawData(ii).MetaData.FreqStop   == rawData(kk).MetaData.FreqStop   & ...
                                        rawData(ii).MetaData.DataPoints == rawData(kk).MetaData.DataPoints & ...
                                        strcmp(rawData(ii).TaskName, rawData(kk).TaskName);
            
                        if logEvaluation
                            rawData(ii).reportOCC.Related = [rawData(ii).reportOCC.Related, kk];
                        end
                    end
                    
                    if ~isempty(rawData(ii).reportOCC.Related)
                        rawData(ii).reportOCC.Index = rawData(ii).reportOCC.Related(1);
                    end
                end
            end

        end


        function rawData = Misc_statsMatrix(app, rawData)

            for ii = 1:numel(rawData)                
                if ismember(rawData(ii).MetaData.DataType, class.Constants.specDataTypes)
                    rawData(ii).statsData = [min(rawData(ii).Data{2}, [], 2), ...
                                             median(rawData(ii).Data{2},  2), ...
                                             mean(rawData(ii).Data{2},    2), ...
                                             max(rawData(ii).Data{2}, [], 2)];
            
                elseif ismember(rawData(ii).MetaData.DataType, class.Constants.occDataTypes)
                    rawData(ii).statsData = [min(rawData(ii).Data{2}, [], 2), ...
                                             mean(rawData(ii).Data{2},    2), ...
                                             max(rawData(ii).Data{2}, [], 2)];
                end
            end

        end


        function FilesList = Misc_RelatedFiles(app, rawData)
        
            FilesList = {};
            for ii = 1:numel(rawData.Data)
                FilesList = [FilesList; rawData.Data(ii).RelatedFiles.Name];
            end
            FilesList = unique(FilesList);

        end


        function File_specDataReader(app, d)
            
            % Organização dos fluxos de dados e pré-alocação
            File_specDataReader_Aux1(app)
            
            % Leitura dos dados de espectro
            prj_specData = [];
            prj_Info     = [];

            for ii = 1:numel(app.metaData)
                [~, name, ext] = fileparts(app.metaData(ii).File);
                filename = [name ext];
                d.Message = sprintf('<font style="font-size:12;">Em andamento a leitura de dados do espectro do arquivo:\n• %s\n\n%d de %d</font>', filename, ii, numel(app.metaData));
                
                switch lower(ext)
                    case '.bin'
                        fileID = fopen(app.metaData(ii).File);
                        Format = fread(fileID, [1 36], '*char');
                        fclose(fileID);
    
                        if contains(Format, 'CRFS', "IgnoreCase", true)
                            SpecInfo = fileReader.CRFSBin(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                        elseif contains(Format, 'RFlookBin v.1/1', "IgnoreCase", true)
                            SpecInfo = fileReader.RFLookBin(app.metaData(ii).File, 'SingleFile');
                        elseif contains(Format, 'RFlookBin v.2/1', "IgnoreCase", true)
                            SpecInfo = fileReader.RFlookBinV2(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                        end

                    case '.dbm'
                        SpecInfo = fileReader.CellPlanDBM(app.metaData(ii).File, 'SpecData', app.metaData(ii), app.RootFolder);                        

                    case '.sm1809'
                        SpecInfo = fileReader.SM1809(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                    
                    case '.csv'
                        SpecInfo = app.metaData(ii).Data;

                    case '.mat'
                        load(app.metaData(ii).File, '-mat', 'prj_specData', 'prj_Info');
                        
                        if strcmp(app.metaData(ii).Type{1}, 'Spectral data')
                            SpecInfo = prj_specData;
                        else
                            SpecInfo = rmfield(prj_specData, {'Emissions', 'reportFlag', 'reportOCC', 'reportDetection', 'reportClassification', 'reportAttachments'});
                            File_specDataReader_OpenProject(app, prj_Info)
                        end
                end
                SpecInfo = File_removeFields(app, SpecInfo);    
                
                % Eliminar alguns campos para possibilitar comparar o fluxo
                % lido (SpecInfo >> auxSpecInfo) com o já salvo (app.specData >> auxSpecData)
                auxSpecInfo = File_specDataReader_Aux3(app, SpecInfo);
                auxSpecData = File_specDataReader_Aux3(app, app.specData);
                
                for jj = 1:numel(SpecInfo)
                    % Necessário pois no leitor do RFLookBin, por exemplo, não é lida a
                    % informação de GPS.
                    if isempty(SpecInfo(jj).RelatedFiles) || isempty(SpecInfo(jj).RelatedGPS) || isempty(SpecInfo(jj).gps)
                        SpecInfo(jj).RelatedFiles = app.metaData(ii).Data(jj).RelatedFiles;
                        SpecInfo(jj).RelatedGPS   = app.metaData(ii).Data(jj).RelatedGPS;
                        SpecInfo(jj).gps          = app.metaData(ii).Data(jj).gps;
                    end
                    auxSpecInfo_GPS = [SpecInfo(jj).gps.Latitude, SpecInfo(jj).gps.Longitude];
                    
                    ind1 = [];
                    for kk = 1:numel(app.specData)
                        auxSpecData_GPS = [app.specData(kk).gps.Latitude, app.specData(kk).gps.Longitude];
                        Distance_GPS    = geo_lldistkm_v1(auxSpecInfo_GPS, auxSpecData_GPS) * 1000;
                        
                        if isequal(auxSpecData(kk), auxSpecInfo(jj)) && (Distance_GPS <= app.General.Merge.DistanceValue)
                            ind1 = kk;
                            break
                        end
                    end

                    if isempty(ind1)
                        continue
                    end
                    
                    if ii == 1; ind2 = 1;
                    else;       ind2 = sum(app.Samples(ind1, 1:(ii-1)))+1;
                    end
                    ind3 = sum(app.Samples(ind1, 1:ii));
                    
                    app.specData(ind1).Data{1}(1,ind2:ind3) = SpecInfo(jj).Data{1};
                    app.specData(ind1).Data{2}(:,ind2:ind3) = SpecInfo(jj).Data{2};
                    
                    if ~any(contains(app.specData(ind1).RelatedFiles.Name, SpecInfo(jj).RelatedFiles.Name))
                        app.specData(ind1).RelatedFiles(end+1:end+height(SpecInfo(jj).RelatedFiles), :) = SpecInfo(jj).RelatedFiles; 
                        app.specData(ind1).RelatedGPS   = [app.specData(ind1).RelatedGPS,   SpecInfo(jj).RelatedGPS];
                    end
                end
            end
            
            % Manipulações acessórias
            d.Message = '<font style="font-size:12;">Em andamento manipulações acessórias, como aferição de parâmetros estatísticos e identificação de fluxo(s) de ocupação relacionado(s).</font>';
            File_specDataReader_Aux2(app, prj_specData, prj_Info)
            
        end
        
        
        function File_specDataReader_Aux1(app)
            for ii = 1:numel(app.metaData)
                SpecInfo   = File_removeFields(app, app.metaData(ii).Data);
                auxSamples = app.metaData(ii).Samples;
                if isempty(auxSamples); auxSamples = 0;
                end

                % INICIALIZAÇÃO DE APP.SPECDATA
                if ii == 1
                    app.specData = SpecInfo;
                    app.Samples  = auxSamples;
                    continue
                end

                % MAPEAMENTOS ENTRE ARQUIVOS DE MONITORAÇÃO (CASO MAIS DE UM)
                auxSpecInfo = File_specDataReader_Aux3(app, SpecInfo);
                for jj = 1:numel(SpecInfo)
                    auxSpecInfo_GPS = [SpecInfo(jj).gps.Latitude, SpecInfo(jj).gps.Longitude];
                    auxSpecData     = File_specDataReader_Aux3(app, app.specData);

                    idx1 = [];
                    for kk = 1:numel(app.specData)
                        auxSpecData_GPS = [app.specData(kk).gps.Latitude, app.specData(kk).gps.Longitude];
                        Distance_GPS    = geo_lldistkm_v1(auxSpecInfo_GPS, auxSpecData_GPS) * 1000;
                        
                        if isequal(auxSpecData(kk), auxSpecInfo(jj)) && (Distance_GPS <= app.General.Merge.DistanceValue)
                            idx1 = kk;
                            app.Samples(kk, ii) = auxSamples(jj);
                            break
                        end
                    end

                    if isempty(idx1)
                        idx2 = numel(app.specData)+1;
                        
                        app.specData(idx2)    = SpecInfo(jj);
                        app.Samples(idx2, ii) = auxSamples(jj);
                    end
                end
            end
            
            % Pré-alocação
            totalSamples = sum(app.Samples, 2);
            for ii = 1:numel(app.specData)
                app.specData(ii).Samples = totalSamples(ii);
                app.specData(ii).Data    = {repmat(datetime([0 0 0 0 0 0], 'Format', 'dd/MM/yyyy HH:mm:ss'), 1, totalSamples(ii)), ...
                                            zeros(app.specData(ii).MetaData.DataPoints, totalSamples(ii), 'single')};
            end
        end
        
        
        function File_specDataReader_Aux2(app, prj_specData, prj_Info)

            % Ordenação dos fluxos dos dados:
            % - Primário:   Node
            % - Secundário: ThreadID
            tableNode      = {};
            for ii = 1:numel(app.specData)
                tableNode = [tableNode; app.specData(ii).Node];
            end
            [~,    idx1] = sortrows(table(tableNode, [app.specData.ThreadID]'));
            app.specData = app.specData(idx1);
            app.Samples  = app.Samples(idx1,:);
            
            % Fluxos de dados ordenados temporalmente
            for ii = 1:numel(app.specData)
                if ~issorted(app.specData(ii).Data{1})
                    [app.specData(ii).Data{1}, idx2] = sort(app.specData(ii).Data{1});
                    app.specData(ii).Data{2}         = app.specData(ii).Data{2}(:,idx2);
                end

                if height(app.specData(ii).RelatedFiles) > 1
                    app.specData(ii).ObservationTime = sprintf('%s - %s', datestr(app.specData(ii).Data{1}(1), 'dd/mm/yyyy HH:MM:SS'), datestr(app.specData(ii).Data{1}(end), 'dd/mm/yyyy HH:MM:SS'));
                end
                
                % Criação de campos úteis no appAnálise
                app.specData(ii).Emissions            = [];            
                app.specData(ii).reportFlag           = [];
                app.specData(ii).reportOCC            = struct('Related', [], 'Index', [], 'Method', '', 'Parameters', '');
                app.specData(ii).reportDetection      = [];
                app.specData(ii).reportClassification = [];
                app.specData(ii).reportAttachments    = struct('image', '', 'table', struct('Source', '', 'SheetID', 1));
            end
                        
            % Ajuste de alguns campos, no caso de ter sido aberto um
            % projeto appAnálise.
            if ~isempty(prj_Info)
                for ii = 1:numel(prj_specData)
                    idx3 = find([app.specData.ThreadID] == prj_specData(ii).ThreadID);

                    for jj = idx3
                        auxPrjData  = File_specDataReader_Aux3(app, prj_specData(ii));
                        auxSpecData = File_specDataReader_Aux3(app, app.specData(jj));
                        
                        if isequal(rmfield(auxPrjData,  {'Emissions', 'reportFlag', 'reportOCC', 'reportDetection', 'reportClassification', 'reportAttachments'}), ...
                                   rmfield(auxSpecData, {'Emissions', 'reportFlag', 'reportOCC', 'reportDetection', 'reportClassification', 'reportAttachments'}))
                            
                            app.specData(jj).Emissions            = prj_specData(ii).Emissions;
                            app.specData(jj).reportFlag           = prj_specData(ii).reportFlag;
                            app.specData(jj).reportOCC            = prj_specData(ii).reportOCC;
                            app.specData(jj).reportDetection      = prj_specData(ii).reportDetection;
                            app.specData(jj).reportClassification = prj_specData(ii).reportClassification;
                            app.specData(jj).reportAttachments    = prj_specData(ii).reportAttachments;

                            break
                        end
                    end
                end
            end
            
            for ii = 1:numel(app.specData)
                % ESTATÍSTICA DAS INFORMAÇÕES DE GPS CONSTANTE NOS DIVERSOS
                % ARQUIVOS
                gpsTable = table('Size', [0, 4],                                                 ...
                                 'VariableTypes', {'double', 'double',   'double',    'double'}, ...
                                 'VariableNames', {'Status', 'Latitude', 'Longitude', 'Count'});  
                
                for jj = 1:numel(app.specData(ii).RelatedGPS)
                    if app.specData(ii).RelatedGPS{jj}.Status ~= 0
                        gpsTable(end+1,:) = {app.specData(ii).RelatedGPS{jj}.Status,    ...
                                             app.specData(ii).RelatedGPS{jj}.Latitude,  ...
                                             app.specData(ii).RelatedGPS{jj}.Longitude, ...
                                             app.specData(ii).RelatedGPS{jj}.Count};
                    end
                end
                
                if height(gpsTable) > 1
                    app.specData(ii).gps.Status    = min(gpsTable.Status(gpsTable.Status ~= 0));
                    app.specData(ii).gps.Latitude  = sum(gpsTable.Count .* gpsTable.Latitude)  ./ sum(gpsTable.Count);
                    app.specData(ii).gps.Longitude = sum(gpsTable.Count .* gpsTable.Longitude) ./ sum(gpsTable.Count);
                    app.specData(ii).gps.Location  = geo_FindCity(app.specData(ii).gps);
                end
                
                if isempty(app.specData(ii).reportFlag)
                    app.specData(ii).reportFlag = 0;
                end
                
                % ESTATÍSTICA DOS FLUXOS DE ESPECTRO E O SEU MAPEAMENTO COM OS FLUXOS DE OCC
                if ismember(app.specData(ii).MetaData.DataType, class.Constants.specDataTypes)
                    app.specData(ii).statsData = [min(app.specData(ii).Data{2}, [], 2), ...
                                                  median(app.specData(ii).Data{2},  2), ...
                                                  mean(app.specData(ii).Data{2},    2), ...
                                                  max(app.specData(ii).Data{2}, [], 2)];
            
                    occInd = [];
                    for jj = 1:numel(app.specData)
                        if ismember(app.specData(jj).MetaData.DataType, class.Constants.occDataTypes)
                            occInd = [occInd, jj];
                        end
                    end

                    for kk = occInd
                        logEvaluation = strcmp(app.specData(ii).Node, app.specData(kk).Node)                         & ...
                                        app.specData(ii).MetaData.FreqStart  == app.specData(kk).MetaData.FreqStart  & ...
                                        app.specData(ii).MetaData.FreqStop   == app.specData(kk).MetaData.FreqStop   & ...
                                        app.specData(ii).MetaData.DataPoints == app.specData(kk).MetaData.DataPoints & ...
                                        strcmp(app.specData(ii).TaskName, app.specData(kk).TaskName);
            
                        if logEvaluation
                            app.specData(ii).reportOCC.Related = [app.specData(ii).reportOCC.Related, kk];
                        end
                    end
                    
                    if ~isempty(app.specData(ii).reportOCC.Related)
                        app.specData(ii).reportOCC.Index = app.specData(ii).reportOCC.Related(1);
                    end
                    
                % ESTATÍSTICA DOS FLUXOS DE OCC
                elseif ismember(app.specData(ii).MetaData.DataType, class.Constants.occDataTypes)
                    app.specData(ii).statsData = [min(app.specData(ii).Data{2}, [], 2), ...
                                                  mean(app.specData(ii).Data{2},    2), ...
                                                  max(app.specData(ii).Data{2}, [], 2)];
                end
            end
            
        end
        
        
        function Data = File_specDataReader_Aux3(app, ReferenceData)
            
            Data = rmfield(ReferenceData, [{'ObservationTime'; 'Samples'; 'Data'; 'statsData'; 'RelatedFiles'; 'RelatedGPS'; 'gps'}; ...
                                           app.General.Merge.OptionalFieldsToRemove]);
            
            for ii = 1:numel(Data)
                Data(ii).MetaData = rmfield(Data(ii).MetaData, 'SampleTime');
                
                if strcmp(app.General.Merge.ThreadIDStatus, 'remove')
                    Data(ii).MetaData = rmfield(Data(ii).MetaData, 'ThreadID');
                end
                
                if strcmp(app.General.Merge.DataTypeStatus, 'remove')
                    Data(ii).MetaData = rmfield(Data(ii).MetaData, 'DataType');
                end
                
                if strcmp(app.General.Merge.AntennaNameStatus, 'remove')
                    Data(ii).MetaData = rmfield(Data(ii).MetaData, 'metaString');
                end              
            end            
        end


        %-----------------------------------------------------------------%
        function SpecInfo = File_removeFields(app, SpecInfo)
            if isfield(SpecInfo, 'Blocks');   SpecInfo = rmfield(SpecInfo, 'Blocks');
            end

            if isfield(SpecInfo, 'UserData'); SpecInfo = rmfield(SpecInfo, 'UserData');
            end
        end


        %-----------------------------------------------------------------%
        function File_specDataReader_OpenProject(app, prj_Info)

            idx = find(ismember([app.metaData.Type], {'Project data'}));
            app.ProjectFilename.Value = app.metaData(idx).File;
            
            app.ProjectName.Value    = prj_Info.Name;
            app.peaksTable           = prj_Info.peaksTable;
            app.exceptionList        = prj_Info.exceptionList;
            app.Report_Issue.Value   = prj_Info.reportInfo.Issue;
            app.Report_Version.Value = prj_Info.reportInfo.General.Version;

            Type = prj_Info.reportInfo.Model.Name;
            if ismember(Type, app.Report_Type.Items)
                app.Report_Type.Value        = Type;
            else
                app.Report_Type.Items{end+1} = Type;
                app.Report_Type.Value        = app.Report_Type{end};
                
                app.General.Models(end+1,:)  = prj_Info.reportInfo.Name;
            end

            app.projData                 = prj_Info.reportInfo.Attachments;

            app.Report_DatePicker1.Value = prj_Info.reportInfo.TimeStamp(1);
            app.Report_Spinner1.Value    = hour(prj_Info.reportInfo.TimeStamp(1));
            app.Report_Spinner2.Value    = minute(prj_Info.reportInfo.TimeStamp(1));
                        
            app.Report_DatePicker2.Value = prj_Info.reportInfo.TimeStamp(2);
            app.Report_Spinner3.Value    = hour(prj_Info.reportInfo.TimeStamp(2));
            app.Report_Spinner4.Value    = minute(prj_Info.reportInfo.TimeStamp(2));
            
            app.Report_ImageWarn.Visible = 0;
                        
        end
        
        
        function File_specDataReader_Error(app)
            
            app.Samples  = [];
            app.specData = [];
            
            if ~isempty(app.playReport_left_Tree.Children)
                delete(app.playReport_left_Tree.Children)
            end
            
            if ~isempty(app.Report_Tree.Children)
                delete(app.Report_Tree.Children)
            end

            Playback_PlotReload(app)
            play_tool_Button4ButtonPushed(app)
            
            app.menu_Button3.Enable = 0;
            app.menu_Button4.Enable = 0;
            app.menu_Button5.Enable = 0;
            app.menu_Button6.Enable = 0;
            
            app.menu_Button2.Value  = 1;
            menu_Button1to6Pushed(app, struct('Source', app.menu_Button2))
            
        end


        function File_mainInfoTreeBuilding(app)
            
            delete(app.playReport_left_Tree.Children);
            
            app.playReport_left_DownArrow.ImageSource = 'LT_downArrow.png';
            app.playReport_left_Metadata.Value         = '';
            
            for ii = 1:length(app.specData)
                allNodes{ii,1} = app.specData(ii).Node;
            end
            [uniqueNodes, ~, ic] = unique(allNodes);
            
            for ii = 1:length(uniqueNodes)
                idx1 = find(ic == ii)';

                imageIcon = Fcn_TreeIcon(app, uniqueNodes{ii});
                Category  = uitreenode(app.playReport_left_Tree, 'Text', uniqueNodes{ii}, 'NodeData', idx1, 'Icon', imageIcon);
                                
                for jj = idx1
                    occMsg = '';
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.occDataTypes)
                        occMsg = '(Ocupação)';
                    end

                    Attributes = uitreenode(Category, 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz %s',        ...
                                                                      app.specData(jj).ThreadID,                ...
                                                                      1e-6*app.specData(jj).MetaData.FreqStart, ...
                                                                      1e-6*app.specData(jj).MetaData.FreqStop,  ...
                                                                      occMsg),                                  ...
                                                      'NodeData', jj);

                    % OBSERVAÇÃO:
                    uitreenode(Attributes, 'Text', sprintf('%s - %s', datestr(app.specData(jj).Data{1}(1),   'dd/mm/yyyy HH:MM:SS'),  ...
                                                                      datestr(app.specData(jj).Data{1}(end), 'dd/mm/yyyy HH:MM:SS')), ...
                                           'NodeData', jj);
                        
                    % GPS
                    if app.specData(jj).gps.Status == -1
                        uitreenode(Attributes, 'Text', sprintf('%.6f, %.6f (%s)', app.specData(jj).gps.Latitude,  ...
                                                                                  app.specData(jj).gps.Longitude, ...
                                                                                  app.specData(jj).gps.Location), ...
                                               'NodeData', jj, 'Icon', 'LT_manual.png');

                    elseif app.specData(jj).gps.Status == 0
                        uitreenode(Attributes, 'Text', 'GPS', 'NodeData', jj, 'Icon', 'LT_redWarn.png');

                    else
                        uitreenode(Attributes, 'Text', sprintf('%.6f, %.6f (%s)', app.specData(jj).gps.Latitude,  ...
                                                                                  app.specData(jj).gps.Longitude, ...
                                                                                  app.specData(jj).gps.Location), ...
                                               'NodeData', jj);
                    end

                    % THRESHOLD
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.specDataTypes)
                        if ~isempty(app.specData(jj).reportOCC.Related)
                        Temp2 = uitreenode(Attributes, 'Text', 'Fluxo(s) de ocupação', 'NodeData', jj);

                            if isempty(app.specData(jj).reportOCC.Index)
                                uitreenode(Temp2, 'Text', 'Ocupação a ser aferida', 'NodeData', jj, 'Icon', 'LT_manual.png');
                            end
                            
                            idx2 = app.specData(jj).reportOCC.Related;
                            for kk = idx2
                                if (app.specData(jj).reportOCC.Index == kk) & (kk == idx2(1))
                                    uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)',          ...
                                                                       app.specData(kk).MetaData.Threshold,      ...
                                                                       app.specData(kk).MetaData.metaString{1},  ...
                                                                       app.specData(kk).ThreadID),               ...
                                                      'NodeData', jj, 'Icon', 'LT_auto.png');
                                else
                                    if app.specData(jj).reportOCC.Index == kk
                                        uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)',         ...
                                                                           app.specData(kk).MetaData.Threshold,     ...
                                                                           app.specData(kk).MetaData.metaString{1}, ...
                                                                           app.specData(kk).ThreadID),              ...
                                                          'NodeData', jj, 'Icon', 'LT_manual.png');
                                    else
                                        uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)',         ...
                                                                           app.specData(kk).MetaData.Threshold,     ...
                                                                           app.specData(kk).MetaData.metaString{1}, ...
                                                                           app.specData(kk).ThreadID),              ...
                                                          'NodeData', jj);
                                    end
                                end                     
                            end
                        end

                    else
                        uitreenode(Attributes, 'Text', sprintf('Threshold: %d %s', app.specData(jj).MetaData.Threshold, app.specData(jj).MetaData.metaString{1}), 'NodeData', jj);
                    end
                end
            end

            Fcn_expandTree(app, 'mainInfo')
            
        end


        %-----------------------------------------------------------------%
        % Modo "PLAYBACK" 
        %-----------------------------------------------------------------%
        function Playback_Layout(app)

            idx = [];
            if ~isempty(app.playReport_left_Tree.SelectedNodes)
                idx = app.playReport_left_Tree.SelectedNodes.NodeData;
            end
            
            if isempty(idx)
                app.traceInfo.SelectedNode = 0;
                app.traceInfo.Mode         = [0, 0, 0];
                
                Playback_Layout_Spec(app)
                
            elseif numel(idx) == 1
                if idx ~= app.traceInfo.SelectedNode
                    if ismember(app.specData(idx).MetaData.DataType, class.Constants.specDataTypes)
                        if app.occFlag
                            app.occFlag = 0;
                            Playback_Layout_Spec(app)
                        end
                        
                    else
                        if ~app.occFlag
                            app.occFlag = 1;
                            Playback_Layout_occ(app)
                        end
                        
                        switch app.traceInfo.plotLayout
                            case 1; app.traceInfo.plotLayout = 0;
                            case 3; app.traceInfo.plotLayout = 2;
                        end
                    end
                    traceInfoCallback1_SelectedNode_Mode(app)
                    Playback_RestoreView(app, idx)
                end
                Playback_TreeBuilding(app, 1)
            end
            
        end

        
        function Playback_Layout_Spec(app)
            
            set(app.play_Average,   'Value', 0, 'Enable', 1)
            set(app.play_MinHold,   'Value', 0, 'Enable', 1)
            set(app.play_MaxHold,   'Value', 0, 'Enable', 1)
            set(app.play_Occupancy, 'Value', 0, 'Enable', 1)
            
            % peakPanel            
            if app.play_FindPeaks_Radio3.Value
                app.play_FindPeaks_Radio1.Value = 1;
            end
            app.play_FindPeaks_Radio3.Enable = 1;
            play_FindPeaks_RadioGroupSelectionChanged(app)
            Playback_TreeBuilding(app, 1)
            
            % occPanel
            set(findall(groot, 'Parent', app.GridLayout23, '-not', 'Type', 'uipanel'), 'Enable', 0)
            set(app.Playback_occGrid2.Children, 'Enable', 0)
            
            % traceInfoCallback2_plotLayout(app)
            
        end
        
        
        function Playback_Layout_occ(app)

            set(app.play_Average,   'Value', 0, 'Enable', 0)
            set(app.play_MinHold,   'Value', 0, 'Enable', 0)
            set(app.play_MaxHold,   'Value', 0, 'Enable', 0)
            set(app.play_Occupancy, 'Value', 0, 'Enable', 0)
            
            % peakPanel
            if app.play_FindPeaks_Radio3.Value
                app.play_FindPeaks_Radio1.Value = 1;
            end
            app.play_FindPeaks_Radio3.Enable = 0;             
            play_FindPeaks_RadioGroupSelectionChanged(app)
            Playback_TreeBuilding(app, 1)

            % occPanel            
            set(findall(groot, 'Parent', app.GridLayout23, '-not', 'Type', 'uipanel'), 'Enable', 0)
            set(app.Playback_occGrid2.Children, 'Enable', 0)
            
        end
        
        
        function Playback_Layout_occPlot(app)
            
            if app.play_Occupancy.Value
                Playback_occTypeValueChanged(app)
                
                app.Playback_occTypeLabel.Enable = 1;
                app.Playback_occType.Enable      = 1;
                
            else
                set(findall(groot, 'Parent', app.GridLayout23, '-not', 'Type', 'uipanel'), 'Enable', 0)
                set(app.Playback_occGrid2.Children, 'Enable', 0)
            end
            
        end


        function Playback_PlotReload(app)

            app.playReport_docPanel.UserData = [];

            app.axes1.Title.String    = '';
            app.axes1.Subtitle.String = '';
            
            cla(app.axes1)
            cla(app.axes2)
            cla(app.axes3)

        end


        function Playback_TreeBuilding(app, selectedEmission)

            if ~isempty(app.play_FindPeaks_Tree.Children)
                delete(app.play_FindPeaks_Tree.Children)
            end
            
            ind1 = app.traceInfo.SelectedNode;

            if app.traceInfo.SelectedNode                
                if ~isempty(app.specData(ind1).Emissions)
                    for ii = 1:height(app.specData(ind1).Emissions)
                        if app.specData(ind1).Emissions.Type(ii); Icon = 'play_Truncated.png';
                        else;                                     Icon = 'play_Untruncated.png';
                        end
    
                        uitreenode(app.play_FindPeaks_Tree, 'Text', sprintf('%d: %.3f MHz ⌂ %.3f kHz', ii, app.specData(ind1).Emissions{ii,2}, app.specData(ind1).Emissions{ii,3}), ...
                                                          'NodeData', ii, 'Icon', Icon, 'ContextMenu', app.play_ContextMenu2);
                    end
    
                    app.play_FindPeaks_Tree.SelectedNodes = app.play_FindPeaks_Tree.Children(selectedEmission);
                end                
            end
            play_FindPeaks_TreeSelectionChanged(app)
            
        end
        
        
        function Playback_RestoreView(app, idx)
            
                if ismember(app.specData(idx).MetaData.DataType, class.Constants.specDataTypes)
                    auxValue = min(app.specData(idx).statsData(:,1));
                    downYLim = auxValue - mod(auxValue, 10);
                
                    auxValue = max(app.specData(idx).statsData(:,end));
                    upYLim   = auxValue - mod(auxValue, 10) + 10;                    
                else
                    downYLim = 0;
                    upYLim   = 100;
                end                
                app.RestoreView = {[app.specData(idx).MetaData.FreqStart, app.specData(idx).MetaData.FreqStop] ./ 1e+6, [downYLim, upYLim]};
            
        end


        function Playback_xLimits(app, src, evt)

            app.play_Limits_xLim1.Value = round(app.axes1.XLim(1), 3);
            app.play_Limits_xLim2.Value = round(app.axes1.XLim(2), 3);

        end
                
        
        function Playback_yLimits(app, src, evt)

            app.play_Limits_yLim1.Value = round(double(app.axes1.YLim(1)), 1);
            app.play_Limits_yLim2.Value = round(double(app.axes1.YLim(2)), 1);

        end

        
        function Plot(app)
            
            t1 = 0;
            while app.timeIndex <= app.specData(app.traceInfo.SelectedNode).Samples
                if ~app.plotFlag
                    return
                elseif app.axesFlag
                    Plot_Layout1(app)
                    Plot_Layout2(app)
                    Plot_StartUp1(app)
                    Plot_StartUp2(app);
                    Plot_StartUp3(app)
                end
                idx = app.traceInfo.SelectedNode;
                
% %             TimeStamp and Slider Update
                tic
                
% %             Axes 1: Spectrum plot ans its markers
                if app.traceInfo.Persistance & app.play_Persistance_Samples.Value ~= "full"
                    Plot_Persistance_Update(app, idx)
                end

                app.line_ClearWrite.YData = app.specData(idx).Data{2}(:, app.timeIndex)';
                
                for ii = 1:numel(app.mkr_Label)
                    app.mkr_Label(ii).Position(2) = app.line_ClearWrite.YData(app.line_ClearWrite.MarkerIndices(ii));
                end
                
% %             Axes2: OCC Plot
                if ismember(app.traceInfo.plotLayout, [1,3])
                    switch app.Playback_occType.Value
                        case 'Linear'
                            app.axes2.Children(1).YData = ((app.Playback_occTime.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.line_ClearWrite.YData > app.Playback_occValue.Value))/app.Playback_occTime.Value;
                        case 'Piso de ruído (Offset)'
                            app.axes2.Children(1).YData = ((app.Playback_occTime.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.line_ClearWrite.YData > app.occTHR))/app.Playback_occTime.Value;
                    end
                    app.axes2.Children(2).YData = max(app.axes2.Children(1).YData, app.axes2.Children(2).YData);
                end

% %             Axes3: WaterFall Plot
                if ismember(app.traceInfo.plotLayout, [2, 3])
                    timestampValue = repmat(app.specData(idx).Data{1}(app.timeIndex), 1, 2);
                    app.line_wfTime.YData = timestampValue;
                end
                drawnow

% %             Miscelâneas                
                app.play_right_Slider.Value = 100 * app.timeIndex/app.specData(idx).Samples;
                Misc_DriveTestUpdate(app)
                
% %             Pause
                pause(app.play_MinPlotTime.Value/1000 - toc)
                t1 = (9*t1+toc)/10;                
                app.axes1.Title.String{4} = sprintf('%s (%d de %d). Tempo de escrita: %.0f ms.', ...
                                            datestr(app.specData(idx).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS'), ...
                                            app.timeIndex, app.specData(idx).Samples, 1000*t1);
                
% %             Reload Flag
                if app.timeIndex == app.specData(idx).Samples
                    if app.play_right_Loop.Tag == "loop"; app.timeIndex = 1;
                    else;                           break
                    end
                else
                    app.timeIndex = app.timeIndex+1;
                end                
            end
            
            plotFcn.toolbar_playback(app)
            
        end
        
        
        function Plot_Layout1(app)
            
            cla(app.axes1)
            cla(app.axes2)
            cla(app.axes3)
            
            app.timeIndex             = 1;
            app.play_right_Slider.Value = 0;
            
            app.line_ClearWrite = [];
            app.line_OCC        = [];
            app.line_OCCLabel   = [];
            app.mkr_ROI         = [];
            app.mkr_Label       = [];
            app.line_wfTime     = [];
            
% %         Desabilita WaterFall e OCC caso fluxo de dados tenha até duas amostras.    
            if app.specData(app.traceInfo.SelectedNode).Samples > 2
                if ~app.occFlag
                    app.play_Occupancy.Enable = 1;
                end
                app.play_Waterfall.Enable = 1;

            else
                set(app.play_Occupancy, 'Enable', 0, 'Value', 0)
                plotFcn.toolbar_occ(app)

                set(app.play_Waterfall, 'Enable', 0, 'Value', 0)
                plotFcn.toolbar_waterfall(app)
            end
            
        end
            
        
        function Plot_Layout2(app)
            
% %         Layout
            linkaxes([app.axes1, app.axes2, app.axes3], 'off')
                        
            switch app.traceInfo.plotLayout
                case 0

                    
                    app.axes1.XTickLabelMode = 'auto';

                    xlabel(app.axes1, 'Frequência (MHz)')
                    
                case 1

                    
                    app.axes1.XTickLabel     = {};
                    app.axes2.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, 'Frequência (MHz)')
                    
                    linkaxes([app.axes1, app.axes2], 'x')

                case 2
                    switch app.play_ClearWriteVisibility_2.Value
                        case '1:1'


                        case '1:2'
                            % Pendente
                        
                        case '1:3'

                    end
                    
                    app.axes1.XTickLabel = {};
                    app.axes3.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (MHz)')
                    
                    linkaxes([app.axes1, app.axes3], 'x')

                case 3
                    switch app.play_ClearWriteVisibility_2.Value
                        case '1:1'
                            app.axes1.InnerPosition = [.08 .58 .85 .30];
                            app.axes2.InnerPosition = [.08 .40 .85 .16];
                            app.axes3.InnerPosition = [.08 .08 .85 .30];
                        
                        case '1:2'
                            % Pendente

                        case '1:3'
                            app.axes1.InnerPosition = [.08 .72 .85 .16];
                            app.axes2.InnerPosition = [.08 .54 .85 .16];
                            app.axes3.InnerPosition = [.08 .08 .85 .44];
                    end
                    
                    app.axes1.XTickLabel = {};
                    app.axes2.XTickLabel = {};
                    app.axes3.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (MHz)')
                    
                    linkaxes([app.axes1, app.axes2, app.axes3], 'x')
            end
            
        end
        
        
        function Plot_StartUp1(app)
            
            ind1 = app.traceInfo.SelectedNode;
            
% %         Axes1 (Spectrum plot): Figure UserData, title and subtitle.
            app.playReport_docPanel.UserData = app.playReport_left_Tree.SelectedNodes;

            metaString = app.specData(ind1).MetaData.metaString([3,4,2]);
            idx1       = cellfun(@(x) ~isempty(x), metaString);
            metaString = strjoin(metaString(idx1), ', ');

            app.axes1.Title.String = sprintf(['\\fontsize{8}\\bf%s\n'                               ...
                                              '\\fontsize{7}\\rm%.3f - %.3f MHz, %s, %.0f pontos\n' ...
                                              'GPS: %.6f, %.6f (%s)'],                      ...
                                              app.specData(ind1).Node,                      ...
                                              app.specData(ind1).MetaData.FreqStart / 1e+6, ...
                                              app.specData(ind1).MetaData.FreqStop  / 1e+6, ...
                                              metaString,                             ...
                                              app.specData(ind1).MetaData.DataPoints, ...
                                              app.specData(ind1).gps.Latitude,        ...
                                              app.specData(ind1).gps.Longitude,       ...
                                              app.specData(ind1).gps.Location);
            
            app.axes1.Title.String{4} = sprintf('%s (%d de %d)',                                               ...
                                                datestr(app.specData(ind1).Data{1}(1), 'dd/mm/yyyy HH:MM:SS'), ...
                                                app.timeIndex, app.specData(ind1).Samples);
            
            if ~ismember(app.specData(ind1).MetaData.DataType, class.Constants.occDataTypes)
                app.Unit = app.specData(ind1).MetaData.metaString{1};
                yLabel   = sprintf('Nível (%s)', app.Unit);
                app.play_FindPeaks_THRLabel.Text{2} = sprintf('(%s):', app.Unit);
            else
                yLabel   = 'Ocupação (%)';
                app.Unit = '%';
            end
            
            set(app.axes1, XLim=app.RestoreView{1}, YLim=app.RestoreView{2})
            ylabel(app.axes1, yLabel);
            
% %          Axes 2 (OCC plot)
            if app.play_Occupancy.Value
                Plot_StartUp1_OCC(app)
            end
            
% %          Axes 3 (WaterFall plot)
            if app.play_Waterfall.Value
                set(app.axes3, XLim=app.axes1.XLim)
                ylabel(app.axes3, 'Amostras');
            end
            
        end


        function Plot_StartUp1_OCC(app)

            set(app.axes2, XLim=app.axes1.XLim, YLim=[0, 100]);
            ylabel(app.axes2, 'Ocupação (%)');

        end
        
        
        function Plot_StartUp2(app)
            
            ind1 = app.traceInfo.SelectedNode;
            
            app.x = round(linspace(app.specData(ind1).MetaData.FreqStart ./ 1e+6, ...
                                   app.specData(ind1).MetaData.FreqStop  ./ 1e+6, ...
                                   app.specData(ind1).MetaData.DataPoints), 3);
            
            app.Span  = (app.specData(ind1).MetaData.FreqStop - app.specData(ind1).MetaData.FreqStart);
            app.aCoef = app.Span ./ (app.specData(ind1).MetaData.DataPoints - 1);
            app.bCoef = app.specData(ind1).MetaData.FreqStart - app.aCoef;

            if app.play_Persistance.Value                                 % Persistance
                Plot_StartUp2_Persistance(app)
            end
            
            Plot_StartUp2_Spectrum(app, 'InitialPlot', ind1, 1)         % Main trace (ClearWrite) and its markers
            Plot_StartUp2_TraceMode(app, ind1)                          % Optional traces (MinHold, Median/Mean, MaxHold)

            if app.play_Occupancy.Value                                         % OCC
                Plot_StartUp2_OCC(app, ind1)
            end
            
            if app.play_Waterfall.Value
                Plot_StartUp2_WaterFall(app)                            % WaterFall
            end
            drawnow nocallbacks
            app.axesFlag = 0;
            
        end
        
        
        function Plot_StartUp2_Spectrum(app, Type, ind1, selectedEmission)
            
            % Startup
            switch Type
                case 'InitialPlot'
                    app.line_ClearWrite = plot(app.axes1, app.x, app.specData(ind1).Data{2}(:, app.timeIndex), Tag='ClearWrite',                   ...
                                               Color=app.General.Colors(4,:), Marker='.', MarkerIndices=[], MarkerFaceColor=[0.40,0.73,0.88], ...
                                               MarkerEdgeColor=[0.40,0.73,0.88], MarkerSize=14, Visible=app.play_ClearWriteVisibility.Value);

                case 'TreeSelectionChanged'
                    ind2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;
                    app.mkr_ROI.Position(:, [1, 3]) = [app.specData(ind1).Emissions.Frequency(ind2)-app.specData(ind1).Emissions.BW(ind2)/(2*1000), ...
                                                        app.specData(ind1).Emissions.BW(ind2)/1000];
                
                case {'AddButtonPushed', 'PeakValueChanged'}
                    delete(findobj('Tag', 'mkrTemp', '-or', 'Tag', 'mkrLine', '-or', 'Tag', 'mkrLabels'))

                case 'DeleteButtonPushed'
                    delete(findobj('Tag', 'mkrTemp', '-or', 'Tag', 'mkrLine', '-or', 'Tag', 'mkrLabels', '-or', 'Tag', 'mkrROI'))
                    
                    app.mkr_ROI       = [];
                    app.mkr_Label = [];
            end

            % Processing...
            switch Type
                case {'InitialPlot', 'AddButtonPushed', 'PeakValueChanged', 'DeleteButtonPushed'}
                    Playback_TreeBuilding(app, selectedEmission)

                    if isempty(app.specData(ind1).Emissions)
                        app.line_ClearWrite.MarkerIndices = [];

                    else
                        app.line_ClearWrite.MarkerIndices = app.specData(ind1).Emissions.Index;

                        mkrLabels = {};
                        for ii = 1:height(app.specData(ind1).Emissions)
                            mkrLabels = [mkrLabels {['  ' num2str(ii)]}];
        
                            FreqStart = app.specData(ind1).Emissions.Frequency(ii)-app.specData(ind1).Emissions.BW(ii)/(2*1000);
                            FreqStop  = app.specData(ind1).Emissions.Frequency(ii)+app.specData(ind1).Emissions.BW(ii)/(2*1000);
                            BW        = app.specData(ind1).Emissions.BW(ii)/1000;
                            Level     = app.RestoreView{2}(1)+1;
                            
                            % Cria uma linha por emissão, posicionando-o na parte inferior
                            % do plot.
                            line(app.axes1, [FreqStart, FreqStop], [Level, Level], ...
                                            Color=[0.40,0.73,0.88], LineWidth=1, ...
                                            Marker='.',             MarkerSize=14, ...
                                            PickableParts='none',   Tag='mkrLine')
            
                            % Cria um ROI para a emissão selecionada, posicionando-o em
                            % todo o plot.
                            if ii == selectedEmission
                                newPosition = [FreqStart, app.RestoreView{2}(1)+1, ...
                                               BW,        app.RestoreView{2}(2)-app.RestoreView{2}(1)-2];
                                
                                if isempty(app.mkr_ROI)
                                    Plot_StartUp2_ROI(app, newPosition)
                                else
                                    app.mkr_ROI.Position = newPosition;
                                end
                            end
                        end
                        uistack(app.line_ClearWrite, 'top');

                        app.mkr_Label = text(app.axes1, app.specData(ind1).Emissions.Frequency, double(app.specData(ind1).Data{2}(app.specData(ind1).Emissions.Index, app.timeIndex)), mkrLabels, ...
                                                             Color=[0.40,0.73,0.88], FontSize=7, FontWeight='bold', FontName='Helvetica', FontSmoothing='on', Tag='mkrLabels');
                    end
            end

        end


        function Plot_StartUp2_ROI(app, newPosition)

            app.mkr_ROI = images.roi.Rectangle(app.axes1, Position=newPosition, ...
                                                           Color=[0.40,0.73,0.88], ...
                                                           MarkerSize=5,           ...
                                                           Deletable=0,            ...
                                                           LineWidth=1,            ...
                                                           Tag='mkrROI');

            addlistener(app.mkr_ROI, 'MovingROI', @app.mkrLineROI);
            addlistener(app.mkr_ROI, 'ROIMoved',  @app.mkrLineROI);

        end
        
        
        function Plot_StartUp2_TraceMode(app, ind1)
            
            auxIndex1 = find(app.traceInfo.Mode ~= 0);
            if ~isempty(auxIndex1)
                    for ii = auxIndex1                        
                        switch ii
                            case 1; auxStr = 'MinHold';
                            case 2
                                switch app.traceInfo.Mode(2)
                                    case 2; auxStr = 'medianFcn';
                                    case 3; auxStr = 'meanFcn';
                                end
                            case 3; auxStr = 'MaxHold';
                        end
                        plot(app.axes1, app.x, app.specData(ind1).statsData(:, app.traceInfo.Mode(ii)), ...
                             'Tag', auxStr, 'Color', app.General.Colors(ii,:));
                    end
            end
            
        end


        function Plot_StartUp2_Persistance(app)

            % SELECTED DATA
            ind = app.traceInfo.SelectedNode;
            
            FreqStart  = app.specData(ind).MetaData.FreqStart / 1e+6;
            FreqStop   = app.specData(ind).MetaData.FreqStop  / 1e+6;
            DataPoints = app.specData(ind).MetaData.DataPoints;


            % IMAGE
            % (a) RESOLUTION (801x201 pixels)
            yResolution   = 201;
            xResolution   = 801;
            if DataPoints < xResolution; xResolution = DataPoints;
            end

            % (b) INTERPOLATION, LEVEL AMPLITUDE AND ALPHADATA
            Interpolation = app.play_Persistance_Interpolation.Value;
            yAmplitude    = 100;

            % (c) WINDOW SIZE (PLAYBACK)
            ySamples = str2double(app.play_Persistance_Samples.Value);
            if isnan(ySamples); ySamples = app.specData(ind).Samples;
            end


            % PROCESSING...
            yMin = min(app.specData(ind).Data{2}, [], 'all');
            yMax = yMin+yAmplitude;
            
            xEdges = linspace(FreqStart, FreqStop, xResolution+1);
            yEdges = linspace(yMin, yMax, yResolution+1);            
            
            specHist = zeros(yResolution, xResolution);
            hImg = image(app.axes1, specHist, 'alphadata', im2double(specHist),'XData', [FreqStart, FreqStop], 'YData', [yMin, yMax], 'Tag', 'Persistance');
            set(hImg, CDataMapping = "scaled", Interpolation = Interpolation, AlphaData = im2double(specHist))
            
            app.obj_Persistance = struct('handle',   hImg,     ...
                                         'ySamples', ySamples, ...
                                         'xEdges',   xEdges,   ...
                                         'yEdges',   yEdges);
            
            Plot_Persistance_Update(app, ind)

            app.play_Persistance_cLim1.Value = app.axes1.CLim(1);
            app.play_Persistance_cLim2.Value = app.axes1.CLim(2);

            uistack(hImg, 'bottom')

        end


        function Plot_Persistance_Update(app, ind)

            if app.play_Persistance_Samples.Value == "full"
                specHist = histcounts2(app.specData(ind).Data{2}, repmat(app.x', 1, app.specData(ind).Samples), ...
                                       app.obj_Persistance.yEdges, ...
                                       app.obj_Persistance.xEdges);

            else
                idx2 = app.timeIndex;
                idx1 = idx2 - (app.obj_Persistance.ySamples-1);
                if idx1 < 1; idx1 = 1;
                end            
                
                specHist = histcounts2(app.specData(ind).Data{2}(:, idx1:idx2), repmat(app.x', 1, idx2-idx1+1), ...
                                       app.obj_Persistance.yEdges, ...
                                       app.obj_Persistance.xEdges);
            end

            set(app.obj_Persistance.handle, 'CData', (100 * specHist ./ sum(specHist)), ...
                                            'AlphaData', double(logical(specHist))*app.play_Persistance_Transparency.Value)
            
            drawnow

        end
        
        

        function Plot_StartUp2_OCC(app, ind1)
            
            occObj = findobj('Tag', 'occROI');

            if isempty(occObj)
                if strcmp(app.specData(ind1).MetaData.metaString{1}, 'dBm') && (app.Playback_occValue.Value > 0)
                    app.Playback_occValueLabel.Text{2} = '(dBm):';
                    app.Playback_occValue.Value        = -80;

                elseif strcmp(app.specData(ind1).MetaData.metaString{1}, 'dBµV') && (app.Playback_occValue.Value < 0)
                    app.Playback_occValueLabel.Text{2} = '(dBµV):';
                    app.Playback_occValue.Value        = 27;
                    
                elseif strcmp(app.specData(ind1).MetaData.metaString{1}, 'dBµV/m') && (app.Playback_occValue.Value < 0)
                    app.Playback_occValueLabel.Text{2} = '(dBµV/m):';
                    app.Playback_occValue.Value        = 40;
                end
                
                if app.Playback_occValue.Value > app.axes1.YLim(2)
                    app.Playback_occValue.Value = double(app.axes1.YLim(2));
                end
                
                switch app.Playback_occType.Value
                    case 'Linear'
                        app.occTHR   = app.Playback_occValue.Value;
                        app.line_OCC = images.roi.Line(app.axes1,'Position',[app.x(1) app.Playback_occValue.Value; app.x(end) app.Playback_occValue.Value], 'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate', 'Tag', 'occROI');
                        
                        addlistener(app.line_OCC, 'MovingROI', @app.occLineROI);
                        addlistener(app.line_OCC, 'ROIMoved',  @app.occLineROI);
                        
                    case 'Piso de ruído (Offset)'
                        occInfo = struct('Offset',   app.Playback_occOffset.Value,                              ...
                                         'Fcn',      app.Playback_occFcn.Value,                                 ...
                                         'Samples', [app.Playback_tSamples.Value, app.Playback_uSamples.Value], ...
                                         'Factor',   app.Playback_occConfLevel.Value);

                        app.occTHR   = Misc_occThreshold('Piso de ruído (Offset)', app.specData(ind1), occInfo);
                        app.line_OCC = plot(app.axes1, app.x, app.occTHR, 'Color', 'red', 'LineStyle', '-', 'LineWidth', 1, 'Marker', 'o', 'MarkerSize', 4, 'MarkerIndices', [1, app.specData(ind1).MetaData.DataPoints], 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'black', 'Tag', 'occROI');
                end
                app.line_OCCLabel = text(app.axes1, app.x(end), double(app.occTHR(end)), '  THR', 'FontName', 'Helvetica', 'FontSize', 7, 'Color', 'red', 'Tag', 'occROI');
                
                plot(app.axes2, app.x, zeros(1, numel(app.line_ClearWrite.YData), 'single'), 'Tag', 'maxOCC',  'Color', app.General.Colors(3,:));   % MaxHold
                plot(app.axes2, app.x, zeros(1, numel(app.line_ClearWrite.YData), 'single'), 'Tag', 'meanOCC', 'Color', app.General.Colors(2,:));   % Média
            
            else
                if isa(occObj(end), 'images.roi.Line')
                    app.line_OCC.Position(:,2)    = [app.Playback_occValue.Value; app.Playback_occValue.Value];
                    app.line_OCCLabel.Position(2) = app.Playback_occValue.Value;

                else
                    occInfo = struct('Offset',   app.Playback_occOffset.Value,                              ...
                                     'Fcn',      app.Playback_occFcn.Value,                                 ...
                                     'Samples', [app.Playback_tSamples.Value, app.Playback_uSamples.Value], ...
                                     'Factor',   app.Playback_occConfLevel.Value);

                    app.occTHR                    = Misc_occThreshold('Piso de ruído (Offset)', app.specData(ind1), occInfo);                    
                    app.line_OCC.YData            = app.occTHR;
                    app.line_OCCLabel.Position(2) = double(app.occTHR(end));
                end
                
                set(app.axes2.Children, 'YData', zeros(1, numel(app.line_ClearWrite.YData), 'single'));
            end
            
        end    
                
        
        function Plot_StartUp2_WaterFall(app)

            ind1 = app.traceInfo.SelectedNode;
            
            if isempty(findobj('Tag', 'wfSurface'))
                if strcmp(app.play_Waterfall_Decimation.Value, 'auto')
                    auxSize = app.specData(ind1).MetaData.DataPoints .* app.specData(ind1).Samples;
                    if auxSize > 1474560; Decimate = ceil(auxSize/1474560);
                    else;                 Decimate = 1;
                    end
                else
                    Decimate = str2double(app.play_Waterfall_Decimation.Value);
                end

                while true
                    t = app.specData(ind1).Data{1}(1:Decimate:end);
                    if numel(t) > 1; break
                    else;            Decimate = round(Decimate/2);
                    end
                end

                if ~strcmp(app.play_Waterfall_Decimation.Value, 'auto')
                    app.play_Waterfall_Decimation.Value = num2str(Decimate);
                end

                if t(1) == t(end)
                    t(end) = t(1)+seconds(1);
                end
                app.RestoreView{3} = [t(1), t(end)];
                
                [X, Y] = meshgrid(app.x, t);

                app.axes3.CLimMode = 'auto';
                mesh(app.axes3, X, Y, app.specData(ind1).Data{2}(:,1:Decimate:end)', 'MeshStyle', app.play_Waterfall_Interpolation.Value, 'SelectionHighlight', 'off', 'Tag', 'wfSurface')
                view(app.axes3, 0, 90);
                
                if strcmp(app.play_Waterfall_Timestamp.Value, 'on')
                    app.line_wfTime = line(app.axes3, [app.x(1), app.x(end)], [app.specData(ind1).Data{1}(app.timeIndex), app.specData(ind1).Data{1}(app.timeIndex)], [app.axes1.YLim(2) app.axes1.YLim(2)], ...
                                                      'Color', 'red', 'LineWidth', 1, 'PickableParts', 'none', 'Tag', 'wfTimeStamp');
                end
                
                tTickLabel    = linspace(t(1), t(end), 3);
                [~, tIndex]   = min(abs(app.specData(ind1).Data{1} - tTickLabel(2)));
                tTickLabel(2) =  app.specData(ind1).Data{1}(tIndex);

                set(app.axes3, 'YLim' , [t(1), t(end)], 'YTick', tTickLabel, 'YTickLabel', [1, round(tIndex), app.specData(ind1).Samples])

                hText = findobj('Tag', 'DecimateLabel');
                if isempty(hText)
                    text(app.axes3, 1.01, .98, 0, sprintf('FD: %.0f', Decimate), 'Units', 'normalized', 'FontName', 'Helvetica', 'FontSize', 7, 'Tag', 'DecimateLabel')
                else
                    hText.String = sprintf('FD: %.0f', Decimate);
                end

                % Colors limits
                app.axes3.CLim(2)  = round(app.axes3.CLim(2));
                app.axes3.CLim(1)  = round(app.axes3.CLim(2) - diff(app.axes3.CLim)/2);

                app.RestoreView{4} = app.axes3.CLim;

                app.play_Waterfall_cLim1.Value = app.axes3.CLim(1);
                app.play_Waterfall_cLim2.Value = app.axes3.CLim(2);

                Li = app.axes3.CLim(1);
                Ls = app.axes3.CLim(2);
            end
            
        end


        function Plot_StartUp3(app)
            
            ind1 = app.traceInfo.SelectedNode;

            % axes1
            for ii = 1:numel(app.axes1.Children)
                if strcmpi(app.axes1.Children(ii).Type, 'line')
                    if ~strcmp(app.axes1.Children(ii).Tag, 'mkrLine')
                        Misc_DataTipSettings(app.axes1.Children(ii), app.specData(ind1).MetaData.metaString{1})
                    end
                end
            end
            
            % axes2
            for ii = 1:numel(app.axes2.Children)
                if strcmpi(app.axes2.Children(ii).Type, 'line')
                    Misc_DataTipSettings(app.axes2.Children(ii), '%%')
                end
            end
            
            % axes3
            for ii = 1:numel(app.axes3.Children)
                if strcmpi(app.axes3.Children(ii).Type, 'surface')
                    Misc_DataTipSettings(app.axes3.Children(ii), app.specData(ind1).MetaData.metaString{1})
                end
            end
            
        end
        
        
        function traceInfoCallback1_SelectedNode_Mode(app)
            
            app.traceInfo.SelectedNode = app.playReport_left_Tree.SelectedNodes.NodeData;
            
            % median and mean
            if app.play_Average.Value
                app.traceInfo.Mode(2) = 3;
            else
                app.traceInfo.Mode(2) = 0;
            end
            
            % MinHold
            if app.play_MinHold.Value
                app.traceInfo.Mode(1) = 1;
            else
                app.traceInfo.Mode(1) = 0;
            end

            % MaxHold
            if app.play_MaxHold.Value
                app.traceInfo.Mode(3) = 4;
            else
                app.traceInfo.Mode(3) = 0;
            end
            
        end
        
        
        function traceInfoCallback2_plotLayout(app)
            
            if app.play_Occupancy.Value && app.play_Waterfall.Value
                app.traceInfo.plotLayout = 3;
            elseif app.play_Waterfall.Value
                app.traceInfo.plotLayout = 2;
            elseif app.play_Occupancy.Value
                app.traceInfo.plotLayout = 1;
            else
                app.traceInfo.plotLayout = 0;
            end
            
        end

        
        function occLineROI(app, src, event)
            
            switch(event.EventName)
                case{'MovingROI'}
                    disableDefaultInteractivity(app.axes1)
                    
                    app.Playback_occValue.Value = event.CurrentPosition(1,2);
                    app.line_OCCLabel.Position(2)      = event.CurrentPosition(1,2);
                    
                case{'ROIMoved'}
                    enableDefaultInteractivity(app.axes1)
                    
                    set(app.axes2.Children, 'YData', zeros(1, numel(app.line_ClearWrite.YData), 'single'));
            end
            
        end
        
        
        function mkrLineROI(app, src, event)

            ind1 = app.traceInfo.SelectedNode;

            switch(event.EventName)
                case{'MovingROI'}
                    disableDefaultInteractivity(app.axes1)

                    FreqCenter = app.mkr_ROI.Position(1) + app.mkr_ROI.Position(3)/2;
                    if (FreqCenter*1e+6 < app.specData(ind1).MetaData.FreqStart) || ...
                       (FreqCenter*1e+6 > app.specData(ind1).MetaData.FreqStop)
                    
                       return
                    end

                    app.play_FindPeaks_PeakCF.Value = round(FreqCenter, 3);
                    app.play_FindPeaks_PeakBW.Value = round(app.mkr_ROI.Position(3) * 1000, 3);

                    ind2 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;
                    app.line_ClearWrite.MarkerIndices(ind2) = round((app.play_FindPeaks_PeakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    
                    % Se tiver apenas um marcador, então a string fica como
                    % cell. Caso tenha mais de um marcador, então a string
                    % fica como char.
                    markerTag = findobj('Type', 'Text', 'String', sprintf('  %d', ind2));
                    if isempty(markerTag)
                        markerTag = findobj('Type', 'Text', 'String', {sprintf('  %d', ind2)});
                    end
                    markerTag.Position(1:2) = [app.play_FindPeaks_PeakCF.Value, app.line_ClearWrite.YData(app.line_ClearWrite.MarkerIndices(ind2))];
                    
                    set(app.play_FindPeaks_Tree.Children(ind2), 'Text', sprintf("%d: %.3f MHz ⌂ %.3f kHz", ind2, app.play_FindPeaks_PeakCF.Value, app.play_FindPeaks_PeakBW.Value), ...
                                                                'NodeData', ind2)
                    
                case{'ROIMoved'}
                    enableDefaultInteractivity(app.axes1)
                    
                    ind2 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;
                    
                    newIndex = round((app.play_FindPeaks_PeakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    
                    app.specData(ind1).Emissions(ind2,[1:3, 5]) = {newIndex, app.play_FindPeaks_PeakCF.Value, app.play_FindPeaks_PeakBW.Value, 'Detecção manual'};
                    app.specData(ind1).Emissions = sortrows(app.specData(ind1).Emissions, 'Index');
                    
                    selectedEmission = find(app.specData(ind1).Emissions.Index == newIndex, 1);
                    Plot_StartUp2_Spectrum(app, 'PeakValueChanged', ind1, selectedEmission)
            end
            
        end


        %-----------------------------------------------------------------%
        % Modo "RELATÓRIO"
        %-----------------------------------------------------------------%
        function Report_Layout(app)

            idx = [];
            if ~isempty(app.Report_Tree.SelectedNodes)
                idx = app.Report_Tree.SelectedNodes.NodeData;
            end

            if numel(idx) == 1
                app.Report_addButton.Enable    = 0;
                set(app.ContextMenu_Report.Children, 'Visible', 1)

                % OCC objects
                app.Report_occMethod.Value     = app.specData(idx).reportOCC.Method;
                app.Report_occParameters.Value = app.specData(idx).reportOCC.Parameters;

                if app.Report_occMethod.Value ~= "Linear fixo (COLETA)"; app.Report_occButton.Enable = 1;
                else;                                                    app.Report_occButton.Enable = 0;
                end                

                % Detection objects
                Limits = [app.specData(idx).MetaData.FreqStart, app.specData(idx).MetaData.FreqStop] / 1e+6;
                Step   = 1;
                if diff(Limits) < 1; Step = 0.001;
                end

                set(app.Report_BandGrid.Children, 'Enable', 1)
                set(app.Report_BandLim1, 'Enable', 1, 'Limits', Limits, 'Step', Step, 'Value', app.specData(idx).reportDetection.Band(1), 'FontColor', [0,0,0])
                set(app.Report_BandLim2, 'Enable', 1, 'Limits', Limits, 'Step', Step, 'Value', app.specData(idx).reportDetection.Band(2), 'FontColor', [0,0,0])
                set(app.Report_PlaybackOnly, 'Enable', 1, 'Value', app.specData(idx).reportDetection.ManualMode)
                Report_LayoutPlaybackOnly(app)
                
                app.Report_Detection.Value           = app.specData(idx).reportDetection.Algorithm;
                app.Report_DetectionParameters.Value = app.specData(idx).reportDetection.Parameters;

                % Classification objects
                app.Report_Classification.Value           = app.specData(idx).reportClassification.Algorithm;
                app.Report_ClassificationButton.Enable    = 1;
                app.Report_ClassificationParameters.Value = app.specData(idx).reportClassification.Parameters;

            else
                if isempty(app.playReport_left_Tree.SelectedNodes); app.Report_addButton.Enable = 0;
                else;                                        app.Report_addButton.Enable = 1;
                end

                if numel(idx) > 1; set(app.ContextMenu_Report.Children, 'Visible', 1)
                else;              set(app.ContextMenu_Report.Children, 'Visible', 0)
                end

                % OCC objects
                app.Report_occMethod.Value     = '';
                app.Report_occButton.Enable    = 0;
                app.Report_occParameters.Value = '';

                % Detection objects
                Limits = [-1, -0.999];

                set(app.Report_BandGrid.Children, 'Enable', 0)
                set(app.Report_BandLim1, 'Enable', 0, 'Limits', Limits, 'Step', 0.001, 'FontColor', [.94,.94,.94])
                set(app.Report_BandLim2, 'Enable', 0, 'Limits', Limits, 'Step', 0.001, 'FontColor', [.94,.94,.94])
                set(app.Report_PlaybackOnly, 'Enable', 0, 'Value', 0)
                
                app.Report_Detection.Value           = '';
                app.Report_DetectionButton.Enable    = 0;
                app.Report_DetectionParameters.Value = '';

                % Classification objects
                app.Report_Classification.Value           = '';
                app.Report_ClassificationButton.Enable    = 0;
                app.Report_ClassificationParameters.Value = '';
            end

        end


        function Report_LayoutPlaybackOnly(app)

            if app.Report_PlaybackOnly.Value
                set(findall(groot, 'Parent', app.GridLayout16, 'Tag', 'Detection'), 'Enable', 0)
            else
                set(findall(groot, 'Parent', app.GridLayout16, 'Tag', 'Detection'), 'Enable', 1)
            end

        end
        
        
        function Report_InfoCreation(app, filteredRows)
            
            % Criação dos campos "reportDetection" e "reportClassification", caso aplicável.
            if ~isempty(filteredRows)
                for ii = filteredRows
                    app.specData(ii).reportFlag = 1;
                    
                    if isempty(app.specData(ii).reportDetection)
                        % OCC
                        if isempty(app.specData(ii).reportOCC.Index)
                            app.specData(ii).reportOCC.Method     = 'Linear adaptativo';
                            app.specData(ii).reportOCC.Parameters = jsonencode(struct('IntegrationTime', 15, 'Offset', 12, 'Fcn',  'Média', 'Samples', [0, 20]));
                        else
                            app.specData(ii).reportOCC.Method     = 'Linear fixo (COLETA)';
                            app.specData(ii).reportOCC.Parameters = jsonencode(struct('IntegrationTime', round(app.specData(app.specData(ii).reportOCC.Index).MetaData.SampleTime/60), ...
                                                                                      'THR', app.specData(app.specData(ii).reportOCC.Index).MetaData.Threshold));
                        end

                        % Detection
                        global peaksReference

                        idx = Misc_Band(app.specData(ii));
                        if isempty(idx)
                            app.specData(ii).reportDetection = struct('Band', [app.specData(ii).MetaData.FreqStart, app.specData(ii).MetaData.FreqStop] / 1e+6, ...
                                                                      'ManualMode', 0,                                                                          ...
                                                                      'Algorithm', 'appAnálise v. 1.00',                                                        ...
                                                                      'Parameters', jsonencode(struct('Cn_minDist', 25, 'Cn_BW', 10,                            ...
                                                                                                      'C1_Fcn', 'Média', 'C1_Proeminence', 10,                  ...
                                                                                                      'C2_Proeminence', 30, 'C2_meanOCC', 1, 'C2_maxOCC', 10)));
                        else
                            if peaksReference(idx).Band(1) < app.specData(ii).MetaData.FreqStart
                                FreqStart = app.specData(ii).MetaData.FreqStart;
                            else
                                FreqStart = peaksReference(idx).Band(1);
                            end

                            if peaksReference(idx).Band(2) > app.specData(ii).MetaData.FreqStop
                                FreqStop = app.specData(ii).MetaData.FreqStop;
                            else
                                FreqStop = peaksReference(idx).Band(2);
                            end

                            app.specData(ii).reportDetection = struct('Band', [FreqStart, FreqStop] / 1e+6, ...
                                                                      'ManualMode', 0,                      ...
                                                                      'Algorithm', 'appAnálise v. 1.00',    ...
                                                                      'Parameters', jsonencode(struct('Cn_minDist', peaksReference(idx).Settings(1)/1e+3, 'Cn_BW', peaksReference(idx).Settings(2)/1e+3, ...
                                                                                                      'C1_Fcn', 'Média', 'C1_Proeminence', 10,                                                           ...
                                                                                                      'C2_Proeminence', 30, 'C2_meanOCC', peaksReference(idx).Settings(3), 'C2_maxOCC', peaksReference(idx).Settings(4))));
                        end

                        % Classification
                        app.specData(ii).reportClassification = struct('Algorithm', 'appAnálise v. 1.20',                    ...
                                                                       'Parameters', jsonencode(struct('Contour', 30,        ...
                                                                                                       'ClassMultiplier', 2, ...
                                                                                                       'bwFactors', [100, 300])));
                    end
                end
            end
            Report_TreeBuilding(app)
            
        end


        function Report_ProjectIcon(app)

            NN = numel(app.projData.image) + numel(app.projData.table);

            switch NN
                case 0;    app.Report_NewProject_2.Icon = 'LT_attach0.png';
                case 1;    app.Report_NewProject_2.Icon = 'LT_attach1.png';
                case 2;    app.Report_NewProject_2.Icon = 'LT_attach2.png';
                otherwise; app.Report_NewProject_2.Icon = 'LT_attachN.png';
            end

        end
        
        
        function Report_TreeBuilding(app)
            
            if ~isempty(app.Report_Tree.Children)
                delete(app.Report_Tree.Children);
            end
            
            FilteredRows = find([app.specData.reportFlag]);
            if isempty(FilteredRows)
                app.report_tool_Button3.Enable    = 0;
                app.report_tool_Button2.Enable = 0;

                Report_TreeBuilding_Aux(app, 'Off')
                
            else
                app.report_tool_Button3.Enable    = 1;
                app.report_tool_Button2.Enable = 1;

                Report_TreeBuilding_Aux(app, 'On')
                
                for ii = 1:numel(FilteredRows)
                    allNodes{ii,1} = app.specData(FilteredRows(ii)).Node;
                end
                [uniqueNodes, ~, ic] = unique(allNodes);
                
                for ii = 1:numel(uniqueNodes)
                    idx1 = find(ic == ii)';

                    imageIcon = Fcn_TreeIcon(app, uniqueNodes{ii});
                    Category  = uitreenode(app.Report_Tree, 'Text', uniqueNodes{ii},        ...
                                                            'NodeData', FilteredRows(idx1), ...
                                                            'Icon', imageIcon,              ...
                                                            'ContextMenu', app.ContextMenu_Report);
                    
                    for jj = idx1
                        idx2 = FilteredRows(jj);

                        Node = uitreenode(Category, 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz',             ...
                                                                    app.specData(idx2).ThreadID,                ...
                                                                    1e-6*app.specData(idx2).MetaData.FreqStart, ...
                                                                    1e-6*app.specData(idx2).MetaData.FreqStop), ...
                                                    'NodeData', idx2, 'ContextMenu', app.ContextMenu_Report);

                        if ~isempty(app.specData(idx2).reportAttachments.image) && ~isempty(app.specData(idx2).reportAttachments.table.Source)
                            Node.Icon = 'LT_attach2.png';
                        elseif ~isempty(app.specData(idx2).reportAttachments.image) || ~isempty(app.specData(idx2).reportAttachments.table.Source)
                            Node.Icon = 'LT_attach1.png';
                        end
                    end
                end
                Fcn_expandTree(app, 'Report')
            end
            
        end
        
        
        function Report_TreeBuilding_Aux(app, Flag)
            
            switch Flag
                case 'Off'
                    app.Report_DatePicker1.Value = NaT;
                    app.Report_DatePicker2.Value = NaT;
                    
                    app.Report_Spinner1.Value = 0;
                    app.Report_Spinner2.Value = 0;
                    app.Report_Spinner3.Value = 23;
                    app.Report_Spinner4.Value = 59;
                    
                case 'On'
                    FilteredRows = find([app.specData.reportFlag]);
                    
                    StartDate = app.specData(FilteredRows(1)).Data{1}(1);
                    StopDate  = app.specData(FilteredRows(1)).Data{1}(end);
                    for ii = 1:numel(FilteredRows)
                        StartDate = min(StartDate, app.specData(FilteredRows(ii)).Data{1}(1));
                        StopDate  = max(StopDate,  app.specData(FilteredRows(ii)).Data{1}(end));
                    end
                    
                    app.Report_DatePicker1.Limits = [StartDate, StopDate];
                    app.Report_DatePicker1.Value  = app.Report_DatePicker1.Limits(1);
                    
                    app.Report_DatePicker2.Limits = app.Report_DatePicker1.Limits;
                    app.Report_DatePicker2.Value  = app.Report_DatePicker2.Limits(2);
            end
            
        end
        
        
        function ReportGenerator_PeaksUpdate(app, CheckedRows, Peaks)
            
            if isequal(CheckedRows, find([app.specData.reportFlag]))
                if ~isempty(Peaks); app.peaksTable      = Peaks;
                else;               app.peaksTable(:,:) = [];
                end
                
            else
                for ii = CheckedRows
                    Tag = sprintf('%s\nThreadID %d: %.3f - %.3f MHz', app.specData(ii).Node,                       ...
                                                                      app.specData(ii).ThreadID,                   ...
                                                                      app.specData(ii).MetaData.FreqStart ./ 1e+6, ...
                                                                      app.specData(ii).MetaData.FreqStop  ./ 1e+6);
                    if ~isempty(Peaks)
                        newTags = unique(Peaks.Tag);

                        ind = find(strcmp(newTags, Tag));
                        if ~isempty(ind)
                            oldInd = find(strcmp(app.peaksTable.Tag, newTags(ind)));
                            newInd = find(strcmp(Peaks.Tag,          newTags(ind)));
                            
                            app.peaksTable(oldInd, :)                  = [];
                            app.peaksTable(end+1:end+numel(newInd), :) = Peaks(newInd,:);
                            
                            app.peaksTable = sortrows(app.peaksTable, 'Tag');
                        else
                            oldInd = find(strcmp(app.peaksTable.Tag, Tag));
                            app.peaksTable(oldInd, :) = [];
                        end
                    else
                        oldInd = find(strcmp(app.peaksTable.Tag, Tag));
                        app.peaksTable(oldInd, :) = [];
                    end
                end
            end
            
        end


        function ReportGenerator_Main(app, Mode)
      
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html'); 
            d.Message = '<font style="font-size:12;">Em andamento a análise dos fluxos de dados selecionados, o que inclui diversas manipulações, como, por exemplo, a busca de emissões, a comparação com a base de dados da Agência e a geração de plots.</font>';
                
            try
                if isempty(app.General.ver.fiscaliza)
                    app.General.ver = startup_Versions("Full", app.RootFolder);
                end

                reportInfo = ReportGenerator_Aux1(app, Mode);
            
                % FLUXOS DE DADOS A PROCESSAR
                if Mode == "Report"
                    ExternalFiles_Flag = extractBefore(reportInfo.Model.Template, '"Origin": "External"');
                    if ~isempty(ExternalFiles_Flag)
                        msg = '<font style="font-size:12;">Confirma que foram relacionados os arquivos externos ao appAnálise estabelecidos no modelo?</font>';
                        selection = uiconfirm(app.UIFigure, msg, 'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                        
                        if selection == "Cancelar"
                            return
                        end
                    end

                    CheckedRows = find([app.specData.reportFlag]);

                else
                    CheckedRows = [];
                    for mm = 1:numel(app.Report_Tree.CheckedNodes)
                        if isnumeric(app.Report_Tree.CheckedNodes(mm).NodeData)
                            CheckedRows = [CheckedRows, app.Report_Tree.CheckedNodes(mm).NodeData];
                        end
                    end
                    CheckedRows = unique(CheckedRows);                    
                end
                
                idx = Misc_FindOCCData(app.specData(CheckedRows));
                ProjectRows = sort([CheckedRows, idx]);
                
                % OCC Map (Step 1 of 2)
                OCCLength = numel(idx);
                if OCCLength
                    OCCMap = zeros(OCCLength, 2);
                    
                    jj = 0;
                    for ii = 1:numel(ProjectRows)
                        if ~isempty(app.specData(ProjectRows(ii)).reportOCC.Index)
                            jj=jj+1;
                            OCCMap(jj,:) = [ProjectRows(ii), app.specData(ProjectRows(ii)).reportOCC.Index];
                            app.specData(ProjectRows(ii)).reportOCC.Index = find(ProjectRows == OCCMap(jj,2));
                        end
                    end
                end
                
                if Mode == "Report"
                    [htmlReport, Peaks] = ReportGenerator(app.specData(ProjectRows), reportInfo, app.exceptionList);
                    ReportGenerator_PeaksUpdate(app, CheckedRows, Peaks)

                    if app.Report_Version.Value == "Definitiva"
                        filename = fullfile(app.menu_userPath.Value, sprintf('Report_%s_%.0f.html', datestr(datetime('now'), 'yyyy.mm.dd_THH.MM.SS'), app.Report_Issue.Value));
                        fileID   = fopen(filename, 'w', 'native', 'ISO-8859-1');

                    else
                        filename = fullfile(app.menu_userPath.Value, sprintf('~Report_%s_%.0f.html', datestr(datetime('now'), 'yyyy.mm.dd_THH.MM.SS'), app.Report_Issue.Value));
                        fileID   = fopen(filename, 'w');
                    end
                    
                    fprintf(fileID, '%s', htmlReport);
                    fclose(fileID);

                    if app.Report_Version.Value == "Definitiva"
                        app.General.Report = filename;
                        
                        [ReportProject, tableStr] = ReportGenerator_Aux2(app, ProjectRows, reportInfo);
                        save(replace(filename, '.html', '.mat'), 'ReportProject', '-mat', '-v7.3')
                        writematrix(tableStr, replace(filename, '.html', '.json'), "FileType", "text", "QuoteStrings", "none")
                    end
                    web(filename, '-new')

                else
                    Peaks = PreviewGenerator(app.specData(ProjectRows), reportInfo);
                    ReportGenerator_PeaksUpdate(app, CheckedRows, Peaks)
                end
    
                % OCC Map (Step 2 of 2)
                if OCCLength
                    for jj = 1:height(OCCMap)
                        app.specData(OCCMap(jj,1)).reportOCC.Index = OCCMap(jj,2);
                    end
                end
                
            catch ME
                fprintf('%s\n', jsonencode(ME))
                MessageBox(app, 'error', getReport(ME))
            end
            delete(d)

        end
        
        
        function reportInfo = ReportGenerator_Aux1(app, Mode)
            
            Date1 = app.Report_DatePicker1.Value + hours(app.Report_Spinner1.Value) + minutes(app.Report_Spinner2.Value);
            Date2 = app.Report_DatePicker2.Value + hours(app.Report_Spinner3.Value) + minutes(app.Report_Spinner4.Value);
            
            if Date1 > Date2
                error('Os valores inseridos para aplicação do filtro de TimeStamp devem ser ajustados!')
            end
            
            idx = find(strcmp(app.Report_Type.Items, app.Report_Type.Value), 1);
            
            reportInfo = struct('appVersion', app.General.ver,                                ...
                                'Issue',      app.Report_Issue.Value,                         ...
                                'General',    struct('Mode',       Mode,                      ...
                                                     'Version',    app.Report_Version.Value,  ...
                                                     'Image',      app.General.Image,         ...
                                                     'RootFolder', app.RootFolder,            ...
                                                     'UserPath',   app.menu_userPath.Value),  ...
                                'Model',      struct('Name',       app.Report_Type.Value,     ...
                                                     'Type',       app.General.Models(idx,:), ...
                                                     'Template',   fileread(fullfile(app.RootFolder, 'Template', app.General.Models.Template{idx}))), ...
                                'Attachments', app.projData,  ...
                                'TimeStamp',  [Date1, Date2], ...
                                'Filename',   '');
            
        end
        
        
        function [ReportProject, tableStr] = ReportGenerator_Aux2(app, ProjectRows, reportInfo)

            % Variável que possibilitará o preenchimento dos campos
            % estruturados na inspeção (no Fiscaliza).
            ReportProject = struct('Issue',           app.Report_Issue.Value,       ...
                                   'Latitude',        [], 'Longitude',          [], ...
                                   'City',            [], 'Bands',              [], ...
                                   'F0',              [], 'F1',                 [], ...
                                   'emissionsValue1',  0, 'emissionsValue2',     0, 'emissionsValue3',     0, ...
                                   'Services',        [], 'tableJournal',       []);

            % Preenchimento dos dados...            
            idx = find(strcmp(app.General.Models.Name, app.Report_Type.Value), 1);
            ReportProject.Services = app.General.Models.RelatedServices{idx};
            
            % Definir FreqStart, FreqStop, Latitude, Longitude e City que
            % irão compor inspeção no Fiscaliza.
            ReportProject.Bands = {};
            for ii = 1:numel(ProjectRows)
                if ismember(app.specData(ii).MetaData.DataType, class.Constants.specDataTypes)
                    ReportProject.Bands(end+1) = {sprintf('%.3f - %.3f MHz', app.specData(ii).MetaData.FreqStart ./ 1e+6, app.specData(ii).MetaData.FreqStop ./ 1e+6)};
                    
                    if isempty(ReportProject.Latitude)
                        ReportProject.Latitude  = app.specData(ProjectRows(ii)).gps.Latitude;
                        ReportProject.Longitude = app.specData(ProjectRows(ii)).gps.Longitude;
                        ReportProject.City      = {app.specData(ProjectRows(ii)).gps.Location};
                        
                        ReportProject.F0 = app.specData(ProjectRows(ii)).MetaData.FreqStart;
                        ReportProject.F1 = app.specData(ProjectRows(ii)).MetaData.FreqStop;
                    
                    else
                        if ~ismember(app.specData(ProjectRows(ii)).gps.Location, ReportProject.City)
                            ReportProject.City{end+1,1} = app.specData(ProjectRows(ii)).gps.Location;
                        end                        
                        
                        ReportProject.F0 = min(ReportProject.F0, app.specData(ProjectRows(ii)).MetaData.FreqStart);
                        ReportProject.F1 = max(ReportProject.F1, app.specData(ProjectRows(ii)).MetaData.FreqStop);
                    end
                end
            end
            
            % Juntar numa mesma variável a informação gerada pelo algoritmo
            % embarcado no appAnálise (app.peaksTable) com a informação
            % gerada pelo fiscal (app.exceptionList).
            [infoTable, countTable] = ReportGenerator_Table_Summary(app.peaksTable, app.exceptionList);

            ReportProject.emissionsValue1 = sum(infoTable{:,2:4}, 'all');                               % Qtd. emissões
            ReportProject.emissionsValue2 = sum(infoTable{:,2});                                        % Qtd. emissões licenciadas
            ReportProject.emissionsValue3 = sum(infoTable{:,5:8}, 'all');                               % Qtd. emissões identificadas

            ReportProject.tableJournal = infoTable(:,cell2mat(reportInfo.Model.Type.JournalTable));

            % Arquivo JSON
            tableStr = ReportGenerator_Aux3(app, ProjectRows, countTable);

        end
        
        
        function tableStr = ReportGenerator_Aux3(app, ProjectRows, countTable)
            
            % Tabelas que irão compor o JSON que será carregado como anexo
            % à inspeção (no Fiscaliza).
            TaskTable   = table('Size', [0, 12],                                                                                                                 ...
                                'VariableTypes', {'uint16', 'cell', 'single', 'single', 'double', 'double', 'cell', 'cell', 'uint32', 'double', 'cell', 'cell'}, ...
                                'VariableNames', {'PK1', 'Node', 'Latitude', 'Longitude', 'FreqStart', 'FreqStop', 'BeginTime', 'EndTime', 'Samples', 'timeOCC', 'Description', 'RelatedFiles'});

            occMethodTable      = table('Size', [0, 2], 'VariableTypes', {'uint16', 'cell'}, 'VariableNames', {'PK2', 'occMethod'});
            DetectionTable      = table('Size', [0, 2], 'VariableTypes', {'uint16', 'cell'}, 'VariableNames', {'PK3', 'Detection'});
            ClassificationTable = table('Size', [0, 2], 'VariableTypes', {'uint16', 'cell'}, 'VariableNames', {'PK4', 'Classification'});
            
            PeakTable = countTable(:,5:21);
            PeakTable.Frequency = round(PeakTable.Frequency, 3);
            PeakTable.BW        = round(1000 * PeakTable.BW, 3);

            occMethod = unique(countTable.occMethod);
            occMethodTable(1:numel(occMethod),:) = [num2cell((1:numel(occMethod))'), occMethod];
            
            Detection = unique(countTable.Detection);
            DetectionTable(1:numel(Detection),:) = [num2cell((1:numel(Detection))'), Detection];
            
            Classification = unique(countTable.Classification);
            ClassificationTable(1:numel(Classification),:) = [num2cell((1:numel(Classification))'), Classification];

            jj = 0;
            for ii = ProjectRows
                if ismember(app.specData(ii).MetaData.DataType, class.Constants.specDataTypes)
                    jj = jj+1;

                    occInfo = jsondecode(app.specData(ii).reportOCC.Parameters);
                    
                    TaskTable(end+1,:) = {jj,                                  ...
                                          app.specData(ii).Node,               ...
                                          app.specData(ii).gps.Latitude,       ...
                                          app.specData(ii).gps.Longitude,      ...
                                          app.specData(ii).MetaData.FreqStart ./ 1e+6, ...
                                          app.specData(ii).MetaData.FreqStop  ./ 1e+6, ...
                                          datestr(app.specData(ii).Data{1}(1),   'dd/mm/yyyy HH:MM:SS'), ...
                                          datestr(app.specData(ii).Data{1}(end), 'dd/mm/yyyy HH:MM:SS'), ...
                                          app.specData(ii).Samples,             ...
                                          occInfo.IntegrationTime,              ...
                                          app.specData(ii).Description,         ...
                                          strjoin(app.specData(ii).RelatedFiles.Name, ', ')};
                    
                    Tag = sprintf('%s\nThreadID %d: %.3f - %.3f MHz', app.specData(ii).Node,     ...
                                                                      app.specData(ii).ThreadID, ...
                                                                      app.specData(ii).MetaData.FreqStart ./ 1e+6, ...
                                                                      app.specData(ii).MetaData.FreqStop  ./ 1e+6);
                    
                    idx1 = find(strcmp(app.peaksTable.Tag, Tag));
                    if ~isempty(idx1)
                        PeakTable.FK1(idx1) = uint16(jj);
                    end
                end
            end

            % occMethod, detection and classification tables
            for ii = 1:numel(occMethod)
                idx_OCC = find(strcmp(app.peaksTable.occMethod, occMethod(ii)));

                if ~isempty(idx_OCC); PeakTable.FK2(idx_OCC) = uint16(ii);
                end                
            end

            for ii = 1:numel(Detection)
                idx_Det = find(strcmp(app.peaksTable.Detection, Detection(ii)));

                if ~isempty(idx_Det); PeakTable.FK3(idx_Det) = uint16(ii);
                end
            end

            for ii = 1:numel(Classification)
                idx_Cla = find(strcmp(app.peaksTable.Classification, Classification(ii)));

                if ~isempty(idx_Cla); PeakTable.FK4(idx_Cla) = uint16(ii);
                end
            end

            if ~isempty(PeakTable)
                PeakTable = movevars(PeakTable, {'FK1', 'FK2', 'FK3', 'FK4'}, 'Before', 1);
                tableStr  = jsonencode(struct('ReferenceData1', TaskTable, 'ReferenceData2', occMethodTable, 'ReferenceData3', DetectionTable, 'ReferenceData4', ClassificationTable, 'MeasurementData', PeakTable(:,1:end-1)), 'PrettyPrint', true);
            else
                tableStr  = jsonencode(struct('ReferenceData1', TaskTable), 'PrettyPrint', true);
            end
            
        end    
        

        %-----------------------------------------------------------------%
        % Modo "EDITAR FLUXO(S) DE DADOS"
        %-----------------------------------------------------------------%
        function Info_Layout(app)
            idx = [];
            if ~isempty(app.playReport_left_Tree.SelectedNodes)
                idx = unique([app.playReport_left_Tree.SelectedNodes.NodeData]);
            end

            flag = 'off';
            switch app.Info_EditionType.Value
                case 'MESCLAR'
                    if numel(idx) > 1
                        flag = 'on';
                    end

                case 'EXCLUIR'
                    if numel(idx) >= 1
                        flag = 'on';
                    end

                otherwise
                    if (numel(idx) == 1) & ismember(app.specData(idx).MetaData.DataType, class.Constants.specDataTypes)
                        flag = 'on';
                    end
            end
        
            switch flag
                case 'on'                    
                    set(app.Info_Toolbar.Children, 'Enable', 1)
                    
                    switch app.Info_EditionType.Value
                        case 'LOCAL DA MONITORAÇÃO';                  Info_Layout_GPS(app)
                        case 'FLUXO DE OCUPAÇÃO';                     Info_Layout_OCC(app)
                        case 'UNIDADE DE MEDIDA (dBm/dBµV → dBµV/m)'; Info_Layout_Unit(app)
                    end                    
                
                case 'off'
                    % LOCATION GRID
                    set(app.Info_GPS_Mode,      'Enable',     0, 'Value', 'Automático')
                    set(app.Info_GPS_Latitude,  'Enable',     0, 'Value', -1)
                    set(app.Info_GPS_Longitude, 'Enable',     0, 'Value', -1)
                    set(app.Info_GPS_City,      'Enable',     0, 'Value', '')
                    set(app.Info_GPS_API,       'Enable',     0)
                    set(app.Info_GPS_CheckBox2, 'Enable',     0, 'Value',  0)
                    set(app.Info_GPS_Table,     'Enable', 'off', 'Data',  [])

                    % OCC & UNIT CONVERSION GRIDS
                    set(app.Info_OCC_Type,     'Enable', 0, 'Items', {})                    
                    set(app.Info_Unit_Value,                'Value', '')
                    set(app.Info_Unit_Antenna, 'Enable', 0, 'Value', '')

                    set(app.Info_Toolbar.Children, 'Enable', 0)
            end     
        end


        function Info_Layout_GPS(app)
            idx = app.playReport_left_Tree.SelectedNodes.NodeData;

            if app.specData(idx).gps.Status == -1; set(app.Info_GPS_Mode, 'Enable', 1, 'Value', 'Manual')
            else;                                  set(app.Info_GPS_Mode, 'Enable', 1, 'Value', 'Automático')
            end
            Info_GPS_ModeValueChanged(app)
            
            set(app.Info_GPS_Latitude,  'Enable', 1, 'Value', app.specData(idx).gps.Latitude)
            set(app.Info_GPS_Longitude, 'Enable', 1, 'Value', app.specData(idx).gps.Longitude)
            set(app.Info_GPS_City,      'Enable', 1, 'Value', app.specData(idx).gps.Location)
            set(app.Info_GPS_API,       'Enable', 1)
            
            gpsTable = table('Size', [height(app.specData(idx).RelatedFiles), 6],                              ...
                             'VariableTypes', {'double', 'cell', 'double', 'cell',     'cell',      'double'}, ...
                             'VariableNames', {'ID',     'File', 'Status', 'Latitude', 'Longitude', 'Count'});                    
            
            for ii = 1:height(gpsTable)
                gpsTable(ii,:) = {ii,                                                          ...
                                  app.specData(idx).RelatedFiles.Name{ii},                     ...
                                  app.specData(idx).RelatedGPS{ii}.Status,                     ...
                                  sprintf('%.6f', app.specData(idx).RelatedGPS{ii}.Latitude),  ...
                                  sprintf('%.6f', app.specData(idx).RelatedGPS{ii}.Longitude), ...
                                  app.specData(idx).RelatedGPS{ii}.Count};
            end
            app.Info_GPS_Table.Data = gpsTable;
        end


        function Info_Layout_OCC(app)
            idx = app.playReport_left_Tree.SelectedNodes.NodeData;

            set(app.Info_OCC_Type, 'Enable', 1, 'Items', {'Ocupação a ser aferida'})
    
            if ~isempty(app.specData(idx).reportOCC.Related)
                for ii = 1:numel(app.specData(idx).reportOCC.Related)
                    app.Info_OCC_Type.Items{ii+1} = sprintf('Threshold: %d %s (ThreadID %d)',                                         ...
                                                       app.specData(app.specData(idx).reportOCC.Related(ii)).MetaData.Threshold, ...
                                                       app.specData(idx).MetaData.metaString{1},                                 ...
                                                       app.specData(app.specData(idx).reportOCC.Related(ii)).ThreadID);
                end
                
                if isempty(app.specData(idx).reportOCC.Index)
                    set(app.Info_OCC_Type, 'Value', app.Info_OCC_Type.Items{1})
                else
                    idx2 = find(app.specData(idx).reportOCC.Related == app.specData(idx).reportOCC.Index, 1);
                    set(app.Info_OCC_Type, 'Value', app.Info_OCC_Type.Items{idx2+1})
                end
            end
        end


        function Info_Layout_Unit(app)
            idx = app.playReport_left_Tree.SelectedNodes.NodeData;

            app.Info_Unit_Value.Value    = app.specData(idx).MetaData.metaString{1};
            app.Info_Unit_Antenna.Value = '';
            
            if ismember(app.Info_Unit_Value.Value, {'dBm', 'dBµV'})    
                app.Info_Unit_Antenna.Enable = 1;
            else
                app.Info_Unit_Antenna.Enable = 0;
            end
        end


        function Info_Toolbar_Button2Pushed_GPS(app)
            idx1 = app.playReport_left_Tree.SelectedNodes.NodeData;

            RelatedGPS = app.specData(idx1).RelatedGPS;
            InitialGPS = app.specData(idx1).gps;

            gpsStatus =  0;
            gpsLat    = -1;
            gpsLon    = -1;
            gpsLoc    = '';

            switch app.Info_GPS_Mode.Value
                case 'Automático'
                    % RELATEDGPS FIELD UPDATE
                    rawRelatedGPS = [];
                    for ii = 1:numel(app.specData(idx1).RelatedGPS)
                        rawRelatedGPS = [rawRelatedGPS; app.specData(idx1).RelatedGPS{ii}.Status];
                    end

                    idx2 = find(rawRelatedGPS ~= app.Info_GPS_Table.Data.Status);
                    for jj = idx2'
                        app.specData(idx1).RelatedGPS{jj}.Status = app.Info_GPS_Table.Data.Status(jj);
                    end

                    % OTHERS FIELDS VALUES
                    gpsTable = app.Info_GPS_Table.Data(logical(app.Info_GPS_Table.Data.Status),:);
                    if sum(gpsTable.Count)
                        gpsStatus = max(gpsTable.Status(gpsTable.Status ~= 0));
                        gpsLat    = sum(str2double(gpsTable.Latitude) .*gpsTable.Count) / sum(gpsTable.Count);
                        gpsLon    = sum(str2double(gpsTable.Longitude).*gpsTable.Count) / sum(gpsTable.Count);
                    end                            
                    
                case 'Manual'
                    gpsStatus = -1;
                    gpsLat    = app.Info_GPS_Latitude.Value;
                    gpsLon    = app.Info_GPS_Longitude.Value;
                    gpsLoc    = app.Info_GPS_City.Value;
            end
            
            if ~gpsStatus
                if app.Info_GPS_Mode.Value == "Automático" || (app.Info_GPS_Mode.Value == "Manual" && ~isempty(app.specData(idx1).gps.Location))
                        app.specData(idx1).gps.Status    =  0;
                        app.specData(idx1).gps.Latitude  = -1;
                        app.specData(idx1).gps.Longitude = -1;
                        app.specData(idx1).gps.Location  = '';
                end

                Info_GPS_ReportCheck(app, idx1, RelatedGPS, InitialGPS)

            else
                if isempty(gpsLoc)
                    gpsLoc = geo_FindCity(struct('Latitude', gpsLat, 'Longitude', gpsLon));
                end
                
                app.specData(idx1).gps.Status    = gpsStatus;
                app.specData(idx1).gps.Latitude  = gpsLat;
                app.specData(idx1).gps.Longitude = gpsLon;
                app.specData(idx1).gps.Location  = gpsLoc;
                
                if app.Info_GPS_CheckBox2.Value
                    for ii = 1:numel(app.specData)
                        if ii == idx1; continue
                        end
                        
                        if strcmp(app.specData(idx1).Node, app.specData(ii).Node)
                            app.specData(ii).gps.Status    = gpsStatus;
                            app.specData(ii).gps.Latitude  = gpsLat;
                            app.specData(ii).gps.Longitude = gpsLon;
                            app.specData(ii).gps.Location  = gpsLoc;
                        end
                    end
                end
            end
        end


        function Info_Toolbar_Button2Pushed_OCC(app)
            idx1 = app.playReport_left_Tree.SelectedNodes.NodeData;

            if ~isempty(app.specData(idx1).reportOCC.Related)
                if app.Info_OCC_Type.Value ~= "Ocupação a ser aferida"
                    idx2 = find(strcmp(app.Info_OCC_Type.Items, app.Info_OCC_Type.Value), 1) - 1;
                    app.specData(idx1).reportOCC.Index = app.specData(idx1).reportOCC.Related(idx2);

                    if app.specData(idx1).reportOCC.Method ~= "Linear fixo (COLETA)"
                        app.specData(idx1).reportOCC.Method = 'Linear fixo (COLETA)';
                    end
                    app.specData(idx1).reportOCC.Parameters = jsonencode(struct('IntegrationTime', round(app.specData(app.specData(idx1).reportOCC.Index).MetaData.SampleTime/60), ...
                                                                                'THR', app.specData(app.specData(idx1).reportOCC.Index).MetaData.Threshold));
                
                else
                    app.specData(idx1).reportOCC.Index = [];

                    if app.specData(idx1).reportOCC.Method == "Linear fixo (COLETA)"
                        app.specData(idx1).reportOCC.Method     = 'Linear adaptativo';
                        app.specData(idx1).reportOCC.Parameters = jsonencode(struct('IntegrationTime', 15, ...
                                                                                    'Offset', 12,          ...
                                                                                    'Fcn', 'Média',        ...
                                                                                    'Samples', [0, 20]));
                    end
                end
            end
        end


        function Info_Toolbar_Button2Pushed_Unit(app)
            if ~isempty(app.Info_Unit_Antenna.Value)
                idx1 = app.playReport_left_Tree.SelectedNodes.NodeData;

                kFactor_raw = Misc_kFactorRead(app.RootFolder, app.Info_Unit_Antenna.Value);
                
                if (kFactor_raw.Data(1,1)   <= app.specData(idx1).MetaData.FreqStart / 1e+6) && ...
                   (kFactor_raw.Data(end,1) >= app.specData(idx1).MetaData.FreqStop  / 1e+6)

                    newData = app.specData(idx1);
                    
                    newData.MetaData.LevelUnit     = 3; 
                    newData.MetaData.metaString{1} = 'dBµV/m';
                    newData.MetaData.metaString{5} = sprintf('%s / %s', app.specData(idx1).MetaData.metaString{5}, app.Info_Unit_Antenna.Value);
                    
                    kFactor = interp1(kFactor_raw.Data(:,1), kFactor_raw.Data(:,2),           ...
                                      linspace(app.specData(idx1).MetaData.FreqStart ./ 1e+6, ...
                                               app.specData(idx1).MetaData.FreqStop  ./ 1e+6, ...
                                               app.specData(idx1).MetaData.DataPoints)',      ...
                                      'spline');
                    
                    if strcmp(app.specData(idx1).MetaData.metaString{1}, 'dBm')
                        kFactor = kFactor + 107;
                    end
                    
                    newData.Data{2}   = app.specData(idx1).Data{2}   + kFactor;
                    newData.statsData = app.specData(idx1).statsData + kFactor;

                    app.specData(idx1) = newData;
                    
                else
                    MessageBox(app, 'warning', 'A curva de correção da antena selecionada não engloba a faixa monitorada.')
                end
            end
        end


        function Info_Toolbar_Button2Pushed_Merge(app)
            tempTable = table('Size', [0,6], ...
                              'VariableTypes', {'double', 'double', 'double', 'double', 'double', 'double'}, ...
                              'VariableNames', {'idx', 'DataType', 'FreqStart', 'FreqStop', 'LevelUnit', 'Samples'});

            idx = unique([app.playReport_left_Tree.SelectedNodes.NodeData]);
            for ii = idx
                tempTable(end+1,:) = {ii,                                  ...
                                      app.specData(ii).MetaData.DataType,  ...
                                      app.specData(ii).MetaData.FreqStart, ...
                                      app.specData(ii).MetaData.FreqStop,  ...
                                      app.specData(ii).MetaData.LevelUnit, ...
                                      app.specData(ii).Samples};
            end
            tempTable = sortrows(tempTable, 'FreqStart');

            if ~isequal(tempTable.FreqStart(2:height(tempTable)), tempTable.FreqStop(1:height(tempTable)-1)) | ...
                    (numel(unique(tempTable.LevelUnit)) > 1) | (numel(unique(tempTable.Samples)) > 1) | (numel(unique(tempTable.DataType)) > 1)
                MessageBox(app, 'warning', 'Os fluxos a mesclar não podem ter descontinuidades, devem possuir um mesmo número de amostras e estar relacionados à uma única unidade de medida.')
            else
                tempData = app.specData(idx(1));
                tempData.MetaData.FreqStop = tempTable.FreqStop(end);

                for ii = idx(2:end)
                    tempData.Data{2} = [tempData.Data{2}; app.specData(ii).Data{2}];
                end
                tempData.MetaData.DataPoints = height(tempData.Data{2});
                tempData = Misc_statsMatrix(app, tempData);

                app.specData(idx(1)) = tempData;
                app.specData(idx(2:numel(idx))) = [];
                app.specData = Misc_occMap(app, app.specData);
            end
        end
        

        function Info_Toolbar_Button2Pushed_Add(app)
            idx = app.playReport_left_Tree.SelectedNodes.NodeData;

            app.specData(end+1) = app.specData(idx);
            
            app.specData = app.specData([1:idx, numel(app.specData), idx+1:numel(app.specData)-1]);
            app.specData = Misc_occMap(app, app.specData);
        end


        function Info_Toolbar_Button2Pushed_Delete(app)
            if ~isequal(unique([app.playReport_left_Tree.Children.NodeData]), unique([app.playReport_left_Tree.SelectedNodes.NodeData]))
                idx = unique([app.playReport_left_Tree.SelectedNodes.NodeData]);
                app.specData(idx) = [];
                app.specData = Misc_occMap(app, app.specData);
            end
        end


        function Info_GPS_ReportCheck(app, idx, RelatedGPS, InitialGPS)
            if app.specData(idx).reportFlag
                msg = '<font style="font-size:12;">O fluxo de dados selecionado foi incluso em "Projeto appAnálise". Ao editar as suas informações de GPS, tornando-a nula, esse fluxo de dados será removido do projeto. Confirma essa operação?</font>';
                selection = uiconfirm(app.UIFigure, msg, 'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if selection == "Cancelar"
                    app.specData(idx).RelatedGPS = RelatedGPS;
                    app.specData(idx).gps        = InitialGPS;
                else
                    app.specData(idx).reportFlag = 0;
                    delete(app.Report_Tree.Children);  
                end
            end
        end
        
        
        function Info_LayoutChange(app)
            if ~isempty(app.playReport_docPanel.UserData)
                Playback_PlotReload(app)
            end

            File_mainInfoTreeBuilding(app)
            app.playReport_left_Tree.SelectedNodes = app.playReport_left_Tree.Children(1).Children(1);
            playReport_left_TreeSelectionChanged(app)
        end


        %-----------------------------------------------------------------%
        % MODO "MISCELÂNEAS"
        %-----------------------------------------------------------------%
        function Misc_ExportFile(app, idx)

            if app.Export_CheckBox.Value; Data = Fcn_DateTimeFilter(app, idx);
            else;                         Data = app.specData(idx);
            end
            
            defaultFilename = fullfile(app.menu_userPath.Value, ['appAnalise_' datestr(now,'yymmdd_THHMMSS')]);
            
            [filename, pathname, index] = uiputfile({'*.mat',    'appAnálise (*.mat)'; ...
                                                     '*.bin',    'Logger (*.bin)';     ...
                                                     '*.sm1809', 'SM1809 (*.sm1809)'}, ...
                                                     'Selecione a pasta e o nome do arquivo', defaultFilename);
            figure(app.UIFigure)

            % VALIDAÇÕES            
            if ~index
                return
            end

            switch index
                case 1                                                      % MAT v. 7.3 (nenhuma restrição)

                case 2                                                      % CRFS Bin v. 5
                    Node = Data(1).Node;
                    for ii = 1:numel(Data)
                        if ~strcmp(Node, Data(ii).Node) || ~contains(Data(ii).Node, 'RFeye', 'IgnoreCase', true)
                            msg = 'A funcionalidade "EXPORTAR FLUXO(S) DE DADOS", quando da escolha do formato de arquivo CRFS Bin, armazena dados gerados por um único sensor RFeye.';
                            MessageBox(app, 'warning', msg)
                            
                            return
                        end
                    end
                    
                case 3                                                      % SM1809
                    Node = Data(1).Node;
                    for ii = 1:numel(Data)
                        if ~strcmp(Node, Data(ii).Node)
                            msg = 'A funcionalidade "EXPORTAR FLUXO(S) DE DADOS", quando da escolha do formato de arquivo SM1809, armazena dados gerados por um único sensor.';
                            MessageBox(app, 'warning', msg)
                            
                            return
                        end
                        
                        if ismember(Data(ii).MetaData.DataType, class.Constants.occDataTypes)
                            msg = 'A funcionalidade "EXPORTAR FLUXO(S) DE DADOS", quando da escolha do formato de arquivo SM1809, aceita apenas fluxos de espectro.';
                            MessageBox(app, 'warning', msg)
                            
                            return
                        end
                    end
            end

            filename = fullfile(pathname, filename);
            switch index
                case 1; fileWriter.MAT73(filename,   Data);
                case 2; fileWriter.CRFSBin(filename, Data);
                case 3; fileWriter.SM1809(filename,  Data);
            end

        end


        function Misc_MaskDraw(app, idx)

            Data = app.specData(idx);

            % VALIDAÇÕES
            Node  = Data(1).Node;
            lUnit = Data(1).MetaData.metaString{1};
            for ii = 1:numel(Data)                
                if ~strcmp(Node, Data(ii).Node)
                    msg = 'A funcionalidade "MÁSCARA ESPECTRAL" aceita apenas fluxo(s) de dados gerados por um único sensor.';
                    MessageBox(app, 'warning', msg)                    
                    return

                elseif ~strcmp(lUnit, Data(ii).MetaData.metaString{1})
                    msg = 'A funcionalidade "MÁSCARA ESPECTRAL" aceita apenas fluxo(s) de dados que possuam a mesma unidade de medida.';
                    MessageBox(app, 'warning', msg)                    
                    return

                elseif ismember(Data(ii).MetaData.DataType, class.Constants.occDataTypes)
                    msg = 'A funcionalidade "MÁSCARA ESPECTRAL" não aceita fluxos de ocupação.';
                    MessageBox(app, 'warning', msg)                    
                    return
                end
            end

            if isempty(app.auxWin4)
                app.auxWin4 = auxApp.winMaskDraw(app, idx);
                drawnow nocallbacks
            else
                figure(app.auxWin4.UIFigure)
            end

        end


        function Misc_DriveTestUpdate(app)

            if ~app.playReport_left_TabLock.Enable
                try
                    app.auxWin2.timeROICallback_auxFcn(app.timeIndex)
                catch
                end
            end

        end


        function Misc_DeleteAllWindows(app)

            delete(app.auxWin1)
            delete(app.auxWin2)
            delete(app.auxWin3)
            delete(app.auxWin4)
            delete(app.auxWin5)
            delete(app.auxWin6)
            delete(app.auxWin7)
            delete(app.auxWin8)

            h = findobj('Type', 'Figure', 'Tag', 'ReportGenerator');
            if ~isempty(h)
                delete(h)
            end

        end

        
        %-----------------------------------------------------------------%
        % OUTRAS FUNÇÕES
        %-----------------------------------------------------------------%
        function Fcn_expandTree(app, Type)            
            switch Type
                case 'mainInfo'
                    for ii = 1:numel(app.playReport_left_Tree.Children)
                        expand(app.playReport_left_Tree.Children(ii));
                    end
                    app.playReport_left_DownArrow.ImageSource = 'LT_upArrow.png';                    
                case 'Report'
                    for ii = 1:numel(app.Report_Tree.Children)
                        expand(app.Report_Tree.Children(ii));
                    end                    
                case 'File'
                    for ii = 1:numel(app.File_Tree2.Children)
                        expand(app.File_Tree2.Children(ii));
                    end                    
            end
        end
        
        
        %-----------------------------------------------------------------%
        function icon = Fcn_TreeIcon(app, appColeta_230818_T151504_ID4_1binNode)            
            if     contains(Node, 'RFeye', 'IgnoreCase', true);        icon = 'Instr_CRFS.png';
            elseif contains(Node, {'FSL','FSVR','FSW','EB500','ETM'}); icon = 'Instr_R&S.png';
            elseif contains(Node, {'N9344C', 'N9936B'});               icon = 'Instr_KeySight.png';
            elseif contains(Node, 'MS2720T');                          icon = 'Instr_Anritsu.png';
            elseif contains(Node, 'SA2500');                           icon = 'Instr_Tektronix.png';
            elseif contains(Node, 'CWSM2');                            icon = 'Instr_CellPlan.png';
            else;                                                      icon = 'LT_redWarn.png';
            end            
        end
        
        
        %-----------------------------------------------------------------%
        function Data = Fcn_DateTimeFilter(app, filteredRows)            
            auxDate1 = app.Export_DatePicker1.Value;
            auxDate1.Format = "dd/MM/yyyy HH:mm:ss";
            auxDate1 = auxDate1 + hours(app.Export_Spinner1.Value) + minutes(app.Export_Spinner2.Value);
            
            auxDate2 = app.Export_DatePicker2.Value;
            auxDate2.Format = "dd/MM/yyyy HH:mm:ss";
            auxDate2 = auxDate2 + hours(app.Export_Spinner3.Value) + minutes(app.Export_Spinner4.Value);
            
            if auxDate1 > auxDate2
                error('Os valores inseridos para aplicação do filtro de TimeStamp devem ser ajustados.')
            end
            
            Data = Misc_TimeStampFilter(app.specData(filteredRows), [auxDate1, auxDate2], 0);            
        end

        
        %-----------------------------------------------------------------%
        % CRIAR UMA ÚNICA FUNÇÃO PRA CONCENTRAR ESSE "PUXADINHO", VISANDO
        % ACESSAR MÉTODOS PRIVADOS DO APP.
        %-----------------------------------------------------------------%
        function Fcn_plotToolbar(app, Type)
            switch Type
                case 1; playReport_left_TreeSelectionChanged(app)
            end
        end


        %-----------------------------------------------------------------%
        function auxWin_AddFiles(app, Type)
            switch Type
                case 'Projeto'
                    Report_ProjectIcon(app)

                case 'Fluxo de dados'
                    Report_TreeBuilding(app)
                    app.Report_Tree.SelectedNodes = app.Report_Tree.Children(1).Children(1);
                    Report_TreeSelectionChanged(app)
            end

            figure(app.UIFigure)
        end


        function auxWin_MaskDraw(app, Flag)

            switch Flag
                case 'Plot_PlaybackMode'
                    app.menu_Button3.Value = 1;
                    menu_Button1to6Pushed(app, struct('Source', app.menu_Button3))

                case 'Plot_TreeSelectionChanged'
                    playReport_left_TreeSelectionChanged(app)

                case 'Plot_FigureVisibility'
                    Playback_VisibilityClicked(app)
            end

        end
        
        
        function auxWin_SignalAnalysis(app)
            
            Report_SaveWarn(app)
            
        end


        function auxWin_DriveTest(app, SliderValue)

            app.play_right_Slider.Value = SliderValue;
            play_right_SliderValueChanging(app, struct('Value', SliderValue, 'EventSource', 'auxWin_DriveTest'))

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            try
                % PATH SEARCH
                appName = class.Constants.appName;

                if isdeployed
                    [~, result]    = system('path');
                    app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
                    if ~isfile(fullfile(app.RootFolder, sprintf('%s.exe', appName)))
                        error('Não identificado o local de instalação do <i>app</i>.')
                    end                
                else
                    app.RootFolder = fileparts(mfilename('fullpath'));
                end

                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')

                % LOG (DIARY)
                diary(fullfile(app.RootFolder, 'Debug', '_log.txt'))                
                diary on
                fprintf(sprintf('<LogEntry>\n<BeginTime>%s</BeginTime>\n', datestr(now)))

                % STARTUP
                startup_WindowPosition(app)
                startup_AxesCreation(app)
                startup_ConfigFiles(app)

                % !! RETIRAR DEPOIS !!
                ButtonValueChanged(app)


            catch ME
                fprintf('%s\n', jsonencode(ME))
                layoutFcn.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            drawnow
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            % RUNNING PROJECT CHECK
            if ~isempty(char(app.ProjectFilename.Value)) && app.Report_ImageWarn.Visible
                Selection = uiconfirm(app.UIFigure, sprintf('<font style="font-size:12;">O projeto aberto - registrado no arquivo <b>"%s"</b> - foi alterado.\n\nDeseja descartar essas alterações? Caso não, favor salvá-las no modo "RELATÓRIO".</font>', char(app.ProjectFilename.Value)), ...
                                      'appAnalise', 'Options', {'Sim', 'Não'}, 'DefaultOption', 2, 'Interpreter', 'html');
                if strcmp(Selection, 'Não')
                    return
                end
            end

            % LOG
            fprintf(sprintf('<EndTime>%s</EndTime>\n</LogEntry>\n', datestr(now)))
            diary off
            
            log = fileread(fullfile(app.RootFolder, 'Debug', '_log.txt'));
            if contains(log, 'error', 'IgnoreCase', true)
                writematrix(log, fullfile(app.RootFolder, 'Debug', '_error.txt'), "QuoteStrings", "none", "WriteMode", "append")
                writematrix("",  fullfile(app.RootFolder, 'Debug', '_log.txt'),   "QuoteStrings", "none", "WriteMode", "overwrite")
            end

            % DELETE TEMP FILES
            delete(fullfile(app.RootFolder, 'Temp', '~Report*.html'))
            delete(fullfile(app.RootFolder, 'Temp', '~Image*.*'))

            delete(fullfile(app.menu_userPath.Value, '~Report*.html'))
            delete(fullfile(app.menu_userPath.Value, '~Image*.*'))

            % DELETE APPS
            Misc_DeleteAllWindows(app)
            delete(app)
            
        end

        % Value changed function: menu_Button1, menu_Button2, 
        % ...and 2 other components
        function menu_Button1to6Pushed(app, event)

            if event.Source.Value
                set(findall(groot, 'Parent', app.menu_Grid, 'Type', 'uistatebutton', '-not', 'Tag', event.Source.Tag), 'Value', 0)
            else
                event.Source.Value = 1;
                return
            end

            ModeOperation(app, event.Source)
            
        end

        % Callback function: menu_Button5, menu_Button6, menu_Button7, 
        % ...and 1 other component
        function menu_Button7to9Pushed(app, event)
            
            switch event.Source
                case app.menu_Button5;  h = app.auxWin7;
                case app.menu_Button6;  h = app.auxWin3;
                case app.menu_Button7;  h = app.auxWin8;
                case app.menu_Settings; h = app.auxWin5;
            end

            if isempty(h)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                d.Message = '<font style="font-size:12;">Em andamento...</font>';

                switch event.Source
                    case app.menu_Button6
                        try
                            app.auxWin3 = auxApp.winCredential(app, app.UIFigure.Position, app.Report_Issue.Value);
                        catch ME
                            MessageBox(app, 'error', ME.message)
                        end

                    case app.menu_Button5;  app.auxWin7 = auxApp.winTemplate(app);
                    case app.menu_Button7;  app.auxWin8 = auxApp.winAnatelDB_old(app);
                    case app.menu_Settings; app.auxWin5 = auxApp.winConfig(app);
                end
                drawnow nocallbacks

                delete(d)                
            else
                figure(h.UIFigure)
            end

        end

        % Image clicked function: menu_Position
        function menu_PositionImageClicked(app, event)

            app.UIFigure.Position(3:4) = class.Constants.windowSize;
            fcn.winPosition(app)
            
        end

        % Callback function: home_Button1, home_Button2, home_Button3, 
        % ...and 2 other components
        function mosaic_ButtonPushed(app, event)
            
            switch event.Source
                case app.home_Button1; app.menu_Button2.Value = 1; menu_Button1to6Pushed(app, struct('Source', app.menu_Button2))
                case app.home_Button2; menu_Button7to9Pushed(app, struct('Source', app.menu_Button5))
                case app.home_Button3; menu_Button7to9Pushed(app, struct('Source', app.menu_Button6))
                case app.home_Button4; menu_Button7to9Pushed(app, struct('Source', app.menu_Button7))
                case app.home_Button5; menu_Button7to9Pushed(app, struct('Source', app.menu_Settings))
            end

        end

        % Value changed function: menu_userPath
        function menu_userPathValueChanged(app, event)
            
            userpaths = {fullfile(getenv('USERPROFILE'), 'Documents'); ...
                         fullfile(getenv('USERPROFILE'), 'Downloads')}; 

            app.General.userPath = app.menu_userPath.Value;

            if ~ismember(app.menu_userPath.Value, userpaths)
                writematrix(jsonencode(rmfield(app.General, {'ver', 'Models', 'Report'}), "PrettyPrint", true), fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json'), "FileType", "text", "QuoteStrings", "none");
            end            
            
        end

        % Image clicked function: menu_searchPath
        function menu_searchPathImageClicked(app, event)
            
            newUserPath = uigetdir(app.menu_userPath.Value, 'Escolha a pasta de trabalho');
            figure(app.UIFigure)
            
            if newUserPath
                idx = find(strcmp(app.menu_userPath.Items, newUserPath), 1);
                
                if isempty(idx)
                    app.menu_userPath.Items(end+1) = {newUserPath};
                    app.menu_userPath.Value        = newUserPath;
                    
                    menu_userPathValueChanged(app)
                else
                    app.menu_userPath.Value        = app.menu_userPath.Items(idx);
                end
            end
            
        end

        % Button pushed function: file_tool_OpenFileButton
        function file_tool_OpenFileButtonPushed(app, event)

            [Files, Path, FileType] = uigetfile({'*.bin',    'appColeta, Logger (*.bin)';      ...
                                                 '*.dbm',    'CellPlan (*.dbm)';               ...
                                                 '*.sm1809', 'SM1809 (*.sm1809)';              ...
                                                 '*.csv',    'Argus (*.csv)';                  ...
                                                 '*.mat',    'appColeta, appAnálise (*.mat)'}, ...
                                                 'Selecione um ou mais arquivos', 'MultiSelect', 'on');
            figure(app.UIFigure)
            
            if ~FileType
                return
            end
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
            
            if ~iscell(Files)
                Files = {Files};
            end
            
            RepeteadFiles = {};
            for ii = 1:numel(Files)
                d.Message = sprintf('<font style="font-size:12;">Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d</font>', Files{ii}, ii, numel(Files));
                
                RelatedFiles = {};
                for jj = 1:numel(app.metaData)
                    RelatedFiles = [RelatedFiles; Misc_RelatedFiles(app, app.metaData(jj))];
                end
                   
                switch FileType
                    case {1, 2, 3, 4}
                        if ~any(contains(RelatedFiles, Files(ii)))
                            ind = numel(app.metaData)+1;
                            
                            app.metaData(ind).File = fullfile(Path, Files{ii});
                            app.metaData(ind).Type = {'Spectral data'};
                        else
                            RepeteadFiles(end+1) = Files(ii);
                            continue
                        end
                        
                    case 5
                        lastwarn('')
                        load(fullfile(Path, Files{ii}), '-mat', 'prj_Type', 'prj_RelatedFiles')
                        [~, warnID] = lastwarn;
                        
                        % Validações...
                        if strcmp(warnID, 'MATLAB:load:variableNotFound')
                            msg = sprintf('O arquivo indicado a seguir não possui a variável "prj_Type", não sendo, portanto, um arquivo gerado pelo appAnálise ou appColeta.\n• %s', Files{ii});
                            MessageBox(app, 'warning', msg)
                            
                            continue
                            
                        elseif ~isempty(strfind([app.metaData.File], Files{ii}))
                            msg = sprintf('O arquivo indicado a seguir já tinha sido lido.\n• %s', Files{ii});
                            MessageBox(app, 'warning', msg)
                            
                            continue
                            
                        elseif any(contains(RelatedFiles, prj_RelatedFiles))
                            msg = sprintf(['O arquivo indicado a seguir não será lido por já ter sido lido ao menos um arquivo relacionado ao ' ...
                                           'projeto appAnálise.\n• %s\n\nArquivo(s) relacionado(s) ao projeto appAnálise já lido(s):\n%s'],   ...
                                           Files{ii}, strjoin(cellfun(@(x) sprintf('• %s', x), RelatedFiles(contains(RelatedFiles, prj_RelatedFiles)), 'UniformOutput', false), '\n'));
                            MessageBox(app, 'warning', msg)
                            
                            continue

                        elseif ~isempty(app.metaData) && strcmp(prj_Type{1}, 'Project data') && ismember({'Project data'}, [app.metaData.Type])
                            msg = sprintf('O arquivo indicado a seguir não será lido porque já foram lidos os metadados de outro projeto appAnálise.\n• %s', Files{ii});
                            MessageBox(app, 'warning', msg)
                            
                            continue
                            
                        else
                            ind = numel(app.metaData)+1;
                            
                            app.metaData(ind).File = fullfile(Path, Files{ii});
                            app.metaData(ind).Type = prj_Type;
                        end
                end
                
                try
                    File_GeneralReader(app, ind)

                catch ME
                    fprintf('%s\n', jsonencode(ME))
                    MessageBox(app, 'error', getReport(ME))
                    
                    if contains(ME.message, 'O arquivo indicado a seguir')
                        if ind == 1; app.metaData = struct('File', {}, 'Type', {}, 'Data', {}, 'Samples', {}, 'Memory', {});
                        else;        app.metaData(ind) = [];
                        end
                        
                        ErrorFlag = 0;
                    else
                        app.metaData = struct('File', {}, 'Type', {}, 'Data', {}, 'Samples', {}, 'Memory', {});
                        app.Samples  = [];
                        
                        ErrorFlag = 1;
                    end
                    fclose all;

                    if ErrorFlag
                        break
                    end
                end
            end
            
            app.File_Memory.Value = sum([app.metaData.Memory]);
            
            if ~isempty(RepeteadFiles)
                msg = 'Os metadados do(s) arquivo(s) indicado(s) a seguir já tinham sido lidos.';
                
                for jj = 1:numel(RepeteadFiles)
                    msg = sprintf('%s\n• %s', msg, RepeteadFiles{jj});
                end
                MessageBox(app, 'warning', msg)
            end

            File_Tree1_Building(app)

        end

        % Callback function
        function File_Button_ReadFilesPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
            
            if ~isempty(app.Report_Tree.Children)
                delete(app.Report_Tree.Children)
            end
            
            app.occFlag                = 0;
            app.traceInfo.SelectedNode = 0;
            app.timeIndex              = 1;
            app.peaksTable(:,:)        = [];
            
            Playback_PlotReload(app)
            
            try
                File_specDataReader(app, d)
            catch ME
                fprintf('%s\n', jsonencode(ME))
                MessageBox(app, 'error', getReport(ME))

                File_specDataReader_Error(app)
                return
            end
            
            File_mainInfoTreeBuilding(app)

            set(findall(groot, 'Parent', app.menu_Grid, 'Type', 'uistatebutton'), 'Enable', 1)
            app.menu_Button3.Value = true;
            menu_Button1to6Pushed(app, struct('Source', app.menu_Button3))
            
            if ~isempty(app.play_FindPeaks_Tree.Children)
                delete(app.play_FindPeaks_Tree.Children)
            end

            if ~isempty(app.Report_Tree.Children)
                delete(app.Report_Tree.Children)
            end
            
        end

        % Selection changed function: file_left_Tree
        function file_left_TreeSelectionChanged(app, event)
            
            if numel(app.file_left_Tree.SelectedNodes) == 1
                File_Tree1SelectionChanged_Aux(app, 'On')
                
                ind1 = app.file_left_Tree.SelectedNodes.NodeData;
                
                app.File_FileType.Value  = app.metaData(ind1).Type;
                app.File_FilesList.Value = strjoin(Misc_RelatedFiles(app, app.metaData(ind1)), '\n');
                
                File_Tree2_Building(app)
            
            else
                File_Tree1SelectionChanged_Aux(app, 'Off')
            end            
            
        end

        % Callback function
        function File_Tree2SelectionChanged(app, event)
            
            if numel(app.File_Tree2.SelectedNodes) == 1 && isnumeric(app.File_Tree2.SelectedNodes.NodeData)
                File_Layout(app, 'On')
                
                ind1 = app.file_left_Tree.SelectedNodes.NodeData;
                ind2 = app.File_Tree2.SelectedNodes.NodeData;
                
                app.File_DataPoints.Value = app.metaData(ind1).Data(ind2).MetaData.DataPoints;
                
                if isempty(app.metaData(ind1).Data(ind2).MetaData.metaString{4})
                    app.File_Operation.Value = app.metaData(ind1).Data(ind2).MetaData.metaString{3};
                else
                    app.File_Operation.Value = sprintf('%s (Modo), %s (Detector)',                           ...
                                                       app.metaData(ind1).Data(ind2).MetaData.metaString{3}, ...
                                                       app.metaData(ind1).Data(ind2).MetaData.metaString{4});
                end
                
                if ~isempty(app.metaData(ind1).Data(ind2).MetaData.Resolution)
                    set(app.File_Resolution, 'Enable', 1, 'Value', app.metaData(ind1).Data(ind2).MetaData.Resolution ./ 1e+3, 'ValueDisplayFormat', '%.3f')
                else
                    set(app.File_Resolution, 'Enable', 0, 'Value', -1, 'ValueDisplayFormat', '%d')
                end
                
                app.File_LevelUnit.Value       = app.metaData(ind1).Data(ind2).MetaData.metaString{1};
                app.File_Description.Value     = app.metaData(ind1).Data(ind2).Description;
                
                if ~isempty(app.metaData(ind1).Data(ind2).Samples)
                    app.File_Samples.Value         = app.metaData(ind1).Data(ind2).Samples;
                    app.File_EstimatedMemory.Value = 4 .* app.metaData(ind1).Data(ind2).Samples .* app.metaData(ind1).Data(ind2).MetaData.DataPoints .* 1e-6; % float32 (4 bytes)
                else
                    app.File_Samples.Value         = 1;
                    app.File_EstimatedMemory.Value = 4 .* app.metaData(ind1).Data(ind2).MetaData.DataPoints .* 1e-6; % float32 (4 bytes)
                end
                
                if ~isempty(app.metaData(ind1).Data(ind2).gps.Location)
                    set(app.File_City, 'Enable', 1, 'Value', app.metaData(ind1).Data(ind2).gps.Location)
                else
                    set(app.File_City, 'Enable', 0, 'Value', '(desconhecido)')
                end
                
                if ~isempty(app.metaData(ind1).Data(ind2).ObservationTime)
                    set(app.File_ObservationTime, 'Enable', 1, 'Value', app.metaData(ind1).Data(ind2).ObservationTime)
                else
                    set(app.File_ObservationTime, 'Enable', 0, 'Value', '(pendente de implementação)')
                end
                
                app.File_Info.Text = '';
                if app.File_Samples.Value == 65536
                    app.File_Info.Text = 'Atingido o limite do número de traços por fluxo de dados (65536).';
                end
                
            else
                File_Layout(app, 'Off')
            end
            
        end

        % Menu selected function: File_ContextMenu1
        function File_Tree1Delete(app, event)
            
            idx = [];
            for ii = 1:numel(app.file_left_Tree.SelectedNodes)
                idx = [idx, app.file_left_Tree.SelectedNodes(ii).NodeData];
            end
            
            if numel(idx) < numel(app.metaData)
                app.metaData(idx)     = [];
                app.File_Memory.Value = sum([app.metaData.Memory]);
            else
                app.metaData          = struct('File', {}, 'Type', {}, 'Data', {}, 'Samples', {}, 'Memory', {});
                app.File_Memory.Value = 0;
            end
            
            File_Tree1_Building(app)
                        
        end

        % Menu selected function: File_ContextMenu2
        function File_Tree2Delete(app, event)
            
            idx1 = app.file_left_Tree.SelectedNodes.NodeData;
            
            idx2 = [];
            for ii = 1:numel(app.File_Tree2.SelectedNodes)
                if ~isnumeric(app.File_Tree2.SelectedNodes(ii).NodeData)
                    msg = 'Somente poderão ser excluídos os fluxos de dados individualmente, devendo restar ao menos um fluxo de dados.';
                    MessageBox(app, 'warning', msg)
                    
                    return
                end
                
                idx2 = [idx2, app.File_Tree2.SelectedNodes(ii).NodeData];
            end
            
            if numel(app.metaData(idx1).Data) > numel(idx2)
                app.metaData(idx1).Data(idx2)    = [];
                app.metaData(idx1).Samples(idx2) = [];
                
                
            else
                msg = 'O arquivo selecionado deve possuir ao menos um fluxo de dados.';
                MessageBox(app, 'warning', msg)
            end
            
            app.metaData(idx1).Memory = File_GeneralReader_EstimatedMemory(app, idx1);
            app.File_Memory.Value     = sum([app.metaData.Memory]);
            
            File_Tree2_Building(app)
            
        end

        % Selection changed function: playReport_left_Tree
        function playReport_left_TreeSelectionChanged(app, event)
            
            idx = [];
            if ~isempty(app.playReport_left_Tree.SelectedNodes)
                idx = unique([app.playReport_left_Tree.SelectedNodes.NodeData]);
            end
            
            % PLAYBACK
            if app.menu_Button3.Value
                if numel(idx) == 1
                    InitialSelectedNode = app.traceInfo.SelectedNode;

                    Playback_Layout(app)
                    
                    if app.plotFlag
                        if idx ~= InitialSelectedNode
                            app.timeIndex             = 1;
                            app.play_right_Slider.Value = 0;
                            
                            if app.specData(idx).Samples > 1
                                app.axesFlag = 1;
                            else
                                Plot_Layout1(app)
                                Plot_Layout2(app)
                                Plot_StartUp1(app)
                                Plot_StartUp2(app)
                                Plot_StartUp3(app)
                            end
                        end
                    else
                        app.play_FindPeaks_add.Enable = 1;
                        app.play_right_Slider.Enable    = 1;
                        app.play_right_Loop.Enable          = 1;
                        app.play_right_Play.Enable          = 1;
                        
                       if idx ~= InitialSelectedNode
                            Plot_Layout1(app)
                            Plot_Layout2(app)
                            Plot_StartUp1(app)
                            Plot_StartUp2(app)
                            Plot_StartUp3(app)
                       end
                    end

                else
                    if ~isempty(app.playReport_docPanel.UserData) & isvalid(app.playReport_docPanel.UserData) & ~isequal(app.playReport_left_Tree.SelectedNodes, app.playReport_docPanel.UserData)
                        app.playReport_left_Tree.SelectedNodes = app.playReport_docPanel.UserData;
                        return
                    end
                end

            % REPORT, EDIT OR MISC
            else
                if ~isempty(app.Report_Tree.SelectedNodes)
                    app.Report_Tree.SelectedNodes  = [];
                    app.Report_addButton.Enable    = 1;
                end

                Report_Layout(app)

                % EDIT
                if app.menu_Button5.Value
                    ModeOperation_Edit(app)

                % MISC
                elseif app.menu_Button6.Value
                    ModeOperation_Misc(app)
                end
            end
            

            % METADATA MESSAGE BOX (BELOW TREE)
            if numel(idx) == 1
                app.playReport_left_Metadata.Enable = 1;
                
                string1 = {sprintf('Script: "%s"\nDescrição: "%s"', app.specData(idx).TaskName,         ...
                                                                    app.specData(idx).Description); ''; ...
                                                                    sprintf('Pontos por traço: %d\nVarreduras: %d\nUnidade: %s\nOperação: %s', app.specData(idx).MetaData.DataPoints,    ...
                                                                                                                                               app.specData(idx).Samples,                ...
                                                                                                                                               app.specData(idx).MetaData.metaString{1}, ...
                                                                                                                                               app.specData(idx).MetaData.metaString{3})};
                
                if ~isempty(app.specData(idx).MetaData.metaString{4})
                    string1{3} = sprintf('%s (Modo), %s (Detector)', string1{3}, app.specData(idx).MetaData.metaString{4});
                end
                
                if ~isempty(app.specData(idx).MetaData.metaString{2})
                    string1{3} = sprintf('%s\nResolução: %s', string1{3}, app.specData(idx).MetaData.metaString{2});
                end
                
                string1{4} = sprintf('Antena: %s\n\n', app.specData(idx).MetaData.metaString{5});
                string1{5} = sprintf('Arquivo(s) relacionado(s): %s', strjoin(app.specData(idx).RelatedFiles.Name, ', '));
                
                app.playReport_left_Metadata.Value = string1;
                set(app.play_Limits_Grid.Children, 'Enable', 1, 'FontColor', [0,0,0])
                app.play_FindPeaks_add.Enable = 1;
                
            else
                set(app.playReport_left_Metadata, 'Enable', 0, 'Value', '')    
                
                set(app.play_Limits_Grid.Children, 'Enable', 0, 'FontColor', [0.94,0.94,0.94])
                app.play_Limits_xLimLabel.FontColor = [0,0,0];
                app.play_Limits_yLimLabel.FontColor = [0,0,0];

                app.play_FindPeaks_add.Enable = 0;
                if ~isempty(app.play_FindPeaks_Tree.Children)
                    delete(app.play_FindPeaks_Tree.Children)
                end
            end
            
        end

        % Image clicked function: playReport_left_DownArrow
        function playReport_left_DownArrowCollapseTreeNodes(app, event)
            
            if strcmpi(app.playReport_left_DownArrow.ImageSource, 'LT_downArrow.png')
                for ii = 1:numel(app.playReport_left_Tree.Children)
                    expand(app.playReport_left_Tree.Children(ii));
                end
                app.playReport_left_DownArrow.ImageSource = 'LT_upArrow.png';
                
            else
                collapse(app.playReport_left_Tree, 'all');
                app.playReport_left_DownArrow.ImageSource = 'LT_downArrow.png';
            end
            
        end

        % Button pushed function: play_tool_Button2
        function play_tool_Button2ButtonPushed(app, event)
            
            msg = '<font style="font-size:12;">Confirma a exclusão de todos os fluxos de dados e as informações auxiliares, dentre elas a lista de exceções?</font>';
            selection = uiconfirm(app.UIFigure, msg, 'Alerta', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Interpreter', 'html');
            
            if strcmp(selection, 'OK')
                app.projData = struct('image', {''}, 'table', {[]});
                Report_ProjectIcon(app)

                app.peaksTable(:,:)    = [];
                app.exceptionList(:,:) = [];

                set(app.play_Limits_Grid.Children, 'Enable', 0, 'FontColor', [0.94,0.94,0.94])
                app.play_Limits_xLimLabel.FontColor = [0,0,0];
                app.play_Limits_yLimLabel.FontColor = [0,0,0];

                Playback_PlotReload(app)
                File_specDataReader_Error(app)

                app.Report_ImageWarn.Visible = 0;
                app.ProjectName.Value        = 'Projeto 001';
                app.ProjectFilename.Value    = '';
                app.Report_Version.Value     = 'Preliminar';
                app.Report_Issue.Value       = -1;
            end
            
        end

        % Button pushed function: play_tool_Button4
        function play_tool_Button4ButtonPushed(app, event)
            
            Misc_DeleteAllWindows(app)
            
            app.auxWin1 = [];
            app.auxWin2 = [];
            app.auxWin3 = [];
            app.auxWin4 = [];
            app.auxWin5 = [];
            app.auxWin6 = [];
            app.auxWin7 = [];
            app.auxWin8 = [];

            set(app.playReport_left_TabLock, 'Enable', 1, 'ImageSource', 'play_lockOpen.png')
            app.playReport_left_Tree.Enable = 1;
            
        end

        % Selection changed function: play_FindPeaks_Tree
        function play_FindPeaks_TreeSelectionChanged(app, event)
            
            % Startup
            app.play_FindPeaks_PeakCFLabel.Enable = 0;
            app.play_FindPeaks_PeakBWLabel.Enable = 0;
            set(app.play_FindPeaks_PeakCF, 'Value', 0, 'Enable', 0)
            set(app.play_FindPeaks_PeakBW, 'Value', 0, 'Enable', 0)

            % Processing...
            if numel(app.play_FindPeaks_Tree.SelectedNodes) == 1
                ind1 = app.traceInfo.SelectedNode;
                ind2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;

                app.play_FindPeaks_PeakCFLabel.Enable = 1;
                app.play_FindPeaks_PeakBWLabel.Enable = 1;
                set(app.play_FindPeaks_PeakCF, 'Value', round(app.specData(ind1).Emissions.Frequency(ind2), 3), 'Enable', 1)
                set(app.play_FindPeaks_PeakBW, 'Value', round(app.specData(ind1).Emissions.BW(ind2), 3),    'Enable', 1)

                if strcmp(app.play_FindPeaks_Tree.SelectedNodes.Icon, 'play_Truncated.png')
                    app.play_ContextMenu2_digital.Enable = 0;
                    app.play_ContextMenu2_analog.Enable        = 1;
                else
                    app.play_ContextMenu2_digital.Enable = 1;
                    app.play_ContextMenu2_analog.Enable        = 0;
                end
                app.play_ContextMenu2_search.Enable = 1;
                app.play_ContextMenu2_Drive.Enable  = 1;

                if exist('event', 'var')
                    Plot_StartUp2_Spectrum(app, 'TreeSelectionChanged', ind1, [])
                end

            elseif numel(app.play_FindPeaks_Tree.SelectedNodes) > 1
                set(app.play_ContextMenu2_edit.Children, 'Enable', 1)
                app.play_ContextMenu2_search.Enable = 0;
                app.play_ContextMenu2_Drive.Enable  = 0;
            end
            
        end

        % Selection changed function: play_FindPeaks_RadioGroup
        function play_FindPeaks_RadioGroupSelectionChanged(app, event)
            
            switch app.play_FindPeaks_RadioGroup.SelectedObject.Text
                case {'ROI', 'DataTips'}
                    app.play_right_Tab3Info.RowHeight{3} = 0;
                case 'Automática'
                    app.play_right_Tab3Info.RowHeight{3} = 124;
            end
            
        end

        % Image clicked function: play_FindPeaks_add
        function Playback_peakAddPushed(app, event)
            
            ind1 = app.traceInfo.SelectedNode;
            
            % Identificação dos novos valores de frequência, largura de
            % banda e índice.
            if app.play_FindPeaks_Radio1.Value                
                app.axes1.Interactions  = zoomInteraction;
                app.axes2.Interactions  = zoomInteraction;
                app.axes3.Interactions  = zoomInteraction;

                mkrTemp = drawrectangle(app.axes1, Color=[0.40,0.73,0.88], LineWidth=1, Tag='mkrTemp');

                newFreq = mkrTemp.Position(1) + mkrTemp.Position(3)/2;                                                              % Em MHz
                if (newFreq*1e+6 < app.specData(ind1).MetaData.FreqStart) || ...
                   (newFreq*1e+6 > app.specData(ind1).MetaData.FreqStop)
                
                   delete(mkrTemp)
                   return
                end
                
                newBW    = mkrTemp.Position(3) * 1000;                                                                              % Em kHz
                newIndex = round((newFreq .* 1e+6 - app.bCoef) ./ app.aCoef);
                Method   = 'Detecção manual';

            elseif app.play_FindPeaks_Radio3.Value
                Attributes = struct('Fcn',         app.play_FindPeaks_Trace.Value,       ...
                                    'NPeaks',      app.play_FindPeaks_Numbers.Value,     ...
                                    'THR',         app.play_FindPeaks_THR.Value,         ...
                                    'Proeminence', app.play_FindPeaks_proeminence.Value, ...
                                    'Distance',    app.play_FindPeaks_distance.Value,    ...
                                    'BW',          app.play_FindPeaks_BW.Value);

                [newIndex, newFreq, newBW] = Detection_findpeaks(app.specData(ind1), Attributes);
                newBW = newBW * 1000;

                Method = sprintf('%s - %s', 'Findpeaks', jsonencode(Attributes));

            else
                h = findobj('Type', 'DataTip');

                newFreq = [];
                ii = 0;
                while ii < numel(h)
                    ii = ii+1;
                    if isequal(h(ii).Parent.Parent.Parent, app.playReport_docPanel)
                        newFreq(end+1,1) = double(round(h(ii).X, 3));                                                                   % Em MHz
                    end
                end
                newBW    = zeros(numel(newFreq), 1);
                newIndex = round((newFreq .* 1e+6 - app.bCoef) ./ app.aCoef);
                Method   = 'Detecção manual';
            end


            % Validações dos índices:
            newIndex(newIndex < 1) = 1;
            newIndex(newIndex > app.specData(ind1).MetaData.DataPoints) = app.specData(ind1).MetaData.DataPoints;
            
            % Inclusão das novas informações na variável app.specData e 
            % ajuste do plot.
            if ~isempty(newIndex)
                if isempty(app.specData(ind1).Emissions)
                    app.specData(ind1).Emissions = table('Size', [0, 5],                                                   ...
                                                         'VariableTypes', {'uint16', 'double', 'double', 'uint8', 'cell'}, ...
                                                         'VariableNames', {'Index', 'Frequency', 'BW', 'Type', 'Detection'});
                end
                app.specData(ind1).Emissions(end+1:end+numel(newIndex),:) = table(newIndex, newFreq, newBW, ones(numel(newIndex),1), repmat({Method}, numel(newIndex), 1));

                [~, uniqueIndex, ~]          = unique(app.specData(ind1).Emissions.Index);
                app.specData(ind1).Emissions = app.specData(ind1).Emissions(uniqueIndex,:);
                
                selectedEmission = find(app.specData(ind1).Emissions.Index == newIndex(1), 1);
                Plot_StartUp2_Spectrum(app, 'AddButtonPushed', ind1, selectedEmission)

            else
                msg = 'Não identificado pico que atenda as condições estabelecidas';
                MessageBox(app, 'warning', msg)
            end
            
        end

        % Menu selected function: play_ContextMenu2_del
        function Playback_peakDeletePushed(app, event)
            
            ind1 = app.playReport_left_Tree.SelectedNodes.NodeData;
            
            for ii = numel(app.play_FindPeaks_Tree.SelectedNodes):-1:1
                app.specData(ind1).Emissions(app.play_FindPeaks_Tree.SelectedNodes(ii).NodeData,:) = [];
            end

            Plot_StartUp2_Spectrum(app, 'DeleteButtonPushed', ind1, 1)
            
        end

        % Value changed function: play_FindPeaks_PeakBW, 
        % ...and 1 other component
        function Playback_peakValueChanged(app, event)
            
            ind1 = app.traceInfo.SelectedNode;
            ind2 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;

            switch event.Source
                case app.play_FindPeaks_PeakCF
                    if (app.play_FindPeaks_PeakCF.Value*1e+6 < app.specData(ind1).MetaData.FreqStart) || ...
                       (app.play_FindPeaks_PeakCF.Value*1e+6 > app.specData(ind1).MetaData.FreqStop)
                    
                       app.play_FindPeaks_PeakCF.Value = round(app.specData(ind1).Emissions.Frequency(ind2), 3);
                       return
                    end
                    
                    newIndex = round((app.play_FindPeaks_PeakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    app.play_FindPeaks_PeakCF.Value = app.x(newIndex);
                    
                    app.specData(ind1).Emissions(ind2,[1:2, 5]) = {newIndex, app.play_FindPeaks_PeakCF.Value, 'Detecção manual'};
                    app.specData(ind1).Emissions = sortrows(app.specData(ind1).Emissions, 'Index');
                                
                    selectedEmission = find(app.specData(ind1).Emissions.Index == newIndex, 1);
                    Plot_StartUp2_Spectrum(app, 'PeakValueChanged', ind1, selectedEmission)

                case app.play_FindPeaks_PeakBW
                    app.specData(ind1).Emissions(ind2,[3, 5]) = {app.play_FindPeaks_PeakBW.Value, 'Detecção manual'};
                    Plot_StartUp2_Spectrum(app, 'PeakValueChanged', ind1, ind2)
            end
            
        end

        % Menu selected function: play_ContextMenu2_search
        function Playback_peakSearch(app, event)
            
            global AnatelDB

            idx0 = app.playReport_left_Tree.SelectedNodes.NodeData;
            idx1 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;            
            
            Data = app.specData(idx0);
            if Data.Emissions.Type(idx1)
                Truncated = Misc_TruncatedFrequency(Data, Data.Emissions.Frequency(idx1));
            else
                Truncated = Data.Emissions.Frequency(idx1);
            end            

            idx2 = find(abs(AnatelDB.Frequency - Truncated) <= 1e-5);
            if ~isempty(idx2)
                auxDistance = geo_lldistkm_v2(struct('lat', Data.gps.Latitude, 'lon', Data.gps.Longitude), AnatelDB(idx2, 3:4));
                [Distance, idx3] = min(auxDistance);
                Description = char(AnatelDB.Description(idx2(idx3)));

                if Data.Emissions.Type(idx1)
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) foi truncada em <b>%.5f MHz</b>.\n\n' ...
                                   'Foram identificados <b>%d registros</b> de estações cujas frequências centrais coincidem com %.3f MHz.\n\n' ...
                                   'A estação mais próxima do local de monitoração está a <b>%.1f km</b>, tendo como descrição "<b>%s</b>"'],   ...
                                   Data.Emissions.Frequency(idx1), Truncated, numel(idx2), Truncated, Distance, Description);
                else
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) não foi truncada.\n\n' ...
                                   'Foram identificados <b>%d registros</b> de estações cujas frequências centrais coincidem com %.3f MHz.\n\n' ...
                                   'A estação mais próxima do local de monitoração está a <b>%.1f km</b>, tendo como descrição "<b>%s</b>"'],   ...
                                   Data.Emissions.Frequency(idx1), numel(idx2), Truncated, Distance, Description);
                end

            else
                if Data.Emissions.Type(idx1)
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) foi truncada em <b>%.5f MHz</b>.\n\n' ...
                                   'Não foi identificado registro de estação cuja frequência central coincide com %.5f MHz.'], ...
                                   Data.Emissions.Frequency(idx1), Truncated, Truncated);
                else
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) não foi truncada.\n\n' ...
                                   'Não foi identificado registro de estação cuja frequência central coincide com %.5f MHz.'], ...
                                   Data.Emissions.Frequency(idx1), Truncated);
                end
            end

            MessageBox(app, 'info', msg)

        end

        % Value changed function: Playback_occType
        function Playback_occTypeValueChanged(app, event)
            
            app.Playback_occTime.Enable      = 1;
            app.Playback_occTimeLabel.Enable = 1;
            
            switch app.Playback_occType.Value
                case 'Linear'
                    app.Playback_occValue.Enable          = 1;
                    app.Playback_occValueLabel.Enable     = 1;
                    
                    app.Playback_occOffset.Enable         = 0;
                    app.Playback_occOffsetLabel.Enable    = 0;
                    app.Playback_occConfLevel.Enable      = 0;
                    app.Playback_occConfLevelLabel.Enable = 0;
                    app.Playback_occLabel.Enable          = 0;
                    set(app.Playback_occGrid2.Children, 'Enable', 0)
                    
                case 'Piso de ruído (Offset)'
                    app.Playback_occValue.Enable          = 0;
                    app.Playback_occValueLabel.Enable     = 0;
                                        
                    app.Playback_occOffset.Enable         = 1;
                    app.Playback_occOffsetLabel.Enable    = 1;
                    app.Playback_occConfLevel.Enable      = 1;
                    app.Playback_occConfLevelLabel.Enable = 1;
                    app.Playback_occLabel.Enable          = 1;
                    set(app.Playback_occGrid2.Children, 'Enable', 1)
            end
            
            delete(findobj('Tag', 'occROI'))
            cla(app.axes2)
            
            Playback_occValueChanged(app)
            
        end

        % Menu selected function: play_ContextMenu2_analog, 
        % ...and 1 other component
        function Playback_TruncatedCheckBoxValueChanged(app, event)
            
            ind1 = app.playReport_left_Tree.SelectedNodes.NodeData;

            for ii = 1:numel(app.play_FindPeaks_Tree.SelectedNodes)
                ind2 = app.play_FindPeaks_Tree.SelectedNodes(ii).NodeData;
    
                switch event.Source.Text
                    case 'Truncar frequência'
                        app.specData(ind1).Emissions.Type(ind2)      = 1;
                        app.play_FindPeaks_Tree.SelectedNodes(ii).Icon = 'play_Truncated.png'; 
    
                    case 'Não truncar'
                        app.specData(ind1).Emissions.Type(ind2)      = 0;
                        app.play_FindPeaks_Tree.SelectedNodes(ii).Icon = 'play_Untruncated.png';
                end
            end
            play_FindPeaks_TreeSelectionChanged(app)
            
        end

        % Value changed function: Playback_occValue
        function Playback_occValueChanged(app, event)
            
            if app.traceInfo.SelectedNode
                ind1 = app.traceInfo.SelectedNode;
                
                Plot_StartUp2_OCC(app, ind1)
                Plot_StartUp3(app)
            end
            
        end

        % Value changing function: play_right_Slider
        function play_right_SliderValueChanging(app, event)
            
            ind1 = app.traceInfo.SelectedNode;
            
            app.timeIndex = round(event.Value/100 * app.specData(ind1).Samples);
            if app.timeIndex < 1
                app.timeIndex = 1;
            elseif app.timeIndex > app.specData(ind1).Samples
                app.timeIndex = app.specData(ind1).Samples;
            end

            if ~isempty(app.obj_Persistance)
                Plot_Persistance_Update(app, ind1)
            end

            app.line_ClearWrite.YData = app.specData(ind1).Data{2}(:, app.timeIndex)';
            
            for ii = 1:numel(app.mkr_Label)
                app.mkr_Label(ii).Position(2) = app.line_ClearWrite.YData(app.line_ClearWrite.MarkerIndices(ii));
            end
            
            if ~isempty(app.line_wfTime) & strcmp(app.play_Waterfall_Timestamp.Value, 'on')
                app.line_wfTime.YData = repmat(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 1, 2);
            end

            if ~app.plotFlag
                app.axes1.Title.String{4} = sprintf('%s (%d de %d)', ...
                                            datestr(app.specData(ind1).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS'), ...
                                            app.timeIndex, app.specData(ind1).Samples);
            end
            
            event = struct(event);
            if ~isfield(event, 'EventSource')
                Misc_DriveTestUpdate(app)
            end
            
        end

        % Callback function: play_Waterfall_Colormap, 
        % ...and 4 other components
        function play_Waterfall_DecimationValueChanged(app, event)
            
            switch event.Source
                case app.play_Waterfall_Decimation
                    if ~isempty(app.axes3.Children) && ~isempty(app.playReport_left_Tree.SelectedNodes)
                        d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                        d.Message = '<font style="font-size:12;">Em andamento...</font>'; 
        
                        delete(findobj({'Tag', 'wfSurface', '-or', 'Tag', 'wfTimeStamp'}))
                        app.line_wfTime = [];
        
                        Plot_StartUp2_WaterFall(app)
                        Plot_StartUp3(app)
                        drawnow nocallbacks
                    end


                case app.play_Waterfall_Timestamp
                    switch app.play_Waterfall_Timestamp.Value
                        case 'on'
                            if app.traceInfo.SelectedNode
                                ind = app.traceInfo.SelectedNode;
                                t   = app.specData(ind).Data{1}(app.timeIndex);
                                
                                app.line_wfTime = line(app.axes3, [app.x(1) app.x(end)], [t(1) t(1)], [app.axes1.YLim(2) app.axes1.YLim(2)], ...
                                                                  'Color', 'red', 'LineWidth', 1, 'PickableParts', 'none', 'Tag', 'wfTimeStamp');
                            end

                        case 'off'
                            delete(findobj('Tag', 'wfTimeStamp')) 
                            app.line_wfTime = [];
                    end


                case app.play_Waterfall_Colormap
                    startup_Colormap_axes3(app)


                case app.play_Waterfall_Interpolation
                    set(findobj('Tag', 'wfSurface'), 'MeshStyle', app.play_Waterfall_Interpolation.Value)


                case app.play_Waterfall_cLim_Mode
                    set(app.axes3, 'CLim', app.RestoreView{4})
                    app.play_Waterfall_cLim1.Value = app.axes3.CLim(1);
                    app.play_Waterfall_cLim2.Value = app.axes3.CLim(2);
            end

        end

        % Value changed function: play_Limits_xLim1, play_Limits_xLim2
        function Playback_xLimitsValueChanged(app, event)
            
            FreqRange = app.play_Limits_xLim2.Value - app.play_Limits_xLim1.Value;
            if FreqRange > 0
                app.axes1.XLim = [app.play_Limits_xLim1.Value, app.play_Limits_xLim2.Value];
            else
                switch event.Source.Tag
                    case 'FreqStart'; app.play_Limits_xLim1.Value = event.PreviousValue;
                    case 'FreqStop';  app.play_Limits_xLim2.Value = event.PreviousValue;
                end
            end

        end

        % Value changed function: play_Limits_yLim1, play_Limits_yLim2
        function Playback_yLimitsValueChanged(app, event)

            LevelRange = app.play_Limits_yLim2.Value - app.play_Limits_yLim1.Value;
            if LevelRange > 0
                app.axes1.YLim = [app.play_Limits_yLim1.Value, app.play_Limits_yLim2.Value];
            else
                switch event.Source.Tag
                    case 'MinLevel'; app.play_Limits_yLim1.Value = event.PreviousValue;
                    case 'MaxLevel'; app.play_Limits_yLim2.Value = event.PreviousValue;
                end
            end

        end

        % Selection changed function: Report_Tree
        function Report_TreeSelectionChanged(app, event)
            
            app.playReport_left_Tree.SelectedNodes = [];
            set(app.playReport_left_Metadata, 'Enable', 0, 'Value', '')
            
            Report_Layout(app)
            
        end

        % Button pushed function: report_tool_Button2, report_tool_Button3
        function Report_playButtonPushed(app, event)
            
            switch event.Source
                case app.report_tool_Button2; Mode = 'Preview';
                case app.report_tool_Button3;    Mode = 'Report';
            end

            % EXCLUSÃO FIGURAS INVISÍVEIS:
            h = findobj('Type', 'Figure', 'Tag', 'ReportGenerator', 'Visible', 0);
            if ~isempty(h)
                delete(h)
            end
            
            % VALIDAÇÕES:
            if (Mode == "Report") & (app.Report_Version.Value == "Definitiva")
                if app.Report_Issue.Value <= 0
                    msg = 'Favor inserir número da inspeção.';
                    MessageBox(app, 'warning', msg)

                    return
                end

                msg = sprintf('<font style="font-size:12;">Confirma que se trata de monitoração relacionada à Inspeção nº %d?</font>', app.Report_Issue.Value);
                selection = uiconfirm(app.UIFigure, msg, 'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if selection == "Cancelar"
                    return
                end
                
            elseif Mode == "Preview"
                if isempty(app.Report_Tree.CheckedNodes)
                    msg = 'Necessário selecionar ao menos um fluxo de dados a processar.';
                    MessageBox(app, 'warning', msg)
                    
                    return
                end
            end

            ReportGenerator_Main(app, Mode)

            % EXCLUI JANELA DE "ANÁLISE DE EMISSÕES":
            if ~isempty(app.auxWin1)
                delete(app.auxWin1)
                app.auxWin1 = [];
            end
            
        end

        % Button pushed function: Report_NewProject
        function Report_NewProjectButtonPushed(app, event)
            
                msg = sprintf('<font style="font-size:12;">Confirma o fechamento do projeto "%s"?</font>', app.ProjectName.Value);
                selection = uiconfirm(app.UIFigure, msg, 'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if strcmp(selection, 'Cancelar')
                    return
                end
                
                app.ProjectName.Value     = 'Projeto 001';
                app.ProjectFilename.Value = '';
                app.Report_Version.Value  = 'Preliminar';
                app.Report_Issue.Value    = -1;
                
                idx = find([app.specData.reportFlag]);
                for ii = idx
                    app.specData(ii).reportFlag = 0;
                end

                delete(app.Report_Tree.Children);
                app.playReport_left_Tree.SelectedNodes = app.playReport_left_Tree.Children(1);
                playReport_left_TreeSelectionChanged(app)
                
                app.peaksTable(:,:)    = [];
                app.exceptionList(:,:) = [];
                
                play_tool_Button4ButtonPushed(app)
                
            if ~isempty(app.auxWin1)
                delete(app.auxWin1)
                app.auxWin1 = [];
            end
                
        end

        % Button pushed function: Report_SaveProject
        function Report_SaveProjectValueChanged(app, event)
            
            if isempty(app.Report_Tree.Children)
                msg = ['Criação/edição de projeto somente é possível quando há ao menos um fluxo de ' ...
                       'dados a processar.'];            
                MessageBox(app, 'warning', msg)

                return
            end
            
            if isempty(app.ProjectFilename.Value{1})
                defaultFilename = fullfile(app.menu_userPath.Value, ['appAnalise_' datestr(now,'yymmdd_THHMMSS')]);
            else
                defaultFilename = app.ProjectFilename.Value{1};
            end
            
            [filename, pathname, index] = uiputfile({'*.mat', 'Projeto appAnálise (*.mat)'}, ...
                                                     'Selecione a pasta e o nome do arquivo', defaultFilename);
            figure(app.UIFigure)
            
            if index
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                d.Message = sprintf('<font style="font-size:12;">Em andamento a criação/edição do projeto "%s".</font>', app.ProjectName.Value);
                
                ProjectRows = find([app.specData.reportFlag]);

                idx = Misc_FindOCCData(app.specData(ProjectRows));
                ProjectRows = sort([ProjectRows, idx]);
                
                % OCC Map (Step 1 of 2)
                OCCLength = numel(idx);
                if OCCLength
                    OCCMap = zeros(OCCLength, 2);
                    
                    jj = 0;
                    for ii = 1:numel(ProjectRows)
                        if ~isempty(app.specData(ProjectRows(ii)).reportOCC.Index)
                            jj=jj+1;
                            OCCMap(jj,:) = [ProjectRows(ii), app.specData(ProjectRows(ii)).reportOCC.Index];
                            app.specData(ProjectRows(ii)).reportOCC.Index = find(ProjectRows == OCCMap(jj,2));
                        end
                    end
                end
                
                reportInfo = ReportGenerator_Aux1(app, 'Report');
                
                % Estrutura que será salva no arquivo.
                prjInfo = struct('Name',          app.ProjectName.Value,           ...
                                 'reportInfo',    rmfield(reportInfo, 'Filename'), ...
                                 'peaksTable',    app.peaksTable,                  ...
                                 'exceptionList', app.exceptionList);
                
                d.Message = sprintf('<font style="font-size:12;">Salvando o projeto appAnálise no arquivo:\n• %s</font>', filename);
                fileWriter.MAT73(fullfile(pathname, filename), app.specData(ProjectRows), prjInfo)
                
                % OCC Map (Step 2 of 2)
                if OCCLength
                    for jj = 1:height(OCCMap)
                        app.specData(OCCMap(jj,1)).reportOCC.Index = OCCMap(jj,2);
                    end
                end
                
                app.ProjectFilename.Value     = fullfile(pathname, filename);
                app.Report_ImageWarn.Visible = 0;
            end
            
        end

        % Button pushed function: report_tool_Button1
        function report_tool_Button1ValueChanged(app, event)
            
            if ~isempty(app.peaksTable)
                if isempty(app.auxWin1)
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                    d.Message = '<font style="font-size:12;">Em andamento...</font>';
                    
                    app.auxWin1 = auxApp.winSignalAnalysis(app);
                    drawnow nocallbacks

                    delete(d)
                else
                    figure(app.auxWin1.UIFigure)
                end
            else
                msg = ['Aplicável apenas quando identificada ao menos uma emissão. Favor alterar os parâmetros do algoritmo de ' ...
                       'detecção de emissões ou, opcionalmente, incluir emissões diretamente no modo "PLAYBACK".'];
                MessageBox(app, 'warning', msg)
            end
            
        end

        % Value changed function: ProjectName
        function Report_ProjectNameValueChanged(app, event)
            
            if isempty(app.ProjectName.Value)
                app.ProjectName.Value = 'Projeto 001';
                
                msg = 'O campo nome do projeto não pode ficar vazio, tendo sido preenchido com "Projeto 001".';
                MessageBox(app, 'warning', msg)
            end
            Report_SaveWarn(app)
            
        end

        % Callback function: Report_ExternalFiles, Report_NewProject_2
        function Report_ExternalFilesMenuSelected(app, event)
            
            if ~isempty(app.Report_Tree.Children)
                switch event.Source
                    case app.Report_NewProject_2;  Type = 'Project';
                    case app.Report_ExternalFiles; Type = 'specData';
                end
    
                auxApp.winAddFiles(app, Type)

            else
                msg = 'O relacionamento de arquivos externos ao projeto somente é possível se existir ao menos um fluxo de dados a processar.';
                MessageBox(app, 'warning', msg)
            end

        end

        % Image clicked function: Report_addButton
        function Report_addButtonPushed(app, event)
            
            if ~isempty(app.playReport_left_Tree.SelectedNodes)
                idx = unique([app.playReport_left_Tree.SelectedNodes.NodeData]);
    
                for ii = idx
                    if ismember(app.specData(ii).MetaData.DataType, class.Constants.occDataTypes)
                        msg = ['É possível a inclusão apenas de fluxo de espectro. O fluxo de ocupação automaticamente ' ...
                               'será incluso na edição do relatório, desde que selecionado no próprio fluxo de espectro.'];            
                        MessageBox(app, 'warning', msg)
                        
                        return
                        
                    elseif ~app.specData(ii).gps.Status
                        msg = 'Necessária a edição de informações de coordenadas geográficas de fluxo(s) de dado(s).';
                        MessageBox(app, 'warning', msg)
                        
                        return
                        
                    elseif app.specData(ii).Samples == 1
                        msg = 'É possível a inclusão de fluxo(s) de dados que possua(m) ao menos duas amostras.';
                        MessageBox(app, 'warning', msg)
                        
                        return
                    end
                end
                
                Report_InfoCreation(app, idx)
                Report_SaveWarn(app)
            end
    
        end

        % Menu selected function: Report_Delete
        function Report_deleteButtonPushed(app, event)
            
            if ~isempty(app.Report_Tree.SelectedNodes)
                idx = app.Report_Tree.SelectedNodes.NodeData;

                for ii = idx
                    app.specData(ii).reportFlag           = 0;
                    app.specData(ii).reportDetection      = [];
                    app.specData(ii).reportClassification = [];
                end

                Report_TreeBuilding(app)
                if ~isempty(app.Report_Tree.Children)
                    app.Report_Tree.SelectedNodes = app.Report_Tree.Children(1).Children(1);
                    Report_TreeSelectionChanged(app)

                else
                    app.playReport_left_Tree.SelectedNodes = app.playReport_left_Tree.Children(1);
                    playReport_left_TreeSelectionChanged(app)
                end

                Report_SaveWarn(app)
            end
            
        end

        % Value changed function: Report_PlaybackOnly
        function Report_Report_PlaybackOnlyValueChanged(app, event)
            
            idx = app.Report_Tree.SelectedNodes.NodeData;

            app.specData(idx).reportDetection.ManualMode = app.Report_PlaybackOnly.Value;
            Report_LayoutPlaybackOnly(app)

            Report_SaveWarn(app)
            
        end

        % Button pushed function: Report_ClassificationButton, 
        % ...and 2 other components
        function Report_ChangeParametersButtonPushed(app, event)
            
            switch event.Source
                case app.Report_occButton;            auxApp.winOCC(app);
                case app.Report_DetectionButton;      auxApp.winDetection(app);
                case app.Report_ClassificationButton; auxApp.winClassification(app);
            end

        end

        % Value changed function: Report_BandLim1, Report_BandLim2
        function Report_BandLimValueChanged(app, event)
            
            idx = app.Report_Tree.SelectedNodes.NodeData;

            switch event.Source
                case app.Report_BandLim1
                    app.specData(idx).reportDetection.Band(1) = app.Report_BandLim1.Value;

                    if app.Report_BandLim1.Value > app.Report_BandLim2.Value
                        app.Report_BandLim2.Value = app.Report_BandLim1.Value;
                        app.specData(idx).reportDetection.Band(2) = app.Report_BandLim2.Value;
                    end

                case app.Report_BandLim2
                    app.specData(idx).reportDetection.Band(2) = app.Report_BandLim2.Value;

                    if app.Report_BandLim2.Value < app.Report_BandLim1.Value
                        app.Report_BandLim1.Value = app.Report_BandLim2.Value;
                        app.specData(idx).reportDetection.Band(1) = app.Report_BandLim1.Value;
                    end
            end
            
            Report_SaveWarn(app)

        end

        % Value changed function: Report_DatePicker1, Report_DatePicker2, 
        % ...and 5 other components
        function Report_SaveWarn(app, event)

            app.Report_ImageWarn.Visible = 1;
            
        end

        % Callback function
        function Info_EditionTypeSelectionChanged(app, event)
            
            app.Info_Label.Text = 'Aspectos gerais';
            
            app.Info_GPS_Grid.Visible = 0;
            app.Info_Toolbar_Button1.Visible = 0;
            app.Info_OCC_Grid.Visible = 0;
            app.Info_Unit_Grid.Visible = 0;            
            
            switch app.Info_EditionType.Value
                case 'LOCAL DA MONITORAÇÃO'
                    app.Info_Grid3.RowHeight(4:6) = {'1x',0,0}; 
                    app.Info_GPS_Grid.Visible = 1;
                    app.Info_Toolbar_Button1.Visible = 1;

                case 'FLUXO DE OCUPAÇÃO'
                    app.Info_Grid3.RowHeight(4:6) = {0,'1x', 0};
                    app.Info_OCC_Grid.Visible = 1;

                case 'UNIDADE DE MEDIDA (dBm/dBµV → dBµV/m)'
                    app.Info_Grid3.RowHeight(4:6) = {0, 0,'1x'};
                    app.Info_Unit_Grid.Visible = 1;

                otherwise
                    app.Info_Grid3.RowHeight(4:6) = {0, 0, 0};
                    app.Info_Label.Text = '';
            end

            Info_Layout(app)

        end

        % Callback function
        function Info_GPS_ModeValueChanged(app, event)

            switch app.Info_GPS_Mode.Value
                case 'Automático'
                    set(app.Info_GPS_Latitude,  'Editable', 0)
                    set(app.Info_GPS_Longitude, 'Editable', 0)
                    set(app.Info_GPS_City,      'Editable', 0)

                    app.Info_GPS_Table.Enable = 'on';
                    set(app.Info_GPS_CheckBox2, 'Enable', 0, 'Value', 0)
                    
                case 'Manual'
                    set(app.Info_GPS_Latitude,  'Editable', 1)
                    set(app.Info_GPS_Longitude, 'Editable', 1)
                    set(app.Info_GPS_City,      'Editable', 1)

                    app.Info_GPS_Table.Enable = 'off';
                    set(app.Info_GPS_CheckBox2, 'Enable', 1, 'Value', 0)
            end

        end

        % Callback function
        function Info_GPS_LatOrLongValueChanged(app, event)
            
            if (app.Info_GPS_Latitude.Value ~= -1) & (app.Info_GPS_Longitude.Value ~= -1)
                gps = struct('Latitude',  app.Info_GPS_Latitude.Value, ...
                             'Longitude', app.Info_GPS_Longitude.Value);
                
                app.Info_GPS_City.Value = geo_FindCity(gps);
            end
            
        end

        % Callback function
        function Info_GPS_CityValueChanged(app, event)
            
            [City, Latitude, Longitude] = geo_FindCoordinates(event.Value);
            
            if ~isempty(City)
                app.Info_GPS_City.Value = City;

                msg = sprintf(['<font style="font-size:12;">Coordenadas geográficas atuais:\n• Latitude: %.6f\n• Longitude: %.6f\n\n' ...
                               'Coordenadas geográficas da sede de <b>%s</b>:\n• Latitude: %.6f\n• Longitude: %.6f\n\n' ...
                               'Deseja atualizar, além da localidade, as informações de Latitude e Longitude?</font>'], app.Info_GPS_Latitude.Value, app.Info_GPS_Longitude.Value, event.Value, Latitude, Longitude);

                selection = uiconfirm(app.UIFigure, msg, 'appAnalise', 'Options', {'Sim', 'Não'}, 'DefaultOption', 1, 'Icon', 'question', 'Interpreter', 'html');
                if selection == "Sim"
                    app.Info_GPS_Latitude.Value  = Latitude;
                    app.Info_GPS_Longitude.Value = Longitude;
                end
                
            else
                msg = sprintf(['Não encontrada em base do IBGE o município "%s". Favor '  ...
                               'corrigir eventual erro na grafia, inserindo os acentos, ' ...
                               'no formato Município/UF'], event.Value);
                MessageBox(app, 'warning', msg)
                
                app.Info_GPS_City.Value = event.PreviousValue;
            end
            
        end

        % Callback function
        function Info_GPS_apiSearch(app, event)
            
            gps = struct('Latitude', app.Info_GPS_Latitude.Value, 'Longitude', app.Info_GPS_Longitude.Value);
            [cityName_API, distValue_km, locInfo] = geo_FindCity(gps, 'API');

            switch locInfo.infoSource
                case 'API';  cityName_IBGE = geo_FindCity(gps, 'IBGE');
                case 'IBGE'; cityName_IBGE = cityName_API; cityName_API = '(não identificada)';
            end

            msg = sprintf(['<b>IBGE</b>\n• Localidade: %s\n• Distância entre local da monitoração e sede da localidade: %.3f km\n\n' ...
                           '<b>API </b>\n• Localidade: %s\n• Estrutura: %s\n'], cityName_IBGE, distValue_km, cityName_API, jsonencode(locInfo));
            MessageBox(app, 'info', msg)

        end

        % Callback function
        function Info_Toolbar_Button1Pushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
            d.Message = '<font style="font-size:12;">Em andamento...</font>';

            idx = app.playReport_left_Tree.SelectedNodes.NodeData;
            
            RelatedGPS = app.specData(idx).RelatedGPS;
            InitialGPS = app.specData(idx).gps;

            gpsTable = table('Size', [0, 4],                                                 ...
                             'VariableTypes', {'double', 'double',   'double',    'double'}, ...
                             'VariableNames', {'Status', 'Latitude', 'Longitude', 'Count'});
            
            for ii = 1:numel(app.specData(idx).RelatedGPS)
                if app.specData(idx).RelatedGPS{ii}.Count > 0
                    app.specData(idx).RelatedGPS{ii}.Status = 1;
                end

                if app.specData(idx).RelatedGPS{ii}.Status
                    gpsTable(end+1,:) = {app.specData(idx).RelatedGPS{ii}.Status,    ...
                                         app.specData(idx).RelatedGPS{ii}.Latitude,  ...
                                         app.specData(idx).RelatedGPS{ii}.Longitude, ...
                                         app.specData(idx).RelatedGPS{ii}.Count};
                end
            end

            if sum(gpsTable.Count)
                app.specData(idx).gps.Status    = max(gpsTable.Status(gpsTable.Status ~= 0));
                app.specData(idx).gps.Latitude  = sum(gpsTable.Latitude  .* gpsTable.Count) / sum(gpsTable.Count);
                app.specData(idx).gps.Longitude = sum(gpsTable.Longitude .* gpsTable.Count) / sum(gpsTable.Count);
                app.specData(idx).gps.Location  = geo_FindCity(struct('Latitude', app.specData(idx).gps.Latitude, 'Longitude', app.specData(idx).gps.Longitude));
            else
                app.specData(idx).gps = struct('Status', 0, 'Latitude', -1, 'Longitude', -1, 'Location', '');
                Info_GPS_ReportCheck(app, idx, RelatedGPS, InitialGPS)
            end
            delete(d)
            
            Info_LayoutChange(app)
            
        end

        % Callback function
        function Info_Toolbar_Button2Pushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
            d.Message = '<font style="font-size:12;">Em andamento...</font>';

            switch app.Info_EditionType.Value
                case 'LOCAL DA MONITORAÇÃO';                  Info_Toolbar_Button2Pushed_GPS(app)
                case 'FLUXO DE OCUPAÇÃO';                     Info_Toolbar_Button2Pushed_OCC(app)
                case 'UNIDADE DE MEDIDA (dBm/dBµV → dBµV/m)'; Info_Toolbar_Button2Pushed_Unit(app)
                case 'MESCLAR';                               Info_Toolbar_Button2Pushed_Merge(app)
                case 'DUPLICAR';                              Info_Toolbar_Button2Pushed_Add(app)
                case 'EXCLUIR';                               Info_Toolbar_Button2Pushed_Delete(app)
            end           
            
            % Ajuste da árvore...
            Info_LayoutChange(app)
            
            delete(d)
            drawnow nocallbacks
            
        end

        % Callback function
        function Info_GPS_TableCellEdit(app, event)
            
            idx = app.playReport_left_Tree.SelectedNodes.NodeData;
            msg = {};

            % VALIDATIONS
            if ~ismember(event.NewData, [-1,0,1])
                msg{end+1} = 'O <i>status</i> pode ter apenas os valores -1 (origem manual), 0 (origem manual ou automática, porém inválida) e 1 (origem automática e válida).';
            elseif (app.specData(idx).RelatedGPS{event.Indices(1)}.Status ==  0) & ~app.specData(idx).RelatedGPS{event.Indices(1)}.Count
                msg{end+1} = 'Esse registro, considerando o seu valor inicial, não aceita alteração do seu <i>status</i>.';
            end

            if ~isempty(msg)
                app.Info_GPS_Table.Data{event.Indices(1),event.Indices(2)} = event.PreviousData;
                MessageBox(app, 'warning', strjoin(msg'))
            end
            
        end

        % Callback function
        function Misc_Button_PlayPushed(app, event)
            
            if app.Export_newAnatelDB.Value    
                if isempty(app.auxWin8)
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                    d.Message = '<font style="font-size:12;">Em andamento...</font>';
    
                    app.auxWin8 = auxApp.winAnatelDB(app);
                    drawnow nocallbacks
    
                    delete(d)
                else
                    figure(app.auxWin8.UIFigure)
                end

            else
                if isempty(app.playReport_left_Tree.SelectedNodes)
                    msg = 'Selecione ao menos um fluxo de dados.';
                    MessageBox(app, 'warning', msg)
                    
                    return
                end
    
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                d.Message = '<font style="font-size:12;">Em andamento...</font>';
                
                if ~isempty(app.playReport_left_Tree.SelectedNodes)
                    idx = unique([app.playReport_left_Tree.SelectedNodes.NodeData]);
    
                    try
                        if app.Export_SaveFile.Value
                            Misc_ExportFile(app, idx)
                        elseif app.Export_MaskDraw.Value
                            Misc_MaskDraw(app, idx)
                        end
        
                        app.Export_CheckBox.Value = 0;
                        Export_CheckBoxValueChanged(app)
        
                    catch ME
                        fprintf('%s\n', jsonencode(ME))
                        MessageBox(app, 'error', getReport(ME))
                    end
                end
    
                delete(d)
            end
            
        end

        % Callback function
        function Export_CheckBoxValueChanged(app, event)
            
            if app.Export_CheckBox.Value
                if ~isempty(app.playReport_left_Tree.SelectedNodes)                    
                    idx = unique([app.playReport_left_Tree.SelectedNodes.NodeData]);
                    
                    Start = [];
                    Stop  = [];
                    for ii = 1:numel(idx)
                        Start = [Start app.specData(idx(ii)).Data{1}(1)];
                        Stop  = [Start app.specData(idx(ii)).Data{1}(end)];
                    end
                    
                    app.Export_DatePicker1.Value = min(Start);
                    app.Export_Spinner1.Value    = 0;
                    app.Export_Spinner2.Value    = 0;

                    app.Export_DatePicker2.Value = max(Stop);
                    app.Export_Spinner3.Value    = 23;
                    app.Export_Spinner4.Value    = 59;
                    
                    set(app.Export_Grid3.Children, 'Enable', 1)

                else
                    app.Export_CheckBox.Value = 0;
                    set(app.Export_Grid3.Children, 'Enable', 0)                    
                end
            else
                set(app.Export_Grid3.Children, 'Enable', 0)
            end
            
        end

        % Callback function
        function Misc_ButtonGroupSelectionChanged(app, event)
            
            if app.Export_SaveFile.Value
                set(app.Misc_Button_Play, 'Text', 'Exportar', 'Icon', 'LT_save.png')
                set(app.Export_CheckBox,  'Enable', 1)

            elseif app.Export_MaskDraw.Value
                set(app.Misc_Button_Play, 'Text', 'Máscara', 'Icon', 'LT_mask.png')
                set(app.Export_CheckBox,  'Enable', 0, 'Value', 0)

            elseif app.Export_newAnatelDB.Value
                set(app.Misc_Button_Play, 'Text', 'anateldb', 'Icon', 'LT_mosaic.png')
                set(app.Export_CheckBox,  'Enable', 0, 'Value', 0)
            end

            Export_CheckBoxValueChanged(app)

        end

        % Callback function: not associated with a component
        function playReport_left_TabLockImageClicked(app, event)
            
            if app.playReport_left_Tree.Enable
                app.playReport_left_Tree.Enable = 0;
                app.playReport_left_TabLock.ImageSource = 'play_lockClose.png';
            else
                app.playReport_left_Tree.Enable = 1;
                app.playReport_left_TabLock.ImageSource = 'play_lockOpen.png';
            end

        end

        % Menu selected function: play_ContextMenu2_Drive
        function play_ContextMenu2_DriveSelected(app, event)
            
            % VALIDAÇÕES
            idx1 = app.playReport_left_Tree.SelectedNodes.NodeData;

            if ~contains(app.specData(idx1).FileFormat, 'RFlookBin')
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas arquivos gerados pelo appColeta.';
                    MessageBox(app, 'warning', msg)                    
                    return

            elseif sum(cellfun(@(x) x.Count, app.specData(idx1).RelatedGPS)) <= 1
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas fluxo de espectro que possua registrado as coordenadas geográficas de ao menos dois locais de monitoração.';
                    MessageBox(app, 'warning', msg)                    
                    return

            elseif sum(cellfun(@(x) x.Count, app.specData(idx1).RelatedGPS)) ~= app.specData(idx1).Samples
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas fluxo de espectro cujo número de coordenadas geográficas registrado seja igual ao número de varreduras (relação um para um).';
                    MessageBox(app, 'warning', msg)                    
                    return
            end
            
            Tag = sprintf('%s & ThreadID %.0f: %.3f - %.3f MHz & idx=%.0f', app.specData(idx1).Node, ...
                                                                            app.specData(idx1).ThreadID, ...
                                                                            app.specData(idx1).MetaData.FreqStart / 1e+6, ...
                                                                            app.specData(idx1).MetaData.FreqStop  / 1e+6, ...
                                                                            idx1);

            h = findall(groot, 'Type', 'figure', 'Tag', Tag);
            h(~isvalid(h)) = [];

            if isempty(h)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                d.Message = '<font style="font-size:12;">Em andamento...</font>';

                idx2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;

                app.auxWin2 = auxApp.winDriveTest(app, idx1, idx2, Tag);
                drawnow nocallbacks

                delete(d)
            else
                figure(h(1))
            end

        end

        % Callback function: play_Persistance_Colormap, 
        % ...and 4 other components
        function play_Persistance_Callbacks(app, event)
            
            if app.traceInfo.Persistance
                switch event.Source
                    case app.play_Persistance_Interpolation
                        app.obj_Persistance.handle.Interpolation = app.play_Persistance_Interpolation.Value;
    
                    case app.play_Persistance_Samples
                        delete(app.obj_Persistance.handle)
                        Plot_StartUp2_Persistance(app)

                    case app.play_Persistance_Transparency
                        app.obj_Persistance.handle.CData(isnan(app.obj_Persistance.handle.CData)) = 0; 
                        app.obj_Persistance.handle.AlphaData = double(logical(app.obj_Persistance.handle.CData)) * app.play_Persistance_Transparency.Value;

                    case app.play_Persistance_Colormap
                        startup_Colormap_axes1(app)

                    case app.play_Persistance_cLim_Mode
                        app.axes1.CLimMode = 'auto';
                        app.play_Persistance_cLim1.Value = app.axes1.CLim(1);
                        app.play_Persistance_cLim2.Value = app.axes1.CLim(2);
                end                
                drawnow nocallbacks
            end
            
        end

        % Value changed function: play_Persistance_cLim1, 
        % ...and 1 other component
        function Playback_xLim1_4ValueChanged(app, event)

            LevelRange = app.play_Persistance_cLim2.Value - app.play_Persistance_cLim1.Value;
            if LevelRange > 0
                app.axes1.CLim = [app.play_Persistance_cLim1.Value, app.play_Persistance_cLim2.Value];
            else
                switch event.Source
                    case app.play_Persistance_cLim1; app.play_Persistance_cLim1.Value = event.PreviousValue;
                    case app.play_Persistance_cLim2; app.play_Persistance_cLim2.Value = event.PreviousValue;
                end
            end

        end

        % Value changed function: play_Waterfall_cLim1, 
        % ...and 1 other component
        function play_Waterfall_cLim1ValueChanged(app, event)

            LevelRange = app.play_Waterfall_cLim2.Value - app.play_Waterfall_cLim1.Value;
            if LevelRange > 0
                app.axes3.CLim = [app.play_Waterfall_cLim1.Value, app.play_Waterfall_cLim2.Value];
            else
                switch event.Source
                    case app.play_Waterfall_cLim1; app.play_Waterfall_cLim1.Value = event.PreviousValue;
                    case app.play_Waterfall_cLim2; app.play_Waterfall_cLim2.Value = event.PreviousValue;
                end
            end
            
        end

        % Value changed function: play_ClearWriteVisibility
        function play_ClearWriteVisibilityValueChanged(app, event)
            
            try
                set(app.line_ClearWrite, 'Visible', app.play_ClearWriteVisibility.Value)
            catch
            end

        end

        % Image clicked function: play_right_Tab1Image, 
        % ...and 5 other components
        function Tab2_Image_2Clicked(app, event)
            
            switch event.Source
                case app.play_right_Tab1Image;   app.playReport_rightGrid.RowHeight([2 4 6 8 10 12]) = {'1x' 0 0 0 0 0};
                case app.play_right_Tab2Image;   app.playReport_rightGrid.RowHeight([2 4 6 8 10 12]) = {0 '1x' 0 0 0 0};
                case app.play_right_Tab3Image;   app.playReport_rightGrid.RowHeight([2 4 6 8 10 12]) = {0 0 '1x' 0 0 0};
                case app.play_right_Tab4Image;   app.playReport_rightGrid.RowHeight([2 4 6 8 10 12]) = {0 0 0 '1x' 0 0};
                case app.report_right_Tab1Image; app.playReport_rightGrid.RowHeight([2 4 6 8 10 12]) = {0 0 0 0 '1x' 0};
                case app.report_right_Tab2Image; app.playReport_rightGrid.RowHeight([2 4 6 8 10 12]) = {0 0 0 0 0 '1x'};
            end

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)

            if app.Button.Value
                app.file_docGrid.RowHeight(end-1:end) = {22, '0.5x'};
            else
                app.file_docGrid.RowHeight(end-1:end) = {0, 0};
            end

            % APLICA FILTRO

        end

        % Image clicked function: file_left_TabImage_2, 
        % ...and 1 other component
        function file_left_TabImage_2Clicked(app, event)
            
            switch event.Source
                case app.file_left_TabImage_2; app.GridLayout42.RowHeight([2 4]) = {'1x', 0};
                case app.file_left_TabImage_3; app.GridLayout42.RowHeight([2 4]) = {0, '1x'};
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise R2022c';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout.Tooltip = {''};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_Home = uitab(app.TabGroup);
            app.Tab1_Home.Title = 'HOME';
            app.home_Grid = uigridlayout(app.Tab1_Home);
            app.home_Button1 = ccTools.Button(app.home_Grid);
            app.home_Button1.Text = 'Leitura de|arquivos';
            app.home_Button1.Description = '<i>Playback</i>, detecção e classificação de emissões, aferição da<br>ocupação por <i>bin</i>, geração de relatório e máscara espectral  ';
            app.home_Button1.Icon = 'LT_OpenFile.png';
            app.home_Button1.VerticalAlign = 'top';
            app.home_Button1.RowTextSpacing = 14;
            app.home_Button1.IconWidth = 36;
            app.home_Button1.IconHeight = 36;
            app.home_Button1.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button2 = ccTools.Button(app.home_Grid);
            app.home_Button2.Text = 'Modelos de|relatórios';
            app.home_Button2.Description = 'Criação e edição de<br>modelos de relatórios';
            app.home_Button2.Icon = 'LR_Template.png';
            app.home_Button2.VerticalAlign = 'top';
            app.home_Button2.RowTextSpacing = 14;
            app.home_Button2.IconWidth = 36;
            app.home_Button2.IconHeight = 36;
            app.home_Button2.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button3 = ccTools.Button(app.home_Grid);
            app.home_Button3.Text = 'API|Fiscaliza';
            app.home_Button3.Description = 'Relato de inspeções,<br><i>upload</i> de relatório';
            app.home_Button3.Icon = 'LR_redmine.png';
            app.home_Button3.VerticalAlign = 'top';
            app.home_Button3.RowTextSpacing = 138;
            app.home_Button3.IconWidth = 36;
            app.home_Button3.IconHeight = 36;
            app.home_Button3.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button4 = ccTools.Button(app.home_Grid);
            app.home_Button4.Text = 'API|anateldb';
            app.home_Button4.Description = 'Consulta à base de dados com registros de estações<br>(Mosaico, STEL, SRD, AISWEB, GEOAIS e ICAO)';
            app.home_Button4.Icon = 'LR_mosaic.png';
            app.home_Button4.VerticalAlign = 'top';
            app.home_Button4.RowTextSpacing = 14;
            app.home_Button4.IconWidth = 36;
            app.home_Button4.IconHeight = 36;
            app.home_Button4.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button5 = ccTools.Button(app.home_Grid);
            app.home_Button5.Text = 'Configurações|gerais';
            app.home_Button5.Description = 'Atualização de componentes,<br>customizações de <i>plots</i> etc';
            app.home_Button5.Icon = 'LT_Dots.png';
            app.home_Button5.VerticalAlign = 'top';
            app.home_Button5.RowTextSpacing = 14;
            app.home_Button5.IconWidth = 36;
            app.home_Button5.IconHeight = 36;
            app.home_Button5.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.Tab2_File = uitab(app.TabGroup);
            app.Tab2_File.Title = 'FILE';
            app.file_Grid = uigridlayout(app.Tab2_File);
            app.file_toolGrid = uigridlayout(app.file_Grid);
            app.file_tool_SpecReadButton = uibutton(app.file_toolGrid, 'push');
            app.file_tool_SpecReadButton.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_playback2.png');
            app.file_tool_SpecReadButton.Enable = 'off';
            app.file_tool_SpecReadButton.Text = 'Leitura espectro';
            app.file_tool_RepositoryButton_3 = uibutton(app.file_toolGrid, 'push');
            app.file_tool_RepositoryButton_3.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_redX.png');
            app.file_tool_RepositoryButton_3.Tooltip = {'Excluir arquivo ou fluxo'};
            app.file_tool_RepositoryButton_3.Text = '';
            app.play_tool_Separator1_2 = uiimage(app.file_toolGrid);
            app.play_tool_Separator1_2.Enable = 'off';
            app.play_tool_Separator1_2.ImageSource = 'LT_LineV.png';
            app.Button = uibutton(app.file_toolGrid, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Tooltip = {'Aplicar filtro'};
            app.Button.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Filter.png');
            app.Button.Text = '';
            app.file_tool_RepositoryButton_4 = uibutton(app.file_toolGrid, 'push');
            app.file_tool_RepositoryButton_4.Icon = fullfile(pathToMLAPP, 'Icons', 'Refresh_24.png');
            app.file_tool_RepositoryButton_4.Enable = 'off';
            app.file_tool_RepositoryButton_4.Tooltip = {'Reler arquivo'};
            app.file_tool_RepositoryButton_4.Text = '';
            app.file_docGrid = uigridlayout(app.file_Grid);
            app.file_left_Tree = uitree(app.file_docGrid);
            app.file_left_Tree.Multiselect = 'on';
            app.file_left_Tree.SelectionChangedFcn = createCallbackFcn(app, @file_left_TreeSelectionChanged, true);
            app.appColeta_211202_T080348_ThreadID1_1binNode = uitreenode(app.file_left_Tree);
            app.appColeta_211202_T080348_ThreadID1_1binNode.Text = 'appColeta_211202_T080348_ThreadID1_1.bin';
            app.ROHDESCHWARZEB500101512002V04904072871000Node_2 = uitreenode(app.appColeta_211202_T080348_ThreadID1_1binNode);
            app.ROHDESCHWARZEB500101512002V04904072871000Node_2.Icon = fullfile(pathToMLAPP, 'Icons', 'Instr_R&S.png');
            app.ROHDESCHWARZEB500101512002V04904072871000Node_2.Text = 'ROHDE&SCHWARZ,EB500,101.512/002,V04.90-4072.8710.00';
            app.file_left_TreeLabel = uilabel(app.file_docGrid);
            app.file_left_TreeLabel.Text = 'Lista de arquivos';
            app.file_left_TreeLabel_2 = uilabel(app.file_docGrid);
            app.file_left_TreeLabel_2.Text = 'Origem';
            app.Panel_42 = uipanel(app.file_docGrid);
            app.GridLayout41 = uigridlayout(app.Panel_42);
            app.file_tool_OpenFileButton = uibutton(app.GridLayout41, 'push');
            app.file_tool_OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @file_tool_OpenFileButtonPushed, true);
            app.file_tool_OpenFileButton.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_OpenFile2.png');
            app.file_tool_OpenFileButton.Text = '';
            app.file_tool_RepositoryButton = uibutton(app.GridLayout41, 'push');
            app.file_tool_RepositoryButton.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_database2.png');
            app.file_tool_RepositoryButton.Text = '';
            app.LocalLabel = uilabel(app.GridLayout41);
            app.LocalLabel.Text = 'Local';
            app.repoSFILabel = uilabel(app.GridLayout41);
            app.repoSFILabel.Text = 'repoSFI';
            app.file_left_TabGrid = uigridlayout(app.file_docGrid);
            app.file_left_TabGrid.Tag = 'COLORLOCKED';
            app.file_left_TabTitle = uilabel(app.file_left_TabGrid);
            app.file_left_TabTitle.Text = 'ARQUIVOS';
            app.file_left_TabImage = uiimage(app.file_left_TabGrid);
            app.file_left_TabImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_stats.png');
            app.playReport_left_MetadataGrid_2 = uigridlayout(app.file_docGrid);
            app.playReport_left_DownArrow_4 = uiimage(app.playReport_left_MetadataGrid_2);
            app.playReport_left_DownArrow_4.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_upArrow_Zoom.png');
            app.playReport_left_DownArrow_5 = uiimage(app.playReport_left_MetadataGrid_2);
            app.playReport_left_DownArrow_5.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_downArrow_Zoom.png');
            app.GridLayout42 = uigridlayout(app.file_Grid);
            app.file_left_TabGrid_3 = uigridlayout(app.GridLayout42);
            app.file_left_TabGrid_3.Tag = 'COLORLOCKED';
            app.file_left_TabTitle_3 = uilabel(app.file_left_TabGrid_3);
            app.file_left_TabTitle_3.Text = 'FILTROS';
            app.file_left_TabImage_3 = uiimage(app.file_left_TabGrid_3);
            app.file_left_TabImage_3.ImageClickedFcn = createCallbackFcn(app, @file_left_TabImage_2Clicked, true);
            app.file_left_TabImage_3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_stats.png');
            app.file_left_TabGrid_2 = uigridlayout(app.GridLayout42);
            app.file_left_TabGrid_2.Tag = 'COLORLOCKED';
            app.file_left_TabTitle_2 = uilabel(app.file_left_TabGrid_2);
            app.file_left_TabTitle_2.Text = 'METADADOS';
            app.file_left_TabImage_2 = uiimage(app.file_left_TabGrid_2);
            app.file_left_TabImage_2.ImageClickedFcn = createCallbackFcn(app, @file_left_TabImage_2Clicked, true);
            app.file_left_TabImage_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_stats.png');
            app.GridLayout43 = uigridlayout(app.GridLayout42);
            app.file_doc_MetadataLabel = uilabel(app.GridLayout43);
            app.file_doc_MetadataLabel.Text = 'Metadados';
            app.file_doc_Metadata = uitextarea(app.GridLayout43);
            app.file_doc_Metadata.Editable = 'off';
            app.file_doc_Metadata.Value = {'ARQUIVO'; '- Formato: RFlookBin v.2/1'; '- Tamanho: 1234343 MB'; '- ...'; '- Tipo de arquivo: SpectralData (ou ProjectData)'; '- Arquivos relacionados: (relação, caso seja projectData - se for SpecData, omitir essa informação...)'; ''; ''; 'TAREFA:'; '- Nome: ...'; '- Observação: 02/02/2022 08:00:00 - 12/02/2023 08:00:00'; ''; 'RECEPTOR:'; '- Frequência inicial: 88.000 MHz'; '- Frequência final: 108.000 MHz'; '- Passo da varredura: 25.000 kHz'; '- Resolução: 30.000 kHz'; '- VBW: 100 Hz'; '- ...'; ''; 'ANTENA:'; '- ...'};
            app.GridLayout44 = uigridlayout(app.GridLayout42);
            app.file_doc_MetadataLabel_2 = uilabel(app.GridLayout44);
            app.file_doc_MetadataLabel_2.Text = 'Filtros';
            app.Tree = uitree(app.GridLayout44);
            app.IDNode = uitreenode(app.Tree);
            app.IDNode.Text = 'ID';
            app.Node = uitreenode(app.IDNode);
            app.Node.Text = '10';
            app.Node_2 = uitreenode(app.IDNode);
            app.Node_2.Text = '300';
            app.DESCRIONode = uitreenode(app.Tree);
            app.DESCRIONode.Text = 'DESCRIÇÃO';
            app.Faixa1de10PMEC2022Node = uitreenode(app.DESCRIONode);
            app.Faixa1de10PMEC2022Node.Text = '"Faixa 1 de 10 - PMEC 2022"';
            app.Faixa2de10PMEC2022Node = uitreenode(app.DESCRIONode);
            app.Faixa2de10PMEC2022Node.Text = '"Faixa 2 de 10 - PMEC 2022"';
            app.FREQUNCIANode = uitreenode(app.Tree);
            app.FREQUNCIANode.Text = 'FREQUÊNCIA';
            app.Tab3_playReport = uitab(app.TabGroup);
            app.Tab3_playReport.Title = 'PLAYBACK';
            app.playReport_Grid = uigridlayout(app.Tab3_playReport);
            app.playReport_leftGrid = uigridlayout(app.playReport_Grid);
            app.playReport_left_TabGrid = uigridlayout(app.playReport_leftGrid);
            app.playReport_left_TabGrid.Tag = 'COLORLOCKED';
            app.playReport_left_TabTitle = uilabel(app.playReport_left_TabGrid);
            app.playReport_left_TabTitle.Text = 'DADOS';
            app.playReport_left_TabImage = uiimage(app.playReport_left_TabGrid);
            app.playReport_left_TabImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Scatter.png');
            app.playReport_left_Tree = uitree(app.playReport_leftGrid);
            app.playReport_left_Tree.Multiselect = 'on';
            app.playReport_left_Tree.SelectionChangedFcn = createCallbackFcn(app, @playReport_left_TreeSelectionChanged, true);
            app.appColeta_230818_T151504_ID4_1binNode = uitreenode(app.playReport_left_Tree);
            app.appColeta_230818_T151504_ID4_1binNode.Text = 'appColeta_230818_T151504_ID4_1.bin';
            app.RohdeSchwarzFSVR3013110006K30101119163Node = uitreenode(app.playReport_left_Tree);
            app.RohdeSchwarzFSVR3013110006K30101119163Node.Icon = fullfile(pathToMLAPP, 'Icons', 'Instr_R&S.png');
            app.RohdeSchwarzFSVR3013110006K30101119163Node.Text = 'Rohde&Schwarz,FSVR-30,1311.0006K30/101119,1.63';
            app.TEKTRONIXSA2500PCB0000007050Node = uitreenode(app.playReport_left_Tree);
            app.TEKTRONIXSA2500PCB0000007050Node.Icon = fullfile(pathToMLAPP, 'Icons', 'Instr_Tektronix.png');
            app.TEKTRONIXSA2500PCB0000007050Node.Text = 'TEKTRONIX,SA2500PC,B000000,7.050';
            app.KeysightTechnologiesN9344CCN06041424A0627Node = uitreenode(app.playReport_left_Tree);
            app.KeysightTechnologiesN9344CCN06041424A0627Node.Icon = fullfile(pathToMLAPP, 'Icons', 'Instr_KeySight.png');
            app.KeysightTechnologiesN9344CCN06041424A0627Node.Text = 'Keysight Technologies,N9344C,CN06041424,A.06.27';
            app.RohdeSchwarzEB500101512002V04904072871000Node = uitreenode(app.playReport_left_Tree);
            app.RohdeSchwarzEB500101512002V04904072871000Node.Icon = fullfile(pathToMLAPP, 'Icons', 'Instr_R&S.png');
            app.RohdeSchwarzEB500101512002V04904072871000Node.Text = 'Rohde&Schwarz,EB500,101.512/002,V04.90-4072.8710.00';
            app.playReport_left_Metadata = uitextarea(app.playReport_leftGrid);
            app.playReport_left_Metadata.Editable = 'off';
            app.playReport_left_MetadataGrid = uigridlayout(app.playReport_leftGrid);
            app.playReport_left_MetadataLabel = uilabel(app.playReport_left_MetadataGrid);
            app.playReport_left_MetadataLabel.Text = 'Fluxos espectrais';
            app.playReport_left_DownArrow_3 = uiimage(app.playReport_left_MetadataGrid);
            app.playReport_left_DownArrow_3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_upArrow_Zoom.png');
            app.playReport_left_DownArrow = uiimage(app.playReport_left_MetadataGrid);
            app.playReport_left_DownArrow.ImageClickedFcn = createCallbackFcn(app, @playReport_left_DownArrowCollapseTreeNodes, true);
            app.playReport_left_DownArrow.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_downArrow_Zoom.png');
            app.playReport_left_TreeLabel = uilabel(app.playReport_leftGrid);
            app.playReport_left_TreeLabel.Text = 'Metadados';
            app.playReport_rightGrid = uigridlayout(app.playReport_Grid);
            app.play_right_Tab1Grid = uigridlayout(app.playReport_rightGrid);
            app.play_right_Tab1Grid.Tag = 'COLORLOCKED';
            app.play_right_Tab1Label = uilabel(app.play_right_Tab1Grid);
            app.play_right_Tab1Label.Text = 'ASPECTOS GERAIS';
            app.play_right_Tab1Image = uiimage(app.play_right_Tab1Grid);
            app.play_right_Tab1Image.ImageClickedFcn = createCallbackFcn(app, @Tab2_Image_2Clicked, true);
            app.play_right_Tab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Dots2.png');
            app.play_right_Tab1Info = uigridlayout(app.playReport_rightGrid);
            app.play_right_MainControlLabel = uilabel(app.play_right_Tab1Info);
            app.play_right_MainControlLabel.Text = 'Playback';
            app.play_right_PanelA = uipanel(app.play_right_Tab1Info);
            app.play_right_GridA = uigridlayout(app.play_right_PanelA);
            app.play_right_Grid = uigridlayout(app.play_right_GridA);
            app.play_right_Play = uibutton(app.play_right_Grid, 'push');
            app.play_right_Play.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_play.png');
            app.play_right_Play.Text = '';
            app.play_right_Loop = uibutton(app.play_right_Grid, 'push');
            app.play_right_Loop.Icon = fullfile(pathToMLAPP, 'Icons', 'LR_playback_loop.png');
            app.play_right_Loop.Text = '';
            app.play_right_Slider = uislider(app.play_right_GridA);
            app.play_right_Slider.MajorTicks = [0 25 50 75 100];
            app.play_right_Slider.MajorTickLabels = {'0', '', '', '', '1'};
            app.play_right_Slider.ValueChangingFcn = createCallbackFcn(app, @play_right_SliderValueChanging, true);
            app.play_right_Slider.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.play_right_Slider.Enable = 'off';
            app.play_LimitsLabel_2 = uilabel(app.play_right_Tab1Info);
            app.play_LimitsLabel_2.Text = 'Controles';
            app.Panel_33 = uipanel(app.play_right_Tab1Info);
            app.play_ControlsGrid = uigridlayout(app.Panel_33);
            app.play_MinHoldLabel = uilabel(app.play_ControlsGrid);
            app.play_MinHoldLabel.Text = 'MinHold';
            app.play_AverageLabel = uilabel(app.play_ControlsGrid);
            app.play_AverageLabel.Text = 'Média';
            app.play_MaxHoldLabel = uilabel(app.play_ControlsGrid);
            app.play_MaxHoldLabel.Text = 'MaxHold';
            app.play_PersistanceLabel = uilabel(app.play_ControlsGrid);
            app.play_PersistanceLabel.Text = 'Persistência';
            app.play_OccupancyLabel = uilabel(app.play_ControlsGrid);
            app.play_OccupancyLabel.Text = 'Ocupação';
            app.play_WaterfallLabel = uilabel(app.play_ControlsGrid);
            app.play_WaterfallLabel.Text = 'Waterfall';
            app.play_MinHold = uibutton(app.play_ControlsGrid, 'state');
            app.play_MinHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MinHold.png');
            app.play_MinHold.Text = '';
            app.play_Average = uibutton(app.play_ControlsGrid, 'state');
            app.play_Average.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Average.png');
            app.play_Average.Text = '';
            app.play_MaxHold = uibutton(app.play_ControlsGrid, 'state');
            app.play_MaxHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MaxHold.png');
            app.play_MaxHold.Text = '';
            app.play_Persistance = uibutton(app.play_ControlsGrid, 'state');
            app.play_Persistance.Icon = fullfile(pathToMLAPP, 'Icons', 'LR_Persistance.png');
            app.play_Persistance.Text = '';
            app.play_Occupancy = uibutton(app.play_ControlsGrid, 'state');
            app.play_Occupancy.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_OCC.png');
            app.play_Occupancy.Text = '';
            app.play_Waterfall = uibutton(app.play_ControlsGrid, 'state');
            app.play_Waterfall.Icon = fullfile(pathToMLAPP, 'Icons', 'LR_waterfall.png');
            app.play_Waterfall.Text = '';
            app.play_LimitsLabel_3 = uilabel(app.play_right_Tab1Info);
            app.play_LimitsLabel_3.Text = 'Limites';
            app.play_Limits_Panel = uipanel(app.play_right_Tab1Info);
            app.play_Limits_Grid = uigridlayout(app.play_Limits_Panel);
            app.play_Limits_xLimLabel = uilabel(app.play_Limits_Grid);
            app.play_Limits_xLimLabel.Enable = 'off';
            app.play_Limits_xLimLabel.Text = 'Frequência';
            app.play_Limits_xLim1 = uispinner(app.play_Limits_Grid);
            app.play_Limits_xLim1.ValueDisplayFormat = '%.3f';
            app.play_Limits_xLim1.ValueChangedFcn = createCallbackFcn(app, @Playback_xLimitsValueChanged, true);
            app.play_Limits_xLim1.Tag = 'FreqStart';
            app.play_Limits_xLim1.Enable = 'off';
            app.play_Limits_xLim1.Tooltip = {''};
            app.play_Limits_xLim2 = uispinner(app.play_Limits_Grid);
            app.play_Limits_xLim2.ValueDisplayFormat = '%.3f';
            app.play_Limits_xLim2.ValueChangedFcn = createCallbackFcn(app, @Playback_xLimitsValueChanged, true);
            app.play_Limits_xLim2.Tag = 'FreqStop';
            app.play_Limits_xLim2.Enable = 'off';
            app.play_Limits_xLim2.Tooltip = {''};
            app.play_Limits_yLimLabel = uilabel(app.play_Limits_Grid);
            app.play_Limits_yLimLabel.Enable = 'off';
            app.play_Limits_yLimLabel.Text = 'Nível';
            app.play_Limits_yLim1 = uispinner(app.play_Limits_Grid);
            app.play_Limits_yLim1.Step = 5;
            app.play_Limits_yLim1.ValueDisplayFormat = '%.1f';
            app.play_Limits_yLim1.ValueChangedFcn = createCallbackFcn(app, @Playback_yLimitsValueChanged, true);
            app.play_Limits_yLim1.Tag = 'MinLevel';
            app.play_Limits_yLim1.Enable = 'off';
            app.play_Limits_yLim1.Tooltip = {''};
            app.play_Limits_yLim2 = uispinner(app.play_Limits_Grid);
            app.play_Limits_yLim2.Step = 5;
            app.play_Limits_yLim2.ValueDisplayFormat = '%.1f';
            app.play_Limits_yLim2.ValueChangedFcn = createCallbackFcn(app, @Playback_yLimitsValueChanged, true);
            app.play_Limits_yLim2.Tag = 'MaxLevel';
            app.play_Limits_yLim2.Enable = 'off';
            app.play_Limits_yLim2.Tooltip = {''};
            app.play_LimitsLabel_4 = uilabel(app.play_right_Tab1Info);
            app.play_LimitsLabel_4.Text = 'Outros aspectos';
            app.Panel_34 = uipanel(app.play_right_Tab1Info);
            app.GridLayout40 = uigridlayout(app.Panel_34);
            app.play_ClearWriteVisibilityLabel = uilabel(app.GridLayout40);
            app.play_ClearWriteVisibilityLabel.Text = 'Visibilidade da curva ClearWrite:';
            app.play_ClearWriteVisibility = uidropdown(app.GridLayout40);
            app.play_ClearWriteVisibility.Items = {'on', 'off'};
            app.play_ClearWriteVisibility.ValueChangedFcn = createCallbackFcn(app, @play_ClearWriteVisibilityValueChanged, true);
            app.play_ClearWriteVisibility.Value = 'on';
            app.play_MinPlotTimeLabel = uilabel(app.GridLayout40);
            app.play_MinPlotTimeLabel.Text = 'Tempo mínimo de escrita (ms):';
            app.play_MinPlotTime = uispinner(app.GridLayout40);
            app.play_MinPlotTime.Step = 10;
            app.play_MinPlotTime.Limits = [10 1000];
            app.play_MinPlotTime.RoundFractionalValues = 'on';
            app.play_MinPlotTime.ValueDisplayFormat = '%.0f';
            app.play_MinPlotTime.Value = 10;
            app.play_right_Tab2Grid = uigridlayout(app.playReport_rightGrid);
            app.play_right_Tab2Grid.Tag = 'COLORLOCKED';
            app.play_right_Tab2Label = uilabel(app.play_right_Tab2Grid);
            app.play_right_Tab2Label.Text = 'PLOT';
            app.play_right_Tab2Image = uiimage(app.play_right_Tab2Grid);
            app.play_right_Tab2Image.ImageClickedFcn = createCallbackFcn(app, @Tab2_Image_2Clicked, true);
            app.play_right_Tab2Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_playback.png');
            app.play_right_Tab2Info = uigridlayout(app.playReport_rightGrid);
            app.play_Persistance_Label = uilabel(app.play_right_Tab2Info);
            app.play_Persistance_Label.Text = 'Persistência';
            app.play_Persistance_Panel = uipanel(app.play_right_Tab2Info);
            app.play_Persistance_Grid = uigridlayout(app.play_Persistance_Panel);
            app.play_Persistance_InterpolationLabel = uilabel(app.play_Persistance_Grid);
            app.play_Persistance_InterpolationLabel.Enable = 'off';
            app.play_Persistance_InterpolationLabel.Text = 'Interpolação:';
            app.play_Persistance_Interpolation = uidropdown(app.play_Persistance_Grid);
            app.play_Persistance_Interpolation.Items = {'nearest', 'bilinear'};
            app.play_Persistance_Interpolation.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Interpolation.Enable = 'off';
            app.play_Persistance_Interpolation.Value = 'nearest';
            app.play_Persistance_SamplesLabel = uilabel(app.play_Persistance_Grid);
            app.play_Persistance_SamplesLabel.Enable = 'off';
            app.play_Persistance_SamplesLabel.Text = 'Janela:';
            app.play_Persistance_Samples = uidropdown(app.play_Persistance_Grid);
            app.play_Persistance_Samples.Items = {'128', '256', '512', 'full'};
            app.play_Persistance_Samples.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Samples.Enable = 'off';
            app.play_Persistance_Samples.Value = 'full';
            app.play_Persistance_TransparencyLabel = uilabel(app.play_Persistance_Grid);
            app.play_Persistance_TransparencyLabel.Enable = 'off';
            app.play_Persistance_TransparencyLabel.Text = 'Transparência:';
            app.play_Persistance_Transparency = uispinner(app.play_Persistance_Grid);
            app.play_Persistance_Transparency.Step = 0.05;
            app.play_Persistance_Transparency.Limits = [0.2 1];
            app.play_Persistance_Transparency.ValueDisplayFormat = '%.2f';
            app.play_Persistance_Transparency.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Transparency.Enable = 'off';
            app.play_Persistance_Transparency.Value = 1;
            app.play_Persistance_cLim_Label = uilabel(app.play_Persistance_Grid);
            app.play_Persistance_cLim_Label.Enable = 'off';
            app.play_Persistance_cLim_Label.Text = 'Limites (%):';
            app.play_Persistance_cLim_Grid1 = uigridlayout(app.play_Persistance_Grid);
            app.play_Persistance_cLim_Mode = uiimage(app.play_Persistance_cLim_Grid1);
            app.play_Persistance_cLim_Mode.ImageClickedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_cLim_Mode.Enable = 'off';
            app.play_Persistance_cLim_Mode.ImageSource = 'LT_refresh.png';
            app.play_Persistance_cLim_Grid2 = uigridlayout(app.play_Persistance_Grid);
            app.play_Persistance_cLim1 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim1.Step = 0.1;
            app.play_Persistance_cLim1.Limits = [0 Inf];
            app.play_Persistance_cLim1.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim1.ValueChangedFcn = createCallbackFcn(app, @Playback_xLim1_4ValueChanged, true);
            app.play_Persistance_cLim1.Enable = 'off';
            app.play_Persistance_cLim1.Tooltip = {''};
            app.play_Persistance_cLim2 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim2.Limits = [0 Inf];
            app.play_Persistance_cLim2.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim2.ValueChangedFcn = createCallbackFcn(app, @Playback_xLim1_4ValueChanged, true);
            app.play_Persistance_cLim2.Enable = 'off';
            app.play_Persistance_cLim2.Tooltip = {''};
            app.play_Persistance_cLim_Separation = uilabel(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim_Separation.Enable = 'off';
            app.play_Persistance_cLim_Separation.Text = '-';
            app.play_Persistance_ColormapLabel = uilabel(app.play_Persistance_Grid);
            app.play_Persistance_ColormapLabel.Enable = 'off';
            app.play_Persistance_ColormapLabel.Text = 'Mapa de cor:';
            app.play_Persistance_Colormap = uidropdown(app.play_Persistance_Grid);
            app.play_Persistance_Colormap.Items = {'winter', 'parula', 'turbo'};
            app.play_Persistance_Colormap.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Colormap.Enable = 'off';
            app.play_Persistance_Colormap.Value = 'winter';
            app.play_Waterfall_Label = uilabel(app.play_right_Tab2Info);
            app.play_Waterfall_Label.Text = 'Waterfall';
            app.play_Waterfall_Panel = uipanel(app.play_right_Tab2Info);
            app.GridLayout15 = uigridlayout(app.play_Waterfall_Panel);
            app.play_Waterfall_Decimation_Label = uilabel(app.GridLayout15);
            app.play_Waterfall_Decimation_Label.Enable = 'off';
            app.play_Waterfall_Decimation_Label.Text = 'Decimação:';
            app.play_Waterfall_Decimation = uidropdown(app.GridLayout15);
            app.play_Waterfall_Decimation.Items = {'auto', '1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.play_Waterfall_Decimation.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_DecimationValueChanged, true);
            app.play_Waterfall_Decimation.Enable = 'off';
            app.play_Waterfall_Decimation.Value = 'auto';
            app.play_Waterfall_Timestamp_Label = uilabel(app.GridLayout15);
            app.play_Waterfall_Timestamp_Label.Enable = 'off';
            app.play_Waterfall_Timestamp_Label.Text = 'Timestamp:';
            app.play_Waterfall_Timestamp = uidropdown(app.GridLayout15);
            app.play_Waterfall_Timestamp.Items = {'on', 'off'};
            app.play_Waterfall_Timestamp.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_DecimationValueChanged, true);
            app.play_Waterfall_Timestamp.Enable = 'off';
            app.play_Waterfall_Timestamp.Value = 'on';
            app.play_Waterfall_Interpolation_Label = uilabel(app.GridLayout15);
            app.play_Waterfall_Interpolation_Label.Enable = 'off';
            app.play_Waterfall_Interpolation_Label.Text = 'Direção:';
            app.play_Waterfall_Interpolation = uidropdown(app.GridLayout15);
            app.play_Waterfall_Interpolation.Items = {'row', 'both'};
            app.play_Waterfall_Interpolation.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_DecimationValueChanged, true);
            app.play_Waterfall_Interpolation.Enable = 'off';
            app.play_Waterfall_Interpolation.Value = 'both';
            app.play_Waterfall_cLim_Label = uilabel(app.GridLayout15);
            app.play_Waterfall_cLim_Label.Enable = 'off';
            app.play_Waterfall_cLim_Label.Text = 'Limites (dB):';
            app.play_Waterfall_cLim_Grid2 = uigridlayout(app.GridLayout15);
            app.play_Waterfall_cLim2 = uispinner(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim2.Step = 5;
            app.play_Waterfall_cLim2.ValueDisplayFormat = '%.0f';
            app.play_Waterfall_cLim2.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_cLim1ValueChanged, true);
            app.play_Waterfall_cLim2.Enable = 'off';
            app.play_Waterfall_cLim2.Tooltip = {''};
            app.play_Waterfall_cLim1 = uispinner(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim1.Step = 5;
            app.play_Waterfall_cLim1.ValueDisplayFormat = '%.0f';
            app.play_Waterfall_cLim1.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_cLim1ValueChanged, true);
            app.play_Waterfall_cLim1.Enable = 'off';
            app.play_Waterfall_cLim1.Tooltip = {''};
            app.play_Waterfall_cLim_Separation = uilabel(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim_Separation.Enable = 'off';
            app.play_Waterfall_cLim_Separation.Text = '-';
            app.play_Waterfall_cLim_Grid1 = uigridlayout(app.GridLayout15);
            app.play_Waterfall_cLim_Mode = uiimage(app.play_Waterfall_cLim_Grid1);
            app.play_Waterfall_cLim_Mode.ImageClickedFcn = createCallbackFcn(app, @play_Waterfall_DecimationValueChanged, true);
            app.play_Waterfall_cLim_Mode.Enable = 'off';
            app.play_Waterfall_cLim_Mode.ImageSource = 'LT_refresh.png';
            app.play_Waterfall_ColormapLabel = uilabel(app.GridLayout15);
            app.play_Waterfall_ColormapLabel.Enable = 'off';
            app.play_Waterfall_ColormapLabel.Text = 'Mapa de cor:';
            app.play_Waterfall_Colormap = uidropdown(app.GridLayout15);
            app.play_Waterfall_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.play_Waterfall_Colormap.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_DecimationValueChanged, true);
            app.play_Waterfall_Colormap.Enable = 'off';
            app.play_Waterfall_Colormap.Value = 'winter';
            app.play_OCC_Label = uilabel(app.play_right_Tab2Info);
            app.play_OCC_Label.Text = 'Ocupação';
            app.play_OCC_Panel = uipanel(app.play_right_Tab2Info);
            app.GridLayout23 = uigridlayout(app.play_OCC_Panel);
            app.Playback_occTypeLabel = uilabel(app.GridLayout23);
            app.Playback_occTypeLabel.Enable = 'off';
            app.Playback_occTypeLabel.Text = 'Tipo threshold:';
            app.Playback_occType = uidropdown(app.GridLayout23);
            app.Playback_occType.Items = {'Linear', 'Piso de ruído (Offset)'};
            app.Playback_occType.ValueChangedFcn = createCallbackFcn(app, @Playback_occTypeValueChanged, true);
            app.Playback_occType.Enable = 'off';
            app.Playback_occType.Value = 'Linear';
            app.Playback_occValueLabel = uilabel(app.GridLayout23);
            app.Playback_occValueLabel.Enable = 'off';
            app.Playback_occValueLabel.Text = 'Valor (dBm):';
            app.Playback_occValue = uispinner(app.GridLayout23);
            app.Playback_occValue.RoundFractionalValues = 'on';
            app.Playback_occValue.ValueDisplayFormat = '%d';
            app.Playback_occValue.ValueChangedFcn = createCallbackFcn(app, @Playback_occValueChanged, true);
            app.Playback_occValue.Enable = 'off';
            app.Playback_occValue.Value = -80;
            app.play_right_Tab3Grid = uigridlayout(app.playReport_rightGrid);
            app.play_right_Tab3Grid.Tag = 'COLORLOCKED';
            app.play_right_Tab3Label = uilabel(app.play_right_Tab3Grid);
            app.play_right_Tab3Label.Text = 'EMISSÕES';
            app.play_right_Tab3Image = uiimage(app.play_right_Tab3Grid);
            app.play_right_Tab3Image.ImageClickedFcn = createCallbackFcn(app, @Tab2_Image_2Clicked, true);
            app.play_right_Tab3Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Detection.png');
            app.play_right_Tab3Info = uigridlayout(app.playReport_rightGrid);
            app.play_FindPeaks_RadioGroup = uibuttongroup(app.play_right_Tab3Info);
            app.play_FindPeaks_RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_RadioGroupSelectionChanged, true);
            app.play_FindPeaks_Radio1 = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_Radio1.Text = 'ROI';
            app.play_FindPeaks_Radio1.Value = true;
            app.play_FindPeaks_Radio2 = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_Radio2.Text = 'DataTips';
            app.play_FindPeaks_Radio3 = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_Radio3.Text = 'Automática';
            app.play_FindPeaks_Panel = uipanel(app.play_right_Tab3Info);
            app.play_FindPeaks_Grid = uigridlayout(app.play_FindPeaks_Panel);
            app.play_FindPeaks_TraceLabel = uilabel(app.play_FindPeaks_Grid);
            app.play_FindPeaks_TraceLabel.Text = {'Tipo de '; 'traço:'};
            app.play_FindPeaks_Trace = uidropdown(app.play_FindPeaks_Grid);
            app.play_FindPeaks_Trace.Items = {'Mediana', 'Média', 'MaxHold'};
            app.play_FindPeaks_Trace.Value = 'Média';
            app.play_FindPeaks_NumbersLabel = uilabel(app.play_FindPeaks_Grid);
            app.play_FindPeaks_NumbersLabel.Text = {'Número de'; 'picos:'};
            app.play_FindPeaks_Numbers = uispinner(app.play_FindPeaks_Grid);
            app.play_FindPeaks_Numbers.Limits = [1 100];
            app.play_FindPeaks_Numbers.RoundFractionalValues = 'on';
            app.play_FindPeaks_Numbers.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Numbers.Value = 10;
            app.play_FindPeaks_THRLabel = uilabel(app.play_FindPeaks_Grid);
            app.play_FindPeaks_THRLabel.Text = {'Threshold'; '(dBμV/m):'};
            app.play_FindPeaks_THR = uispinner(app.play_FindPeaks_Grid);
            app.play_FindPeaks_THR.Step = 10;
            app.play_FindPeaks_THR.RoundFractionalValues = 'on';
            app.play_FindPeaks_THR.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_THR.Value = -Inf;
            app.play_FindPeaks_proeminenceLabel = uilabel(app.play_FindPeaks_Grid);
            app.play_FindPeaks_proeminenceLabel.Text = {'Proeminência '; 'mínima (dB):'};
            app.play_FindPeaks_proeminence = uispinner(app.play_FindPeaks_Grid);
            app.play_FindPeaks_proeminence.Step = 10;
            app.play_FindPeaks_proeminence.Limits = [1 Inf];
            app.play_FindPeaks_proeminence.RoundFractionalValues = 'on';
            app.play_FindPeaks_proeminence.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_proeminence.Value = 30;
            app.play_FindPeaks_distanceLabel = uilabel(app.play_FindPeaks_Grid);
            app.play_FindPeaks_distanceLabel.Text = {'Distância mínima '; 'entre picos (kHz):'};
            app.play_FindPeaks_distance = uispinner(app.play_FindPeaks_Grid);
            app.play_FindPeaks_distance.Step = 25;
            app.play_FindPeaks_distance.Limits = [0 Inf];
            app.play_FindPeaks_distance.RoundFractionalValues = 'on';
            app.play_FindPeaks_distance.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_distance.Value = 25;
            app.play_FindPeaks_BWLabel = uilabel(app.play_FindPeaks_Grid);
            app.play_FindPeaks_BWLabel.Text = {'Largura mínima '; 'ocupada (kHz):'};
            app.play_FindPeaks_BW = uispinner(app.play_FindPeaks_Grid);
            app.play_FindPeaks_BW.Step = 10;
            app.play_FindPeaks_BW.Limits = [0 Inf];
            app.play_FindPeaks_BW.RoundFractionalValues = 'on';
            app.play_FindPeaks_BW.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_BW.Value = 10;
            app.play_FindPeaks_add = uiimage(app.play_right_Tab3Info);
            app.play_FindPeaks_add.ImageClickedFcn = createCallbackFcn(app, @Playback_peakAddPushed, true);
            app.play_FindPeaks_add.Enable = 'off';
            app.play_FindPeaks_add.ImageSource = 'LT_Add.png';
            app.play_FindPeaks_Tree = uitree(app.play_right_Tab3Info);
            app.play_FindPeaks_Tree.Multiselect = 'on';
            app.play_FindPeaks_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_TreeSelectionChanged, true);
            app.play_FindPeaks_PeakCFLabel = uilabel(app.play_right_Tab3Info);
            app.play_FindPeaks_PeakCFLabel.Enable = 'off';
            app.play_FindPeaks_PeakCFLabel.Text = 'Frequência central (MHz):';
            app.play_FindPeaks_PeakCF = uieditfield(app.play_right_Tab3Info, 'numeric');
            app.play_FindPeaks_PeakCF.ValueDisplayFormat = '%.3f';
            app.play_FindPeaks_PeakCF.ValueChangedFcn = createCallbackFcn(app, @Playback_peakValueChanged, true);
            app.play_FindPeaks_PeakCF.Enable = 'off';
            app.play_FindPeaks_PeakBWLabel = uilabel(app.play_right_Tab3Info);
            app.play_FindPeaks_PeakBWLabel.Enable = 'off';
            app.play_FindPeaks_PeakBWLabel.Text = 'Largura ocupada (kHz):';
            app.play_FindPeaks_PeakBW = uieditfield(app.play_right_Tab3Info, 'numeric');
            app.play_FindPeaks_PeakBW.ValueDisplayFormat = '%.3f';
            app.play_FindPeaks_PeakBW.ValueChangedFcn = createCallbackFcn(app, @Playback_peakValueChanged, true);
            app.play_FindPeaks_PeakBW.Enable = 'off';
            app.play_FindPeaks_Label = uilabel(app.play_right_Tab3Info);
            app.play_FindPeaks_Label.Text = 'Detecção de emissões';
            app.play_right_Tab4Grid = uigridlayout(app.playReport_rightGrid);
            app.play_right_Tab4Grid.Tag = 'COLORLOCKED';
            app.play_right_Tab4Label = uilabel(app.play_right_Tab4Grid);
            app.play_right_Tab4Label.Text = 'CANAIS';
            app.play_right_Tab4Image = uiimage(app.play_right_Tab4Grid);
            app.play_right_Tab4Image.ImageClickedFcn = createCallbackFcn(app, @Tab2_Image_2Clicked, true);
            app.play_right_Tab4Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Channel.png');
            app.play_right_Tab4Info = uigridlayout(app.playReport_rightGrid);
            app.play_FindPeaks_RadioGroup_2 = uibuttongroup(app.play_right_Tab4Info);
            app.play_FindPeaks_Radio1_2 = uiradiobutton(app.play_FindPeaks_RadioGroup_2);
            app.play_FindPeaks_Radio1_2.Text = 'Canal';
            app.play_FindPeaks_Radio1_2.Value = true;
            app.play_FindPeaks_Radio2_2 = uiradiobutton(app.play_FindPeaks_RadioGroup_2);
            app.play_FindPeaks_Radio2_2.Text = {'Faixa de '; 'frequência'};
            app.play_FindPeaks_Radio3_2 = uiradiobutton(app.play_FindPeaks_RadioGroup_2);
            app.play_FindPeaks_Radio3_2.Text = 'Arquivo';
            app.play_FindPeaks_Panel_2 = uipanel(app.play_right_Tab4Info);
            app.play_FindPeaks_Grid_2 = uigridlayout(app.play_FindPeaks_Panel_2);
            app.Frequnciacentral1canalMHzEditFieldLabel = uilabel(app.play_FindPeaks_Grid_2);
            app.Frequnciacentral1canalMHzEditFieldLabel.Text = {'Frequência central'; '1º canal (MHz):'};
            app.Frequnciacentral1canalMHzEditField = uieditfield(app.play_FindPeaks_Grid_2, 'numeric');
            app.EspaamentocanaiskHzLabel = uilabel(app.play_FindPeaks_Grid_2);
            app.EspaamentocanaiskHzLabel.Text = {'Espaçamento canais'; '(kHz):'};
            app.EspaamentocanaiskHzEditField = uieditfield(app.play_FindPeaks_Grid_2, 'numeric');
            app.FrequnciacentralnsimocanalMHzEditFieldLabel = uilabel(app.play_FindPeaks_Grid_2);
            app.FrequnciacentralnsimocanalMHzEditFieldLabel.Text = {'Frequência central'; 'n-ésimo canal:'};
            app.FrequnciacentralnsimocanalMHzEditField = uieditfield(app.play_FindPeaks_Grid_2, 'numeric');
            app.LarguracanalkHzLabel = uilabel(app.play_FindPeaks_Grid_2);
            app.LarguracanalkHzLabel.Text = {'Largura canal'; '(kHz):'};
            app.LarguracanalkHzEditField = uieditfield(app.play_FindPeaks_Grid_2, 'numeric');
            app.play_FindPeaks_add_2 = uiimage(app.play_right_Tab4Info);
            app.play_FindPeaks_add_2.Enable = 'off';
            app.play_FindPeaks_add_2.ImageSource = 'LT_Add.png';
            app.play_FindPeaks_Tree_2 = uitree(app.play_right_Tab4Info);
            app.play_FindPeaks_Tree_2.Multiselect = 'on';
            app.play_FindPeaks_Label_6 = uilabel(app.play_right_Tab4Info);
            app.play_FindPeaks_Label_6.Text = 'Inclusão de canais';
            app.report_right_Tab1Grid = uigridlayout(app.playReport_rightGrid);
            app.report_right_Tab1Grid.Tag = 'COLORLOCKED';
            app.report_right_Tab1Label = uilabel(app.report_right_Tab1Grid);
            app.report_right_Tab1Label.Text = 'ASPECTOS GERAIS';
            app.report_right_Tab1Image = uiimage(app.report_right_Tab1Grid);
            app.report_right_Tab1Image.ImageClickedFcn = createCallbackFcn(app, @Tab2_Image_2Clicked, true);
            app.report_right_Tab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Dots2.png');
            app.report_right_Tab1Info = uigridlayout(app.playReport_rightGrid);
            app.Report_Tree = uitree(app.report_right_Tab1Info, 'checkbox');
            app.Report_Tree.SelectionChangedFcn = createCallbackFcn(app, @Report_TreeSelectionChanged, true);
            app.GridLayout8 = uigridlayout(app.report_right_Tab1Info);
            app.Report_ImageWarn = uiimage(app.GridLayout8);
            app.Report_ImageWarn.Tooltip = {'Pendente salvar o projeto'};
            app.Report_ImageWarn.ImageSource = 'LT_redWarn.png';
            app.Report_Sublabel1 = uilabel(app.GridLayout8);
            app.Report_Sublabel1.Text = 'Projeto';
            app.Report_Sublabel4 = uilabel(app.report_right_Tab1Info);
            app.Report_Sublabel4.Text = 'Período de observação a processar';
            app.Panel_36 = uipanel(app.report_right_Tab1Info);
            app.Report_Grid4 = uigridlayout(app.Panel_36);
            app.Report_DatePicker1 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.Report_DatePicker1.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner1 = uispinner(app.Report_Grid4);
            app.Report_Spinner1.Limits = [0 23];
            app.Report_Spinner1.RoundFractionalValues = 'on';
            app.Report_Spinner1.ValueDisplayFormat = '%.0f';
            app.Report_Spinner1.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner2 = uispinner(app.Report_Grid4);
            app.Report_Spinner2.Step = 10;
            app.Report_Spinner2.Limits = [0 59];
            app.Report_Spinner2.RoundFractionalValues = 'on';
            app.Report_Spinner2.ValueDisplayFormat = '%.0f';
            app.Report_Spinner2.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_DatePicker2 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.Report_DatePicker2.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner3 = uispinner(app.Report_Grid4);
            app.Report_Spinner3.Limits = [0 23];
            app.Report_Spinner3.RoundFractionalValues = 'on';
            app.Report_Spinner3.ValueDisplayFormat = '%.0f';
            app.Report_Spinner3.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4 = uispinner(app.Report_Grid4);
            app.Report_Spinner4.Step = 10;
            app.Report_Spinner4.Limits = [0 59];
            app.Report_Spinner4.RoundFractionalValues = 'on';
            app.Report_Spinner4.ValueDisplayFormat = '%.0f';
            app.Report_Spinner4.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Spinner4.Value = 59;
            app.Panel_37 = uipanel(app.report_right_Tab1Info);
            app.GridLayout7 = uigridlayout(app.Panel_37);
            app.ProjectName = uieditfield(app.GridLayout7, 'text');
            app.ProjectName.ValueChangedFcn = createCallbackFcn(app, @Report_ProjectNameValueChanged, true);
            app.ProjectName.Value = 'Projeto 001';
            app.Report_SaveProject = uibutton(app.GridLayout7, 'push');
            app.Report_SaveProject.ButtonPushedFcn = createCallbackFcn(app, @Report_SaveProjectValueChanged, true);
            app.Report_SaveProject.Icon = 'LT_save.png';
            app.Report_SaveProject.Tooltip = {'Salvar projeto'};
            app.Report_SaveProject.Text = '';
            app.Panel_38 = uipanel(app.GridLayout7);
            app.GridLayout21 = uigridlayout(app.Panel_38);
            app.Report_IssueLabel = uilabel(app.GridLayout21);
            app.Report_IssueLabel.Text = 'Inspeção:';
            app.Report_Issue = uieditfield(app.GridLayout21, 'numeric');
            app.Report_Issue.Limits = [-1 Inf];
            app.Report_Issue.RoundFractionalValues = 'on';
            app.Report_Issue.ValueDisplayFormat = '%d';
            app.Report_Issue.ValueChangedFcn = createCallbackFcn(app, @Report_SaveWarn, true);
            app.Report_Issue.Value = -1;
            app.Report_Type = uidropdown(app.GridLayout21);
            app.Report_Type.Items = {};
            app.Report_Type.Value = {};
            app.Report_TypeLabel = uilabel(app.GridLayout21);
            app.Report_TypeLabel.Text = 'Modelo:';
            app.Report_NewProject_2 = uibutton(app.GridLayout21, 'push');
            app.Report_NewProject_2.ButtonPushedFcn = createCallbackFcn(app, @Report_ExternalFilesMenuSelected, true);
            app.Report_NewProject_2.Icon = 'LT_attach0.png';
            app.Report_NewProject_2.Tooltip = {'Arquivos externos'};
            app.Report_NewProject_2.Text = '';
            app.Report_Version = uidropdown(app.GridLayout21);
            app.Report_Version.Items = {'Preliminar', 'Definitiva'};
            app.Report_Version.Value = 'Preliminar';
            app.Report_VersionLabel = uilabel(app.GridLayout21);
            app.Report_VersionLabel.Text = 'Versão:';
            app.RelatrioLabel = uilabel(app.GridLayout7);
            app.RelatrioLabel.Text = 'Relatório:';
            app.ProjectFilename = uitextarea(app.GridLayout7);
            app.ProjectFilename.Editable = 'off';
            app.Report_NewProject = uibutton(app.GridLayout7, 'push');
            app.Report_NewProject.ButtonPushedFcn = createCallbackFcn(app, @Report_NewProjectButtonPushed, true);
            app.Report_NewProject.Icon = 'LT_AddFiles.png';
            app.Report_NewProject.Tooltip = {'Novo projeto'};
            app.Report_NewProject.Text = '';
            app.GridLayout22 = uigridlayout(app.report_right_Tab1Info);
            app.Report_Sublabel3 = uilabel(app.GridLayout22);
            app.Report_Sublabel3.Text = 'Fluxo(s) de dados a processar';
            app.Report_addButton = uiimage(app.GridLayout22);
            app.Report_addButton.ImageClickedFcn = createCallbackFcn(app, @Report_addButtonPushed, true);
            app.Report_addButton.Enable = 'off';
            app.Report_addButton.Tooltip = {''};
            app.Report_addButton.ImageSource = 'LT_Add.png';
            app.report_right_Tab2Grid = uigridlayout(app.playReport_rightGrid);
            app.report_right_Tab2Grid.Tag = 'COLORLOCKED';
            app.report_right_Tab2Label = uilabel(app.report_right_Tab2Grid);
            app.report_right_Tab2Label.Text = 'ALGORITMOS';
            app.report_right_Tab2Image = uiimage(app.report_right_Tab2Grid);
            app.report_right_Tab2Image.ImageClickedFcn = createCallbackFcn(app, @Tab2_Image_2Clicked, true);
            app.report_right_Tab2Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Detection.png');
            app.report_right_Tab2Info = uigridlayout(app.playReport_rightGrid);
            app.Report_Sublabel5 = uilabel(app.report_right_Tab2Info);
            app.Report_Sublabel5.Text = 'Ocupação';
            app.Panel_39 = uipanel(app.report_right_Tab2Info);
            app.GridLayout18 = uigridlayout(app.Panel_39);
            app.Report_occMethodLabel = uilabel(app.GridLayout18);
            app.Report_occMethodLabel.Text = 'Método:';
            app.Report_occMethod = uieditfield(app.GridLayout18, 'text');
            app.Report_occMethod.Editable = 'off';
            app.Report_occButton = uibutton(app.GridLayout18, 'push');
            app.Report_occButton.ButtonPushedFcn = createCallbackFcn(app, @Report_ChangeParametersButtonPushed, true);
            app.Report_occButton.Icon = 'LT_edit.png';
            app.Report_occButton.Enable = 'off';
            app.Report_occButton.Tooltip = {''};
            app.Report_occButton.Text = '';
            app.Report_occParameters = uitextarea(app.GridLayout18);
            app.Report_occParameters.Editable = 'off';
            app.Report_Sublabel5_2 = uilabel(app.report_right_Tab2Info);
            app.Report_Sublabel5_2.Text = 'Detecção de emissões';
            app.Panel_40 = uipanel(app.report_right_Tab2Info);
            app.GridLayout16 = uigridlayout(app.Panel_40);
            app.Report_BandPanel = uipanel(app.GridLayout16);
            app.Report_BandGrid = uigridlayout(app.Report_BandPanel);
            app.Report_BandLim1 = uispinner(app.Report_BandGrid);
            app.Report_BandLim1.Step = 0.001;
            app.Report_BandLim1.Limits = [-1 -0.999];
            app.Report_BandLim1.ValueDisplayFormat = '%.3f';
            app.Report_BandLim1.ValueChangedFcn = createCallbackFcn(app, @Report_BandLimValueChanged, true);
            app.Report_BandLim1.Tag = 'FreqStart';
            app.Report_BandLim1.Enable = 'off';
            app.Report_BandLim1.Tooltip = {''};
            app.Report_BandLim1.Value = -1;
            app.Report_BandSliderLabel = uilabel(app.Report_BandGrid);
            app.Report_BandSliderLabel.Enable = 'off';
            app.Report_BandSliderLabel.Text = 'x';
            app.Report_BandLim2 = uispinner(app.Report_BandGrid);
            app.Report_BandLim2.Step = 0.001;
            app.Report_BandLim2.Limits = [-1 -0.999];
            app.Report_BandLim2.ValueDisplayFormat = '%.3f';
            app.Report_BandLim2.ValueChangedFcn = createCallbackFcn(app, @Report_BandLimValueChanged, true);
            app.Report_BandLim2.Tag = 'FreqStop';
            app.Report_BandLim2.Enable = 'off';
            app.Report_BandLim2.Tooltip = {''};
            app.Report_BandLim2.Value = -1;
            app.Report_DetectionParameters = uitextarea(app.GridLayout16);
            app.Report_DetectionParameters.Tag = 'Detection';
            app.Report_DetectionParameters.Editable = 'off';
            app.Report_PlaybackOnly = uicheckbox(app.GridLayout16);
            app.Report_PlaybackOnly.ValueChangedFcn = createCallbackFcn(app, @Report_Report_PlaybackOnlyValueChanged, true);
            app.Report_PlaybackOnly.Enable = 'off';
            app.Report_PlaybackOnly.Text = 'Restringir emissões às identificadas no modo PLAYBACK.';
            app.Report_BandPanelLabel = uilabel(app.GridLayout16);
            app.Report_BandPanelLabel.Text = 'Faixa sob análise:';
            app.Report_DetectionLabel = uilabel(app.GridLayout16);
            app.Report_DetectionLabel.Text = 'Algoritmo:';
            app.Report_Detection = uieditfield(app.GridLayout16, 'text');
            app.Report_Detection.Tag = 'Detection';
            app.Report_Detection.Editable = 'off';
            app.Report_DetectionButton = uibutton(app.GridLayout16, 'push');
            app.Report_DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @Report_ChangeParametersButtonPushed, true);
            app.Report_DetectionButton.Tag = 'Detection';
            app.Report_DetectionButton.Icon = 'LT_edit.png';
            app.Report_DetectionButton.Enable = 'off';
            app.Report_DetectionButton.Tooltip = {''};
            app.Report_DetectionButton.Text = '';
            app.Report_Sublabel6 = uilabel(app.report_right_Tab2Info);
            app.Report_Sublabel6.Text = 'Classificação de emissões';
            app.Panel_41 = uipanel(app.report_right_Tab2Info);
            app.GridLayout20 = uigridlayout(app.Panel_41);
            app.Report_ClassificationLabel = uilabel(app.GridLayout20);
            app.Report_ClassificationLabel.Text = 'Algoritmo:';
            app.Report_Classification = uieditfield(app.GridLayout20, 'text');
            app.Report_Classification.Editable = 'off';
            app.Report_ClassificationButton = uibutton(app.GridLayout20, 'push');
            app.Report_ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @Report_ChangeParametersButtonPushed, true);
            app.Report_ClassificationButton.Icon = 'LT_edit.png';
            app.Report_ClassificationButton.Enable = 'off';
            app.Report_ClassificationButton.Tooltip = {''};
            app.Report_ClassificationButton.Text = '';
            app.Report_ClassificationParameters = uitextarea(app.GridLayout20);
            app.Report_ClassificationParameters.Editable = 'off';
            app.playReport_docPanel = uipanel(app.playReport_Grid);
            app.playReport_toolGrid = uigridlayout(app.playReport_Grid);
            app.play_toolGrid = uigridlayout(app.playReport_toolGrid);
            app.play_tool_Button3 = uibutton(app.play_toolGrid, 'push');
            app.play_tool_Button3.Icon = fullfile(pathToMLAPP, 'Icons', '1387596.png');
            app.play_tool_Button3.Tooltip = {'Duplicar fluxo(s) de dados'};
            app.play_tool_Button3.Text = '';
            app.play_tool_Button4 = uibutton(app.play_toolGrid, 'push');
            app.play_tool_Button4.ButtonPushedFcn = createCallbackFcn(app, @play_tool_Button4ButtonPushed, true);
            app.play_tool_Button4.Icon = 'LT_redX.png';
            app.play_tool_Button4.Tooltip = {'Excluir fluxo(s) de dados'};
            app.play_tool_Button4.Text = '';
            app.play_tool_Button5 = uibutton(app.play_toolGrid, 'push');
            app.play_tool_Button5.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_mask.png');
            app.play_tool_Button5.Tooltip = {'Máscara espectral'};
            app.play_tool_Button5.Text = '';
            app.play_tool_Button2 = uibutton(app.play_toolGrid, 'push');
            app.play_tool_Button2.ButtonPushedFcn = createCallbackFcn(app, @play_tool_Button2ButtonPushed, true);
            app.play_tool_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'merge_icon_135411.png');
            app.play_tool_Button2.Tooltip = {'Mesclar fluxo(s) de dados'};
            app.play_tool_Button2.Text = '';
            app.play_tool_Button1 = uibutton(app.play_toolGrid, 'push');
            app.play_tool_Button1.Icon = fullfile(pathToMLAPP, 'Icons', 'Export_24.png');
            app.play_tool_Button1.Tooltip = {'Exportar fluxo(s) de dados'};
            app.play_tool_Button1.Text = '';
            app.play_tool_Separator1 = uiimage(app.play_toolGrid);
            app.play_tool_Separator1.Enable = 'off';
            app.play_tool_Separator1.ImageSource = 'LT_LineV.png';
            app.play_tool_Button2_2 = uibutton(app.play_toolGrid, 'push');
            app.play_tool_Button2_2.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_layoutLeft.png');
            app.play_tool_Button2_2.Tooltip = {'Mesclar fluxo(s) de dados'};
            app.play_tool_Button2_2.Text = '';
            app.play_tool_Button2_3 = uibutton(app.play_toolGrid, 'push');
            app.play_tool_Button2_3.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_layoutRight.png');
            app.play_tool_Button2_3.Tooltip = {'Mesclar fluxo(s) de dados'};
            app.play_tool_Button2_3.Text = '';
            app.play_tool_Button1_2 = uibutton(app.play_toolGrid, 'push');
            app.play_tool_Button1_2.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_lockOpen.png');
            app.play_tool_Button1_2.Tooltip = {'Exportar fluxo(s) de dados'};
            app.play_tool_Button1_2.Text = '';
            app.play_tool_Separator1_4 = uiimage(app.play_toolGrid);
            app.play_tool_Separator1_4.Enable = 'off';
            app.play_tool_Separator1_4.ImageSource = 'LT_LineV.png';
            app.play_tool_Button4_2 = uibutton(app.play_toolGrid, 'push');
            app.play_tool_Button4_2.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_photo.png');
            app.play_tool_Button4_2.Tooltip = {'Excluir fluxo(s) de dados'};
            app.play_tool_Button4_2.Text = '';
            app.play_tool_Separator1_5 = uiimage(app.play_toolGrid);
            app.play_tool_Separator1_5.Enable = 'off';
            app.play_tool_Separator1_5.ImageSource = 'LT_LineV.png';
            app.report_toolGrid = uigridlayout(app.playReport_toolGrid);
            app.report_tool_Button1 = uibutton(app.report_toolGrid, 'push');
            app.report_tool_Button1.ButtonPushedFcn = createCallbackFcn(app, @report_tool_Button1ValueChanged, true);
            app.report_tool_Button1.Icon = 'LT_list.png';
            app.report_tool_Button1.Tooltip = {'Análise de emissões'};
            app.report_tool_Button1.Text = '';
            app.play_tool_Separator1_3 = uiimage(app.report_toolGrid);
            app.play_tool_Separator1_3.Enable = 'off';
            app.play_tool_Separator1_3.ImageSource = 'LT_LineV.png';
            app.report_tool_Button2 = uibutton(app.report_toolGrid, 'push');
            app.report_tool_Button2.ButtonPushedFcn = createCallbackFcn(app, @Report_playButtonPushed, true);
            app.report_tool_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Channel.png');
            app.report_tool_Button2.Enable = 'off';
            app.report_tool_Button2.Text = 'Gerar prévia';
            app.report_tool_Button3 = uibutton(app.report_toolGrid, 'push');
            app.report_tool_Button3.ButtonPushedFcn = createCallbackFcn(app, @Report_playButtonPushed, true);
            app.report_tool_Button3.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_report.png');
            app.report_tool_Button3.Enable = 'off';
            app.report_tool_Button3.Text = 'Gerar relatório';
            app.menu_Panel = uipanel(app.GridLayout);
            app.menu_Grid = uigridlayout(app.menu_Panel);
            app.menu_Grid.Tag = 'COLORLOCKED';
            app.menu_Button1 = uibutton(app.menu_Grid, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_Button1to6Pushed, true);
            app.menu_Button1.Tag = 'HOME';
            app.menu_Button1.Tooltip = {''};
            app.menu_Button1.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_home.png');
            app.menu_Button1.Text = '';
            app.menu_Button1.Value = true;
            app.menu_Separator1 = uiimage(app.menu_Grid);
            app.menu_Separator1.Enable = 'off';
            app.menu_Separator1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.menu_Button2 = uibutton(app.menu_Grid, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @menu_Button1to6Pushed, true);
            app.menu_Button2.Tag = 'FILE';
            app.menu_Button2.Tooltip = {''};
            app.menu_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_OpenFile.png');
            app.menu_Button2.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @menu_Button1to6Pushed, true);
            app.menu_Button3.Tag = 'PLAYBACK';
            app.menu_Button3.Tooltip = {''};
            app.menu_Button3.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_playback.png');
            app.menu_Button3.Text = '';
            app.menu_Button4 = uibutton(app.menu_Grid, 'state');
            app.menu_Button4.ValueChangedFcn = createCallbackFcn(app, @menu_Button1to6Pushed, true);
            app.menu_Button4.Tag = 'REPORT';
            app.menu_Button4.Tooltip = {''};
            app.menu_Button4.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_report.png');
            app.menu_Button4.Text = '';
            app.menu_Separator2 = uiimage(app.menu_Grid);
            app.menu_Separator2.Enable = 'off';
            app.menu_Separator2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.menu_Button5 = uibutton(app.menu_Grid, 'push');
            app.menu_Button5.ButtonPushedFcn = createCallbackFcn(app, @menu_Button7to9Pushed, true);
            app.menu_Button5.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_template.png');
            app.menu_Button5.Tooltip = {''};
            app.menu_Button5.Text = '';
            app.menu_Button6 = uibutton(app.menu_Grid, 'push');
            app.menu_Button6.ButtonPushedFcn = createCallbackFcn(app, @menu_Button7to9Pushed, true);
            app.menu_Button6.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_redmine.png');
            app.menu_Button6.Tooltip = {''};
            app.menu_Button6.Text = '';
            app.menu_Button7 = uibutton(app.menu_Grid, 'push');
            app.menu_Button7.ButtonPushedFcn = createCallbackFcn(app, @menu_Button7to9Pushed, true);
            app.menu_Button7.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_mosaic.png');
            app.menu_Button7.Tooltip = {''};
            app.menu_Button7.Text = '';
            app.menu_userPathGrid = uigridlayout(app.menu_Grid);
            app.menu_userPath = uidropdown(app.menu_userPathGrid);
            app.menu_userPath.Items = {'Path'};
            app.menu_userPath.ValueChangedFcn = createCallbackFcn(app, @menu_userPathValueChanged, true);
            app.menu_userPath.Value = 'Path';
            app.menu_searchPath = uiimage(app.menu_Grid);
            app.menu_searchPath.ImageClickedFcn = createCallbackFcn(app, @menu_searchPathImageClicked, true);
            app.menu_searchPath.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_zoom.png');
            app.menu_Position = uiimage(app.menu_Grid);
            app.menu_Position.ImageClickedFcn = createCallbackFcn(app, @menu_PositionImageClicked, true);
            app.menu_Position.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_layout1.png');
            app.menu_Settings = uiimage(app.menu_Grid);
            app.menu_Settings.ImageClickedFcn = createCallbackFcn(app, @menu_Button7to9Pushed, true);
            app.menu_Settings.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_Dots.png');
            app.ContextMenu_Report = uicontextmenu(app.UIFigure);
            app.Report_Delete = uimenu(app.ContextMenu_Report);
            app.Report_Delete.MenuSelectedFcn = createCallbackFcn(app, @Report_deleteButtonPushed, true);
            app.Report_Delete.Separator = 'on';
            app.Report_Delete.Text = 'Excluir';
            app.Report_ExternalFiles = uimenu(app.ContextMenu_Report);
            app.Report_ExternalFiles.MenuSelectedFcn = createCallbackFcn(app, @Report_ExternalFilesMenuSelected, true);
            app.Report_ExternalFiles.Text = 'Arquivos externos';
            app.ContextMenu_File2 = uicontextmenu(app.UIFigure);
            app.File_ContextMenu2 = uimenu(app.ContextMenu_File2);
            app.File_ContextMenu2.MenuSelectedFcn = createCallbackFcn(app, @File_Tree2Delete, true);
            app.File_ContextMenu2.Text = 'Excluir';
            app.play_ContextMenu2 = uicontextmenu(app.UIFigure);
            app.play_ContextMenu2_Drive = uimenu(app.play_ContextMenu2);
            app.play_ContextMenu2_Drive.MenuSelectedFcn = createCallbackFcn(app, @play_ContextMenu2_DriveSelected, true);
            app.play_ContextMenu2_Drive.Text = 'Drive-test';
            app.play_ContextMenu2_search = uimenu(app.play_ContextMenu2);
            app.play_ContextMenu2_search.MenuSelectedFcn = createCallbackFcn(app, @Playback_peakSearch, true);
            app.play_ContextMenu2_search.Text = 'Consultar';
            app.play_ContextMenu2_edit = uimenu(app.play_ContextMenu2);
            app.play_ContextMenu2_edit.Text = 'Editar';
            app.play_ContextMenu2_analog = uimenu(app.play_ContextMenu2_edit);
            app.play_ContextMenu2_analog.MenuSelectedFcn = createCallbackFcn(app, @Playback_TruncatedCheckBoxValueChanged, true);
            app.play_ContextMenu2_analog.Text = 'Não truncar';
            app.play_ContextMenu2_digital = uimenu(app.play_ContextMenu2_edit);
            app.play_ContextMenu2_digital.MenuSelectedFcn = createCallbackFcn(app, @Playback_TruncatedCheckBoxValueChanged, true);
            app.play_ContextMenu2_digital.Text = 'Truncar frequência';
            app.play_ContextMenu2_del = uimenu(app.play_ContextMenu2);
            app.play_ContextMenu2_del.MenuSelectedFcn = createCallbackFcn(app, @Playback_peakDeletePushed, true);
            app.play_ContextMenu2_del.Text = 'Excluir';
            app.ContextMenu_File1 = uicontextmenu(app.UIFigure);
            app.File_ContextMenu1 = uimenu(app.ContextMenu_File1);
            app.File_ContextMenu1.MenuSelectedFcn = createCallbackFcn(app, @File_Tree1Delete, true);
            app.File_ContextMenu1.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAppAnalise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end