classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Grid                   matlab.ui.container.GridLayout
        Image2                 matlab.ui.control.Image
        Panel_3                matlab.ui.container.Panel
        Tab2_Grid              matlab.ui.container.GridLayout
        GridLayout6            matlab.ui.container.GridLayout
        varreduraspontoLabel   matlab.ui.control.Label
        OperaoDropDown         matlab.ui.control.DropDown
        OperaoDropDownLabel    matlab.ui.control.Label
        GridLayout4            matlab.ui.container.GridLayout
        Column1_4              matlab.ui.control.Label
        Column1_3              matlab.ui.control.Label
        Column1_2              matlab.ui.control.Label
        Column1                matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        GridLayout5            matlab.ui.container.GridLayout
        Panel_4                matlab.ui.container.Panel
        Tab1_Grid_2            matlab.ui.container.GridLayout
        Panel_7                matlab.ui.container.Panel
        GridLayout7_3          matlab.ui.container.GridLayout
        ColormapraioLabel_2    matlab.ui.control.Label
        DensidadeLabel         matlab.ui.control.Label
        Panel_6                matlab.ui.container.Panel
        GridLayout7_2          matlab.ui.container.GridLayout
        ColormapraioLabel      matlab.ui.control.Label
        Panel_5                matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        PropriedadesLabel      matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        TamanhoSlider          matlab.ui.control.Slider
        TipoDropDown           matlab.ui.control.DropDown
        TipoDropDownLabel      matlab.ui.control.Label
        DistoroLabel           matlab.ui.control.Label
        RotaLabel              matlab.ui.control.Label
        img_Density            matlab.ui.control.Image
        img_Scatter            matlab.ui.control.Image
        img_Route              matlab.ui.control.Image
        GridLayout2_3          matlab.ui.container.GridLayout
        Image4_3               matlab.ui.control.Image
        PONTOSDEMEDIOLabel     matlab.ui.control.Label
        GridLayout2_2          matlab.ui.container.GridLayout
        Image4_2               matlab.ui.control.Image
        DATABINNINGLabel       matlab.ui.control.Label
        GridLayout2            matlab.ui.container.GridLayout
        Image4                 matlab.ui.control.Image
        CAMADASLabel           matlab.ui.control.Label
        GridLayout             matlab.ui.container.GridLayout
        ASPECTOSGERAISLabel    matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        Panel_2                matlab.ui.container.Panel
        Tab1_Grid              matlab.ui.container.GridLayout
        Tab1_Emissions         matlab.ui.control.DropDown
        Tab1_GridA             matlab.ui.container.GridLayout
        Tab1_EmissionsRefresh  matlab.ui.control.Image
        Tab1_EmissionsLabel    matlab.ui.control.Label
        Tab1_Info              matlab.ui.control.TextArea
        Tab1_InfoLabel         matlab.ui.control.Label
        Tab1_TreeLabel         matlab.ui.control.Label
        Tab1_Tree              matlab.ui.container.CheckBoxTree
        UIAxes2_2              matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
        specData
        Index
        
        specTable

        UIAxes1
        hRoute
        hScatter
        hDensity
        
    end
    
    methods (Access = private)

        function Fcn1_AxesCreation(app)

            app.UIAxes1 = geoaxes(app.Grid, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater');

            while true
                try
                    addToolbarMapButton(axtoolbar(app.UIAxes1, "default"), "basemap", BasemapNames=["darkwater", "satellite", "streets-light", "streets-dark", "topographic", "streets", "colorterrain", "grayterrain"])

                    app.UIAxes1.Layout.Row    = 1;
                    app.UIAxes1.Layout.Column = 3:4;
                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';

                    hold(app.UIAxes1, 'on')

                    break
                catch
                end
            end

            app.UIAxes2.Interactions = dataTipInteraction;            
            enableDefaultInteractivity(app.UIAxes2)

        end
        

        function Fcn2_Panel(app)   

            % EMISSÕES 
            Fcn3_Emissions(app)

            % ARQUIVOS
            if ~isempty(app.Tab1_Tree.Children)
                delete(app.Tab1_Tree.Children)
            end

            for ii = 1:height(app.specData.RelatedFiles)
                uitreenode(app.Tab1_Tree, 'Text', app.specData.RelatedFiles.Name{ii});
            end
            app.Tab1_Tree.CheckedNodes = app.Tab1_Tree.Children(1);
            Tab1_TreeCheckedNodesChanged(app)
        
        end


        function Fcn3_Emissions(app)

            emissionTable = app.specData.Emissions;
            
            app.Tab1_Emissions.Items = {};
            for ii = 1:height(emissionTable)
                app.Tab1_Emissions.Items{end+1} = sprintf('%d: %.3f MHz @ %.3f kHz',       ...
                                                          ii,                              ...
                                                          emissionTable.Frequency(ii), ...
                                                          emissionTable.BW(ii));
            end

        end


        function Fcn3_geoPoints(app)

            % TABELA MEDIÇÃO
            timestamp = app.specData.Data{1}';
            
            lat  = [];
            long = [];
            for ii = 1:numel(app.specData.RelatedGPS)
                lat  = [lat;  app.specData.RelatedGPS{ii}.Matrix(:,1)];
                long = [long; app.specData.RelatedGPS{ii}.Matrix(:,2)];
            end

            lat_String = strsplit(sprintf('%.6f\n', lat),  '\n')';
            lon_String = strsplit(sprintf('%.6f\n', long), '\n')';
            latlong    = string(lat_String(1:end-1)) + ", " + string(lon_String(1:end-1));

            app.specTable = table(timestamp, lat, long, latlong);
            
            % SUMARIZAÇÃO DA TABELA MEDIÇÃO ORIENTADA AOS "PONTOS DE MONITORAÇÃO"
            [latlong_String, latlong_idx, app.specTable.latlong_idx] = unique(app.specTable.latlong, 'stable');
            latlong_Count = accumarray(app.specTable.latlong_idx, 1);
            
            NN   = numel(latlong_String);
            id   = (1:NN)';
            lat  = lat(latlong_idx);
            long = long(latlong_idx);

            [minNN, maxNN] = bounds(latlong_Count);

%             ptDist = deg2km(distance('gc', [lat(1:end-1), long(1:end-1)], [lat(2:end), long(2:end)]));
%             [ptDist_min, ptDist_max] = bounds(ptDist*1000);
%             ptDist_mean = mean(ptDist)*1000;
            ptDist_min  = 0.123;
            ptDist_mean = 40.123;
            ptDist_max  = 150.123;

            app.UITable.Data = table(id, lat, long, latlong_Count);
            
            auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', ptDist_min, ptDist_mean, ptDist_max);
            auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto', minNN, maxNN);

            app.varreduraspontoLabel.Text = sprintf('%s\n%.0f |–––––––%s| %.0f varreduras/ponto', ...
                                                     auxString1, minNN, repmat('–',1,numel(auxString1)-numel(auxString2)+1), maxNN);

        end


        function Fcn4_geoTable(app)

            timestamp = app.specData.Data{1}';

            lat  = [];
            long = [];
            for ii = 1:numel(app.specData.RelatedGPS)
                lat  = [lat;  app.specData.RelatedGPS{ii}.Matrix(2:end,1)];
                long = [long; app.specData.RelatedGPS{ii}.Matrix(2:end,2)];
            end

            idx = find(strcmp(app.Tab1_Emissions.Items, app.Tab1_Emissions.Value), 1);
            FreqCenter = app.specData.Emissions.Frequency(idx) * 1e+6;
            BW         = app.specData.Emissions.BW(idx)        * 1e+3;

            [chPower, chBinBounds] = Misc_chPower(app.specData, [FreqCenter-BW/2, FreqCenter+BW/2]);

            app.specTable = table(timestamp, lat, long, chBinBounds(:,1), chBinBounds(:,2), chPower);

            geolimits(app.UIAxes1, [min(lat)-.01, max(lat)+.01], [min(long)-.01, max(long)+.01])

            
            geoplot(app.UIAxes1, lat+.01, long+.01, 'Tag', 'img_Scatter')
            geoplot(app.UIAxes1, lat+.02, long+.02, 'Tag', 'img_Density')

        end


        function Fcn5_Plot_Route(app)

            switch app.TipoDropDown.Value
                case 'Ponto'
                    app.hRoute = geoscatter(app.UIAxes1, app.UITable.Data.lat, app.UITable.Data.long, ...
                                            'filled', 'Marker', 'o', ...
                                            'MarkerFaceColor', app.Button.BackgroundColor, ...
                                            'MarkerEdgeColor', app.Button.BackgroundColor, ...
                                            'SizeData', .5*app.TamanhoSlider.Value);
                
                case 'Linha'
                    app.hRoute = geoplot(app.UIAxes1, app.UITable.Data.lat, app.UITable.Data.long, ...
                                         'Color', app.Button.BackgroundColor, ...
                                         'LineWidth', .5*app.TamanhoSlider.Value);
                    
                case 'Densidade'
                    app.hRoute = geodensityplot(app.UIAxes1, app.UITable.Data.lat, app.UITable.Data.long, app.UITable.Data.lat*app.TamanhoSlider.Value, ...
                                         'FaceColor', app.Button.BackgroundColor, ...
                                         'Radius', 50*app.TamanhoSlider.Value);
            end
            app.img_Route.ImageSource = 'DriveTest_Route_ON.png';

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx, Tag)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.specData = app.CallingApp.specData(idx);
            app.Index    = idx;

            app.UIFigure.Tag = Tag;

            Fcn1_AxesCreation(app)
            Fcn2_Panel(app)
            Fcn3_geoPoints(app)
            %Fcn4_geoTable(app)
            ImageClicked(app, struct('Source', app.Image))
            
            drawnow nocallbacks
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Callback function: Tab1_Tree
        function Tab1_TreeCheckedNodesChanged(app, event)
            
            checkedNodes = app.Tab1_Tree.CheckedNodes;
            if isempty(checkedNodes)
                app.Tab1_Tree.CheckedNodes = event.PreviousCheckedNodes;
                return
            end

            metaData = '';
            for ii = 1:numel(checkedNodes)
                idx = find(strcmp(app.specData.RelatedFiles.Name, checkedNodes(ii).Text), 1);

                ObservationTime = sprintf('%s - %s', datestr(app.specData.RelatedFiles.BeginTime(idx), 'dd/mm/yyyy HH:MM:SS'), datestr(app.specData.RelatedFiles.EndTime(idx), 'dd/mm/yyyy HH:MM:SS'));

                metaData = sprintf(['%s• %s\n' ...
                                    '  %s\n' ...
                                    '  %.0f varreduras, %.3f seg (tempo de revisita)\n\n'], ...
                                    metaData, ...
                                    app.specData.RelatedFiles.Name{idx}, ...
                                    ObservationTime, ...
                                    app.specData.RelatedFiles.Samples(idx), ...
                                    app.specData.RelatedFiles.RevisitTime(idx));
            end

            app.Tab1_Info.Value = sprintf(['Faixa: %.3f - %.3f MHz\n\n'  ...
                                           'Pontos por traço: %.0f\n'    ...
                                           'Unidade: %s\n'               ...
                                           'Resolução: %.3f kHz\n\n%s'], ...
                                           app.specData.MetaData.FreqStart / 1e+6,  ...
                                           app.specData.MetaData.FreqStop  / 1e+6,  ...
                                           app.specData.MetaData.DataPoints,        ...
                                           app.specData.MetaData.metaString{1},     ...
                                           app.specData.MetaData.Resolution / 1e+3, ...
                                           metaData);

        end

        % Image clicked function: Tab1_EmissionsRefresh
        function Tab1_EmissionsRefreshImageClicked(app, event)
            
            preSelection = extractAfter(app.Tab1_Emissions.Value, ': ');
            
            app.specData = app.CallingApp.specData(app.Index);
            Fcn3_Emissions(app)

            idx = find(contains(app.Tab1_Emissions.Items, preSelection), 1);
            if ~isempty(idx)
                app.Tab1_Emissions.Value = app.Tab1_Emissions.Items(idx);
            else
                Fcn5_Plot(app)
            end

        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.Image2.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.Image2.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

        end

        % Image clicked function: img_Density, img_Route, img_Scatter
        function imgPlotClicked(app, event)
            
            switch event.Source
                case app.img_Route
                    if isempty(app.hRoute)
                        Fcn5_Plot_Route(app);
                    else
                        if app.hRoute.Visible
                            app.hRoute.Visible = 0;
                            app.img_Route.ImageSource = 'DriveTest_Route_OFF.png';
                        else
                            app.hRoute.Visible = 1;
                            app.img_Route.ImageSource = 'DriveTest_Route_ON.png';
                        end
                    end

                case app.img_Scatter
                    h = findall(app.UIAxes1, 'Tag', 'img_Scatter');
                    if h.Visible
                        h.Visible = 0;
                        app.img_Scatter.ImageSource = 'DriveTest_Scatter_OFF.png';
                    else
                        h.Visible = 1;
                        app.img_Scatter.ImageSource = 'DriveTest_Scatter_ON.png';
                    end

                case app.img_Density
                    h = findall(app.UIAxes1, 'Tag', 'img_Density');
                    if h.Visible
                        h.Visible = 0;
                        app.img_Density.ImageSource = 'DriveTest_Density_OFF.png';
                    else
                        h.Visible = 1;
                        app.img_Density.ImageSource = 'DriveTest_Density_ON.png';
                    end
            end

        end

        % Image clicked function: Image, Image4, Image4_2, Image4_3
        function ImageClicked(app, event)
            
            switch event.Source
                case app.Image;    app.Tab2_Grid.RowHeight([2,4,6,8]) = {'1x',0,0,0};
                case app.Image4_3; app.Tab2_Grid.RowHeight([2,4,6,8]) = {0,'1x',0,0};
                case app.Image4_2; app.Tab2_Grid.RowHeight([2,4,6,8]) = {0,0,'1x',0};
                case app.Image4;   app.Tab2_Grid.RowHeight([2,4,6,8]) = {0,0,0,'1x'};
            end

        end

        % Callback function
        function Tab1_EmissionsRefresh_2ImageClicked(app, event)
            
            app.UIFigure.WindowState = "fullscreen";

        end

        % Value changing function: TamanhoSlider
        function TamanhoSliderValueChanging(app, event)
            
            try
                switch app.TipoDropDown.Value
                    case {'Ponto', 'Linha'}
                        set(app.hRoute, 'LineWidth', .5*event.Value)

                    case 'Densidade'
                        set(app.hRoute, 'Radius', 50*event.Value)
                end
            catch
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                app.Button.BackgroundColor = selectedColor;

                try
                    switch app.TipoDropDown.Value
                        case 'Ponto'
                            set(app.hRoute, 'MarkerFaceColor', selectedColor, ...
                                            'MarkerEdgeColor', selectedColor)

                        case 'Linha'
                            set(app.hRoute, 'Color', selectedColor)

                        case 'Densidade'
                            set(app.hRoute, 'FaceColor', selectedColor)
                    end
                catch
                end
            end

        end

        % Value changed function: TipoDropDown
        function TipoDropDownValueChanged(app, event)

            if ~isempty(app.hRoute)
                delete(app.hRoute)
            end
            Fcn5_Plot_Route(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Grid);
            xlabel(app.UIAxes2, 'Amostra')
            ylabel(app.UIAxes2, 'Densidade (dBm/Hz)')
            app.UIAxes2_2 = uiaxes(app.Grid);
            xlabel(app.UIAxes2_2, 'Frequência')
            ylabel(app.UIAxes2_2, 'Nível (dBm)')
            app.Panel_3 = uipanel(app.Grid);
            app.Tab2_Grid = uigridlayout(app.Panel_3);
            app.Panel_2 = uipanel(app.Tab2_Grid);
            app.Tab1_Grid = uigridlayout(app.Panel_2);
            app.Tab1_Tree = uitree(app.Tab1_Grid, 'checkbox');
            % Assign Checked Nodes
            app.Tab1_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @Tab1_TreeCheckedNodesChanged, true);
            app.Tab1_TreeLabel = uilabel(app.Tab1_Grid);
            app.Tab1_TreeLabel.Text = 'Arquivo(s) relacionado(s):';
            app.Tab1_InfoLabel = uilabel(app.Tab1_Grid);
            app.Tab1_InfoLabel.Text = 'Metadados:';
            app.Tab1_Info = uitextarea(app.Tab1_Grid);
            app.Tab1_Info.Editable = 'off';
            app.Tab1_GridA = uigridlayout(app.Tab1_Grid);
            app.Tab1_EmissionsLabel = uilabel(app.Tab1_GridA);
            app.Tab1_EmissionsLabel.Text = 'Emissão:';
            app.Tab1_EmissionsRefresh = uiimage(app.Tab1_GridA);
            app.Tab1_EmissionsRefresh.ImageClickedFcn = createCallbackFcn(app, @Tab1_EmissionsRefreshImageClicked, true);
            app.Tab1_EmissionsRefresh.ImageSource = 'LT_refresh.png';
            app.Tab1_Emissions = uidropdown(app.Tab1_Grid);
            app.Tab1_Emissions.Items = {};
            app.Tab1_Emissions.Value = {};
            app.GridLayout = uigridlayout(app.Tab2_Grid);
            app.GridLayout.Tag = 'COLORLOCKED';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'LT_settings.png';
            app.ASPECTOSGERAISLabel = uilabel(app.GridLayout);
            app.ASPECTOSGERAISLabel.Text = 'ASPECTOS GERAIS';
            app.GridLayout2 = uigridlayout(app.Tab2_Grid);
            app.GridLayout2.Tag = 'COLORLOCKED';
            app.CAMADASLabel = uilabel(app.GridLayout2);
            app.CAMADASLabel.Text = 'CAMADAS';
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image4.ImageSource = 'LT_Layers.png';
            app.GridLayout2_2 = uigridlayout(app.Tab2_Grid);
            app.GridLayout2_2.Tag = 'COLORLOCKED';
            app.DATABINNINGLabel = uilabel(app.GridLayout2_2);
            app.DATABINNINGLabel.Text = 'DATA BINNING';
            app.Image4_2 = uiimage(app.GridLayout2_2);
            app.Image4_2.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image4_2.ImageSource = 'LT_Detection.png';
            app.GridLayout2_3 = uigridlayout(app.Tab2_Grid);
            app.GridLayout2_3.Tag = 'COLORLOCKED';
            app.PONTOSDEMEDIOLabel = uilabel(app.GridLayout2_3);
            app.PONTOSDEMEDIOLabel.Text = 'PONTOS DE MEDIÇÃO';
            app.Image4_3 = uiimage(app.GridLayout2_3);
            app.Image4_3.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image4_3.ImageSource = 'Instr_Global.png';
            app.GridLayout5 = uigridlayout(app.Tab2_Grid);
            app.img_Route = uiimage(app.GridLayout5);
            app.img_Route.ImageClickedFcn = createCallbackFcn(app, @imgPlotClicked, true);
            app.img_Route.Tooltip = {'Rota'};
            app.img_Route.ImageSource = 'DriveTest_Route_OFF.png';
            app.img_Scatter = uiimage(app.GridLayout5);
            app.img_Scatter.ImageClickedFcn = createCallbackFcn(app, @imgPlotClicked, true);
            app.img_Scatter.Tooltip = {'Distorção'};
            app.img_Scatter.ImageSource = 'DriveTest_Scatter_OFF.png';
            app.img_Density = uiimage(app.GridLayout5);
            app.img_Density.ImageClickedFcn = createCallbackFcn(app, @imgPlotClicked, true);
            app.img_Density.Tooltip = {'Densidade'};
            app.img_Density.ImageSource = 'DriveTest_Density_OFF.png';
            app.Panel_4 = uipanel(app.GridLayout5);
            app.Tab1_Grid_2 = uigridlayout(app.Panel_4);
            app.RotaLabel = uilabel(app.Tab1_Grid_2);
            app.RotaLabel.Text = 'Rota:';
            app.DistoroLabel = uilabel(app.Tab1_Grid_2);
            app.DistoroLabel.Text = 'Distorção:';
            app.Panel_5 = uipanel(app.Tab1_Grid_2);
            app.GridLayout7 = uigridlayout(app.Panel_5);
            app.TipoDropDownLabel = uilabel(app.GridLayout7);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TipoDropDown = uidropdown(app.GridLayout7);
            app.TipoDropDown.Items = {'Ponto', 'Linha', 'Densidade'};
            app.TipoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.TipoDropDown.Value = 'Linha';
            app.TamanhoSlider = uislider(app.GridLayout7);
            app.TamanhoSlider.Limits = [1 9];
            app.TamanhoSlider.MajorTicks = [1 3 5 7 9];
            app.TamanhoSlider.MajorTickLabels = {''};
            app.TamanhoSlider.ValueChangingFcn = createCallbackFcn(app, @TamanhoSliderValueChanging, true);
            app.TamanhoSlider.Value = 1;
            app.Button = uibutton(app.GridLayout7, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.PropriedadesLabel = uilabel(app.GridLayout7);
            app.PropriedadesLabel.Text = 'Propriedades:';
            app.Panel_6 = uipanel(app.Tab1_Grid_2);
            app.GridLayout7_2 = uigridlayout(app.Panel_6);
            app.ColormapraioLabel = uilabel(app.GridLayout7_2);
            app.ColormapraioLabel.Text = 'Colormap, raio';
            app.DensidadeLabel = uilabel(app.Tab1_Grid_2);
            app.DensidadeLabel.Text = 'Densidade:';
            app.Panel_7 = uipanel(app.Tab1_Grid_2);
            app.GridLayout7_3 = uigridlayout(app.Panel_7);
            app.ColormapraioLabel_2 = uilabel(app.GridLayout7_3);
            app.ColormapraioLabel_2.Text = 'Colormap, raio';
            app.GridLayout6 = uigridlayout(app.Tab2_Grid);
            app.GridLayout4 = uigridlayout(app.GridLayout6);
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.Column1 = uilabel(app.GridLayout4);
            app.Column1.Text = {'  ID'; '  '};
            app.Column1_2 = uilabel(app.GridLayout4);
            app.Column1_2.Text = {'  Latitude'; '  '};
            app.Column1_3 = uilabel(app.GridLayout4);
            app.Column1_3.Text = {'  Longitude'; '  '};
            app.Column1_4 = uilabel(app.GridLayout4);
            app.Column1_4.Text = {'  Varreduras'; '  '};
            app.OperaoDropDownLabel = uilabel(app.GridLayout6);
            app.OperaoDropDownLabel.Text = 'Operação:';
            app.OperaoDropDown = uidropdown(app.GridLayout6);
            app.OperaoDropDown.Items = {'Mínima', 'Média', 'Mediana', 'RMS', 'Máxima'};
            app.OperaoDropDown.Value = 'Mediana';
            app.varreduraspontoLabel = uilabel(app.GridLayout6);
            app.varreduraspontoLabel.Text = '10 |––––––––––––| 90 varreduras/ponto';
            app.Image2 = uiimage(app.Grid);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'LT_HideLeft.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end