classdef winTemplate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Grid1                  matlab.ui.container.GridLayout
        GridLayout9            matlab.ui.container.GridLayout
        ImagemPanel            matlab.ui.container.Panel
        GridLayout13           matlab.ui.container.GridLayout
        SourceDropDown         matlab.ui.control.DropDown
        SourceDropDownLabel    matlab.ui.control.Label
        TypeDropDown_2         matlab.ui.control.DropDown
        TypeDropDown_2Label    matlab.ui.control.Label
        CaptionEditField       matlab.ui.control.EditField
        CaptionEditFieldLabel  matlab.ui.control.Label
        Panel_4                matlab.ui.container.Panel
        GridLayout12           matlab.ui.container.GridLayout
        HeightEditField        matlab.ui.control.EditField
        HeightEditFieldLabel   matlab.ui.control.Label
        WidthEditField         matlab.ui.control.EditField
        WidthEditFieldLabel    matlab.ui.control.Label
        SettingsLabel          matlab.ui.control.Label
        GridLayout8            matlab.ui.container.GridLayout
        ImageEditField         matlab.ui.control.EditField
        ImageEditFieldLabel    matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        TypeDropDown           matlab.ui.control.DropDown
        TypeDropDownLabel      matlab.ui.control.Label
        Panel_5                matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        Panel_3                matlab.ui.container.Panel
        GridLayout11           matlab.ui.container.GridLayout
        ChildrenTextArea       matlab.ui.control.TextArea
        ChildrenTextAreaLabel  matlab.ui.control.Label
        EditableDropDown       matlab.ui.control.DropDown
        EditableDropDownLabel  matlab.ui.control.Label
        StringTextArea         matlab.ui.control.TextArea
        StringTextAreaLabel    matlab.ui.control.Label
        DataLabel              matlab.ui.control.Label
        RecurrenceCheckBox     matlab.ui.control.CheckBox
        GridLayout10           matlab.ui.container.GridLayout
        Button_open_5          matlab.ui.control.Button
        Button_open_4          matlab.ui.control.Button
        Button_open_3          matlab.ui.control.Button
        GridLayout6            matlab.ui.container.GridLayout
        Warn                   matlab.ui.control.Image
        ModelLabel             matlab.ui.control.Label
        Sublabel2              matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout2_2          matlab.ui.container.GridLayout
        Sublabel1              matlab.ui.control.Label
        GridLayout5            matlab.ui.container.GridLayout
        GridLayout2            matlab.ui.container.GridLayout
        HTML                   matlab.ui.control.HTML
        GridLayout3            matlab.ui.container.GridLayout
        Grid1B                 matlab.ui.container.GridLayout
        Button_open_2          matlab.ui.control.Button
        Button_preview         matlab.ui.control.Button
        GridLayout             matlab.ui.container.GridLayout
        Image_up               matlab.ui.control.Image
        Image_down             matlab.ui.control.Image
        Image_add              matlab.ui.control.Image
        Tree                   matlab.ui.container.Tree
        Model                  matlab.ui.control.DropDown
        Logo                   matlab.ui.control.Image
        Title                  matlab.ui.control.Label
        ContextMenu            matlab.ui.container.ContextMenu
        EditarMenu             matlab.ui.container.Menu
        ExcluirMenu            matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        ModelInfo
        Template

        i = ["OBJETIVO",    "mainMenu_Home.png"; ...
             "INTRODUÇÃO",  "Toolbar_Icon3.png"; ...
             "METODOLOGIA", "Filter.png"; ...
             "RESULTADOS",  "1200px-BellcurveMean_Edited2.png"; ...
             "ANÁLISE",     "TruncatedPeak.png"; ...
             "CONCLUSÃO",   "Pin.png"]
        s = uistyle('FontColor', '#808080')
        
    end
    
    methods (Access = private)

        function Fcn_ReadModels(app)

            app.ModelInfo   = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'DataBase', 'InspectionType.cfg'))));
            app.Model.Items = app.ModelInfo.Name;

        end


        function Fcn_ReadTemplate(app)

            idx = find(strcmp(app.ModelInfo.Name, app.Model.Value));
            
            app.Sublabel1.Text = upper(app.ModelInfo.Description{idx});
            app.Sublabel2.Text = sprintf('v. %s (%s)\n%s', app.ModelInfo.Version{idx}, ...
                                                           app.ModelInfo.Release{idx}, ...
                                                           app.ModelInfo.Author{idx});

            app.Template = jsondecode(fileread(fullfile(app.RootFolder, 'DataBase', app.ModelInfo.Template{idx})));

            Fcn_TreeBuilding(app)

        end


        function Fcn_TreeBuilding(app)

            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end
         
            for ii = 1:numel(app.Template)
                idx = find(app.i(:,1) == app.Template(ii).Data.String);
                
                Parent = uitreenode(app.Tree, 'Text', sprintf('ITEM %.0f: %s', ii, app.Template(ii).Data.String), ...
                                              'NodeData', ii, 'Icon', app.i(idx,2));

                if isempty(app.Template(ii).Data.Children)
                    addStyle(app.Tree, app.s, 'node', Parent)
                else
                    for jj = 1:numel(app.Template(ii).Data.Children)
                    uitreenode(Parent, 'Text', app.Template(ii).Data.Children(jj).Type, ...
                                       'NodeData', jj);
                    end
                end
            end
            expand(app.Tree, 'all')

            if ~isempty(app.Tree.Children)
                app.Tree.SelectedNodes = app.Tree.Children(1);
                TreeSelectionChanged(app)
            end

        end


        function Fcn_Layout_UpDown(app)

            Parent = app.Tree.SelectedNodes.Parent;
            
            NN   = numel(Parent.Children);
            idx1 = find([Parent.Children.NodeData] == Parent.Children(1).NodeData);
            idx2 = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);

            if NN == 1
                app.Image_up.Enable   = 0;
                app.Image_down.Enable = 0;

            elseif idx2 == idx1
                app.Image_up.Enable   = 0;
                app.Image_down.Enable = 1;

            elseif idx2 - idx1 + 1 == NN
                app.Image_up.Enable   = 1;
                app.Image_down.Enable = 0;

            else
                app.Image_up.Enable   = 1;
                app.Image_down.Enable = 1;
            end

        end
        

        function Fcn_NodeData(app)

            for ii = 1:numel(app.Tree.Children)
                idx = app.Tree.Children(ii).UserData;

                set(app.Tree.Children(ii), 'Text', sprintf('ITEM %.0f: %s', ii, app.Template(ii).Data.String), ...
                                           'NodeData', ii)

                for jj = 1:numel(app.Tree.Children(ii).Children)
                    app.Tree.Children(ii).Children(jj).NodeData = ii+jj;
                end
            end
            app.Warn.Visible = 1;

            TreeSelectionChanged(app)

        end

        
        function Fcn_updateValues(app)

            if app.Tree.SelectedNodes.Parent == app.Tree
                idx1 = app.Tree.SelectedNodes.NodeData;

                app.TypeDropDown.Value = app.Template(idx1).Type;
                app.RecurrenceCheckBox.Value = app.Template(idx1).Recurrence;
                app.EditableDropDown.Value = app.Template(idx1).Data.Editable;
                app.StringTextArea.Value = app.Template(idx1).Data.String;
                app.ChildrenTextArea.Value = jsonencode(app.Template(idx1).Data.Children, 'PrettyPrint', true);


            else
                idx1 = app.Tree.SelectedNodes.Parent.NodeData;
                idx2 = app.Tree.SelectedNodes.NodeData;
            end

        end


        function Fcn_reportPreview(app)

            try
%                 load(fullfile(app.RootFolder, 'DataBase', 'template.mat'), 'Data', 'reportInfo');
% 
%                 idx = find(strcmp(app.Model.Items, app.Model.Value), 1);
%                 
%                 reportInfo.appVersion    = app.CallingApp.General.Version;
%                 reportInfo.General.Image = struct('Visibility', 'off', ...
%                                                   'Format', 'jpeg',    ...
%                                                   'Resolution', 120);
%                 reportInfo.Model         = struct('Name',     app.Model.Value,                      ...
%                                                   'Type',     app.CallingApp.General.Models(idx,:), ...
%                                                   'Template', fileread(fullfile(app.RootFolder, 'DataBase', app.CallingApp.General.Models.Template{idx})));
% 
%                 htmlReport = ReportGenerator(Data, reportInfo, []);
% 
%                 app.HTML.HTMLSource = htmlReport;
                app.HTML.HTMLSource = fileread(fullfile(app.RootFolder, 'DataBase', 'Template_2022.05.10_T18.08.18.html'));
                app.Warn.Visible    = 0;

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', fullfile(app.RootFolder, 'Icons', 'Toolbar_Icon3.png'))
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...");

            Fcn_ReadModels(app)
            Fcn_ReadTemplate(app)
            Fcn_reportPreview(app)

            delete(d)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin7 = [];
            delete(app)
            
        end

        % Value changed function: Model
        function ModelValueChanged(app, event)
            
            Fcn_ReadTemplate(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            if ~isempty(app.Tree.SelectedNodes)
                Fcn_updateValues(app)
            end

        end

        % Image clicked function: Image_down, Image_up
        function Image_orderClicked(app, event)
            
            Parent = app.Tree.SelectedNodes.Parent;
            
            idx = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);
            switch event.Source
                case app.Image_down
                    Parent.Children(idx:idx+1) = flip(Parent.Children(idx:idx+1));
                    app.Tree.SelectedNodes       = Parent.Children(idx+1);

                case app.Image_up
                    Parent.Children(idx-1:idx) = flip(Parent.Children(idx-1:idx));
                    app.Tree.SelectedNodes       = Parent.Children(idx-1);
            end

            Fcn_NodeData(app)
            
        end

        % Menu selected function: EditarMenu
        function Button_editPushed(app, event)
            
            Fcn_updateValues(app)

        end

        % Menu selected function: ExcluirMenu
        function Button_delPushed(app, event)
            
            if ~isempty(app.Tree.SelectedNodes)
                idx = app.Tree.SelectedNodes.NodeData;
                delete(app.Tree.Children(idx));

                if ~isempty(app.Tree.Children)
                    app.Tree.SelectedNodes = app.Tree.Children(1);
                end

                Fcn_NodeData(app)
            end

        end

        % Button pushed function: Button_preview
        function Button_previewPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento criação do modelo do relatório...");
            Fcn_reportPreview(app)

            delete(d)

        end

        % Button pushed function: Button_open_2
        function Button_open_2Pushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'DataBase', 'info.txt'));
            msg  = char(extractBetween(info, sprintf('<winTemplate>\n'), sprintf('\n</winTemplate>')));

            Misc_uialertCreation(app, 'info', msg)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Relatório</b>'; '<font>Criação de modelo (Item, parágrafo, lista, tabela e imagem)</font>'; ' '};
            app.Logo = uiimage(app.Grid1);
            app.Logo.ImageSource = 'mainMenu_Report.png';
            app.GridLayout3 = uigridlayout(app.Grid1);
            app.Model = uidropdown(app.GridLayout3);
            app.Model.Items = {'PMEC 2022', 'PMRD 2022', 'PMEF 2022', 'Rádio pirata', 'Drive-test'};
            app.Model.ValueChangedFcn = createCallbackFcn(app, @ModelValueChanged, true);
            app.Model.Value = 'PMEC 2022';
            app.Tree = uitree(app.GridLayout3);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GridLayout = uigridlayout(app.GridLayout3);
            app.Image_add = uiimage(app.GridLayout);
            app.Image_add.ImageSource = 'General_Add.png';
            app.Image_down = uiimage(app.GridLayout);
            app.Image_down.ImageClickedFcn = createCallbackFcn(app, @Image_orderClicked, true);
            app.Image_down.Enable = 'off';
            app.Image_down.ImageSource = 'General_Down2.png';
            app.Image_up = uiimage(app.GridLayout);
            app.Image_up.ImageClickedFcn = createCallbackFcn(app, @Image_orderClicked, true);
            app.Image_up.Enable = 'off';
            app.Image_up.ImageSource = 'General_Up2.png';
            app.Grid1B = uigridlayout(app.GridLayout3);
            app.Button_preview = uibutton(app.Grid1B, 'push');
            app.Button_preview.ButtonPushedFcn = createCallbackFcn(app, @Button_previewPushed, true);
            app.Button_preview.Icon = 'white_zoom.png';
            app.Button_preview.Text = 'Pré-visualizar';
            app.Button_open_2 = uibutton(app.Grid1B, 'push');
            app.Button_open_2.ButtonPushedFcn = createCallbackFcn(app, @Button_open_2Pushed, true);
            app.Button_open_2.Icon = 'Toolbar_Icon3.png';
            app.Button_open_2.Text = '';
            app.GridLayout5 = uigridlayout(app.Grid1);
            app.GridLayout2 = uigridlayout(app.GridLayout5);
            app.HTML = uihtml(app.GridLayout2);
            app.Panel = uipanel(app.Grid1);
            app.GridLayout2_2 = uigridlayout(app.Panel);
            app.Sublabel1 = uilabel(app.GridLayout2_2);
            app.Sublabel1.Interpreter = 'html';
            app.Sublabel1.Text = 'PLANO DE MONITORAÇAO DO ESPECTRO DE RADIOFREQUÊNCIAS';
            app.Sublabel2 = uilabel(app.Grid1);
            app.Sublabel2.Text = {'v. 1.00 (12/03/2022)'; 'Centralizador PMEC 2022'};
            app.ModelLabel = uilabel(app.Grid1);
            app.ModelLabel.Text = 'MODELO:';
            app.GridLayout6 = uigridlayout(app.Grid1);
            app.Warn = uiimage(app.GridLayout6);
            app.Warn.Visible = 'off';
            app.Warn.ImageSource = 'Warn_Red.png';
            app.GridLayout9 = uigridlayout(app.Grid1);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.Button_open_3 = uibutton(app.GridLayout10, 'push');
            app.Button_open_3.Icon = 'Toolbar_Icon3.png';
            app.Button_open_3.Text = '';
            app.Button_open_4 = uibutton(app.GridLayout10, 'push');
            app.Button_open_4.Icon = 'Toolbar_Icon3.png';
            app.Button_open_4.Text = '';
            app.Button_open_5 = uibutton(app.GridLayout10, 'push');
            app.Button_open_5.Icon = 'Toolbar_Icon3.png';
            app.Button_open_5.Text = 'Confirma edição';
            app.Panel_5 = uipanel(app.GridLayout9);
            app.GridLayout7 = uigridlayout(app.Panel_5);
            app.RecurrenceCheckBox = uicheckbox(app.GridLayout7);
            app.RecurrenceCheckBox.Text = 'Recurrence';
            app.DataLabel = uilabel(app.GridLayout7);
            app.DataLabel.Text = 'Data:';
            app.Panel_3 = uipanel(app.GridLayout7);
            app.GridLayout11 = uigridlayout(app.Panel_3);
            app.StringTextAreaLabel = uilabel(app.GridLayout11);
            app.StringTextAreaLabel.Text = 'String:';
            app.StringTextArea = uitextarea(app.GridLayout11);
            app.EditableDropDownLabel = uilabel(app.GridLayout11);
            app.EditableDropDownLabel.Text = 'Editable:';
            app.EditableDropDown = uidropdown(app.GridLayout11);
            app.EditableDropDown.Items = {'true', 'false'};
            app.EditableDropDown.Value = 'true';
            app.ChildrenTextAreaLabel = uilabel(app.GridLayout11);
            app.ChildrenTextAreaLabel.Text = 'Children:';
            app.ChildrenTextArea = uitextarea(app.GridLayout11);
            app.TypeDropDownLabel = uilabel(app.GridLayout9);
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.GridLayout9);
            app.TypeDropDown.Items = {'Item', 'Subitem', 'List', 'Paragraph', 'Table', 'Image'};
            app.TypeDropDown.Value = 'Item';
            app.ImagemPanel = uipanel(app.GridLayout9);
            app.ImagemPanel.Title = 'Imagem';
            app.GridLayout13 = uigridlayout(app.ImagemPanel);
            app.GridLayout8 = uigridlayout(app.GridLayout13);
            app.Button = uibutton(app.GridLayout8, 'push');
            app.Button.Icon = 'mainMenu_File.png';
            app.Button.Text = '';
            app.ImageEditFieldLabel = uilabel(app.GridLayout8);
            app.ImageEditFieldLabel.Text = 'Image:';
            app.ImageEditField = uieditfield(app.GridLayout8, 'text');
            app.SettingsLabel = uilabel(app.GridLayout13);
            app.SettingsLabel.Text = 'Settings:';
            app.Panel_4 = uipanel(app.GridLayout13);
            app.GridLayout12 = uigridlayout(app.Panel_4);
            app.WidthEditFieldLabel = uilabel(app.GridLayout12);
            app.WidthEditFieldLabel.Text = 'Width:';
            app.WidthEditField = uieditfield(app.GridLayout12, 'text');
            app.HeightEditFieldLabel = uilabel(app.GridLayout12);
            app.HeightEditFieldLabel.Text = 'Height:';
            app.HeightEditField = uieditfield(app.GridLayout12, 'text');
            app.CaptionEditFieldLabel = uilabel(app.GridLayout13);
            app.CaptionEditFieldLabel.Text = 'Caption:';
            app.CaptionEditField = uieditfield(app.GridLayout13, 'text');
            app.TypeDropDown_2Label = uilabel(app.GridLayout13);
            app.TypeDropDown_2Label.Text = 'Type:';
            app.TypeDropDown_2 = uidropdown(app.GridLayout13);
            app.TypeDropDown_2.Items = {'Internal', 'External'};
            app.TypeDropDown_2.Value = 'Internal';
            app.SourceDropDownLabel = uilabel(app.GridLayout13);
            app.SourceDropDownLabel.Text = 'Source:';
            app.SourceDropDown = uidropdown(app.GridLayout13);
            app.SourceDropDown.Items = {'specImage', 'Drive-Test'};
            app.SourceDropDown.Value = 'specImage';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.EditarMenu = uimenu(app.ContextMenu);
            app.EditarMenu.MenuSelectedFcn = createCallbackFcn(app, @Button_editPushed, true);
            app.EditarMenu.Text = 'Editar';
            app.ExcluirMenu = uimenu(app.ContextMenu);
            app.ExcluirMenu.MenuSelectedFcn = createCallbackFcn(app, @Button_delPushed, true);
            app.ExcluirMenu.Accelerator = 'D';
            app.ExcluirMenu.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTemplate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end